classdef bidsTaggerGui < matlab.apps.AppBase
    %BIDSTAGGERGUI Converts Set to BIDS folder format

    % Properties that correspond to app components
    properties (Access = public)
        BIDSTaggerUIFigure            matlab.ui.Figure
        OpenSETFolderButton           matlab.ui.control.Button
        OpenBIDSFolderButton          matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Instructions1SelectprepopulatedBIDStemplateLabel  matlab.ui.control.Label
        EntryforBIDSParametersLabel   matlab.ui.control.Label
        RecheckButton                 matlab.ui.control.Button
        BIDSPluginLamp                matlab.ui.control.Lamp
        BIDSPluginLampLabel           matlab.ui.control.Label
        EEGLABLamp                    matlab.ui.control.Lamp
        EEGLABLampLabel               matlab.ui.control.Label
        DependenciesLabel             matlab.ui.control.Label
        BIDSExporterforSETFilesLabel  matlab.ui.control.Label
        ExportBIDSButton              matlab.ui.control.Button
        SetExportDirectoryButton      matlab.ui.control.Button
        SelectdirectorytoexportBIDSstructureEditField  matlab.ui.control.EditField
        BIDSOutputDirectoryLabel      matlab.ui.control.Label
        ListingofSETFilesLabel        matlab.ui.control.Label
        SelecttemplateforBIDSParametersEditField  matlab.ui.control.EditField
        SelecttemplateforBIDSParametersLabel  matlab.ui.control.Label
        OpenBIDStemplateButton        matlab.ui.control.Button
        setlist_statusLabel           matlab.ui.control.Label
        StatusTextArea                matlab.ui.control.TextArea
        StatusTextAreaLabel           matlab.ui.control.Label
        setListTable                  matlab.ui.control.Table
        OpenSETFileDirectoryButton    matlab.ui.control.Button
        SelectdirectorycontainingsourceSETfilesEditField  matlab.ui.control.EditField
        SETInputDirectoryLabel        matlab.ui.control.Label
        vHTPBIDSTaggerLabel           matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        DATASETTab                    matlab.ui.container.Tab
        TASKTab                       matlab.ui.container.Tab
        READMETab                     matlab.ui.container.Tab
        READMETextArea                matlab.ui.control.TextArea
        READMETextAreaLabel           matlab.ui.control.Label
        CHANGESTab                    matlab.ui.container.Tab
        CHANGESTextArea               matlab.ui.control.TextArea
        CHANGESTextAreaLabel          matlab.ui.control.Label
        PARTICIPANTTab                matlab.ui.container.Tab
        pInfoTable                    matlab.ui.control.Table
    end


    properties (Access = private)
        bt % BIDS Tagger Class
        debug % Description
        infoEditField              matlab.ui.control.EditField
        infoEditFieldLabel         matlab.ui.control.Label
        infoTextArea               matlab.ui.control.TextArea
        infoTextAreaLabel          matlab.ui.control.Label
        infoNumberField            matlab.ui.control.NumericEditField
        infoNumberFieldLabel       matlab.ui.control.Label
        setupComplete % Description
        uilist % Description
        uilist_label
    end

    methods (Access = private)

        function results = startupHandler(app, action, x)

            switch action
                case 'firstrun'

            end


            results = "";

        end

        function results = updateStatusDisplay(app)
            app.StatusTextArea.Value = flip(splitlines(app.bt.getStatusLog));
            app.updateDependencyLamps;

        end

        function results = displayHandler(app, action, x)

            switch action
                case 'updateSetListTable'
                    app.setListTable.Data = app.bt.getSetTable();
                    app.setlist_statusLabel.Text =  app.bt.getSetListStatus();
                case 'setupParamsView'

                    app.uilist = {};
                    app.uilist_label = {};

                    params = app.bt.bids_parameters;
                    ginfo_fields = fields(params.('ginfo'));
                    tinfo_fields = fields(params.('tinfo'));

                    labelDb = app.bt.getLabelDatabase();

                    start_y = 200;
                    margin_left = 200;
                    counter = 1;

                    for i = 1 : numel(ginfo_fields)

                        app.uilist_label{ counter } = uilabel(app.DATASETTab);

                        switch class(params.ginfo.(ginfo_fields{i}))
                            case 'char'
                                app.uilist{ counter } = uieditfield(app.DATASETTab, 'text');
                                ui_height = 22;
                                ydelta = -30;
                            case 'string'
                                app.uilist{ counter } = uitextarea(app.DATASETTab);
                                ui_height = 22*3;
                                ydelta = -ui_height - 5;
                            
                            case 'cell'
                                app.uilist{ counter } = uitextarea(app.DATASETTab);
                                ui_height = 22*3;
                                ydelta = -ui_height - 5;                                
                                params.ginfo.(ginfo_fields{i}) = cellstr(params.ginfo.(ginfo_fields{i}));

                            case 'numeric'
                                app.uilist{ counter } = uieditfield(app.DATASETTab, 'numeric');
                                ui_height = 22;
                                ydelta = -30;
                        end

                        if i == 1
                            ydelta = 0;
                        else
                            start_y = start_y + ydelta;
                        end

                        try
                            label = labelDb(ginfo_fields{i});
                        catch
                            label = [ginfo_fields{i} ':'];
                        end

                        set(app.uilist{counter}, 'UserData', sprintf("ginfo.%s",ginfo_fields{i}));
                        set(app.uilist{counter}, 'Position', [margin_left start_y 300 ui_height]);
                        set(app.uilist{counter}, 'Value', params.ginfo.(ginfo_fields{i}));
                        set(app.uilist{counter}, 'ValueChangedFcn', @(src,event)app.modifyParams(src,event));

                        set(app.uilist_label{ counter }, 'Text', label);
                        set(app.uilist_label{ counter }, 'Position', [20 start_y 150 ui_height]);
                        set(app.uilist_label{ counter }, 'FontWeight', 'bold');

                        counter = counter + 1;

                    end

                    start_y = 175;

                    for i = 1 : numel(tinfo_fields)

                        app.uilist_label{ counter } = uilabel(app.TASKTab);

                        switch class(params.tinfo.(tinfo_fields{i}))
                            case 'char'
                                app.uilist{ counter } = uieditfield(app.TASKTab, 'text');
                                ui_height = 22;
                                ydelta = -30;

                            case 'string'
                                app.uilist{ counter } = uitextarea(app.TASKTab);
                                ui_height = 22*3;
                                ydelta = -ui_height - 5;

                            case 'cell'
                                app.uilist{ counter } = uitextarea(app.TASKTab);
                                ui_height = 22*3;
                                ydelta = -ui_height - 5;
                                params.tinfo.(tinfo_fields{i}) = cellstr(params.tinfo.(tinfo_fields{i}));

                            case 'double'
                                app.uilist{ counter } = uieditfield(app.TASKTab, 'numeric');
                                ui_height = 22;
                                ydelta = -30;

                        end

                        if i == 1
                            ydelta = 0;
                        else
                            start_y = start_y + ydelta;
                        end

                        try
                            label = labelDb(tinfo_fields{i});
                        catch
                            label = [tinfo_fields{i} ':'];
                        end

                        set(app.uilist{counter}, 'UserData', sprintf("tinfo.%s",tinfo_fields{i}));
                        set(app.uilist{counter}, 'Position', [margin_left start_y 300 ui_height]);
                        set(app.uilist{counter}, 'Value', params.tinfo.(tinfo_fields{i}));

                        set(app.uilist_label{ counter }, 'Text', label);
                        set(app.uilist_label{ counter }, 'Position', [20 start_y 150 ui_height]);
                        set(app.uilist_label{ counter }, 'FontWeight', 'bold');

                        counter = counter + 1;

                    end

                    app.READMETextArea.Value = params.README;
                    app.READMETextArea.UserData = sprintf("README");

                    app.CHANGESTextArea.Value = params.CHANGES;
                    app.CHANGESTextArea.UserData = sprintf("CHANGES");
                    
                    app.setupComplete = 1;

                case 'updateParamsView'

                        params = app.bt.bids_parameters;
                        ginfo_fields = fields(params.('ginfo'));
                        tinfo_fields = fields(params.('tinfo'));

                        labelDb = app.bt.getLabelDatabase();

                        counter = 1;

                        for i = 1 : numel(ginfo_fields)

                            try
                                label = labelDb(ginfo_fields{i});
                            catch
                                label = [ginfo_fields{i} ':'];
                            end

                            set(app.uilist{counter}, 'Value', params.ginfo.(ginfo_fields{i}));

                            set(app.uilist_label{ counter }, 'Text', label);

                            counter = counter + 1;

                        end

                        for i = 1 : numel(tinfo_fields)

                            try
                                label = labelDb(tinfo_fields{i});
                            catch
                                label = [tinfo_fields{i} ':'];
                            end

                            set(app.uilist{counter}, 'Value', params.tinfo.(tinfo_fields{i}));

                            set(app.uilist_label{ counter }, 'Text', label);

                            counter = counter + 1;

                        end

                        app.READMETextArea.Value = params.README;
                        app.READMETextArea.UserData = sprintf("README");

                        app.CHANGESTextArea.Value = params.CHANGES;
                        app.CHANGESTextArea.UserData = sprintf("CHANGES");

            end
        end


        function modifyParams(app, src, event)
            app.bt.changeParameter( src.UserData, event.Value);
            app.displayHandler('updateParamsView')
            app.updateStatusDisplay;

        end
        
        function updateDependencyLamps(app)
            colors = {'red','green'};
            lightswitch = @(test) colors{ test + 1};
            app.EEGLABLamp.Color = lightswitch(app.bt.eeglab_present);
            app.BIDSPluginLamp.Color = lightswitch(app.bt.bidsplugin_present);
        end
        
        function updateButtons(app)
            app.ExportBIDSButton.Enable = app.bt.button_export;
            app.OpenBIDStemplateButton.Enable  = app.bt.button_template;
            app.OpenSETFileDirectoryButton.Enable  = app.bt.button_setdir;
            app.SetExportDirectoryButton.Enable  = app.bt.button_exportdir;
            app.RecheckButton.Enable = app.bt.button_recheck;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.debug = false;
            app.bt = bidsTaggerClass;
            app.displayHandler('updateSetListTable');
            app.updateButtons;
            app.setupComplete = 0;
            app.updateStatusDisplay;

            % app.startupHandler()
        end

        % Button pushed function: OpenSETFileDirectoryButton
        function OpenSETFileDirectoryButtonPushed(app, event)
            if ~app.debug
                app.bt.loadSetFiles;
            else
                app.bt.loadSetFiles('F:\bidsstart');
            end
            if app.bt.last_validation
                app.SelectdirectorycontainingsourceSETfilesEditField.Value = app.bt.getSetPath;
                app.displayHandler('updateSetListTable');
                app.bt.populateFileData(app.bt.setlist);
                app.bt.scanParticipants;
                app.pInfoTable.Data = app.bt.pinfo_table;
                app.pInfoTable.ColumnName = app.bt.pinfo_table.Properties.VariableNames;
                app.updateStatusDisplay;
            else
                app.updateStatusDisplay;
            end
        end

        % Button pushed function: OpenBIDStemplateButton
        function OpenBIDStemplateButtonPushed(app, event)
            if ~app.debug
                app.bt.loadParameterFile();
            else
                app.bt.loadParameterFile( "C:/srv/vhtp/bids_parameters/bids_template.m");
            end

            if app.bt.last_validation  % if load was successful
                % BIDS parameter filename display
                app.SelecttemplateforBIDSParametersEditField.Value = app.bt.getBidsTemplateFilename();
                app.updateStatusDisplay;
                app.displayHandler('setupParamsView');

            else
                app.updateStatusDisplay;
            end
        end

        % Button pushed function: SetExportDirectoryButton
        function SetExportDirectoryButtonPushed(app, event)
            if ~app.debug
                app.bt.setExportPath;
            else
                app.bt.setExportPath('F:\bidstest');
            end
            app.SelectdirectorytoexportBIDSstructureEditField.Value = app.bt.getExportPath();
        end

        % Button pushed function: ExportBIDSButton
        function ExportBIDSButtonPushed(app, event)
            app.bt.bidsExport;
        end

        % Button pushed function: RecheckButton
        function RecheckButtonPushed(app, event)
           if app.bt.checkDependencies
               app.bt.buttonHandler('file_buttons_on');
               app.updateButtons;
               app.updateStatusDisplay;

           end
 
        end

        % Button pushed function: OpenBIDSFolderButton
        function OpenBIDSFolderButtonPushed(app, event)
            target_dir = app.SelectdirectorytoexportBIDSstructureEditField.Value;

            if ~isempty(target_dir)
                winopen(target_dir);
            end
            
        end

        % Button pushed function: OpenSETFolderButton
        function OpenSETFolderButtonPushed(app, event)
            target_dir = app.SelectdirectorycontainingsourceSETfilesEditField.Value;
            
            if ~isempty(target_dir)
                winopen(target_dir);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BIDSTaggerUIFigure = uifigure('Visible', 'off');
            app.BIDSTaggerUIFigure.Name = 'BIDS Tagger';
            app.TabGroup = uitabgroup(app.BIDSTaggerUIFigure);
            app.DATASETTab = uitab(app.TabGroup);
            app.DATASETTab.Title = 'DATASET';
            app.TASKTab = uitab(app.TabGroup);
            app.TASKTab.Title = 'TASK';
            app.READMETab = uitab(app.TabGroup);
            app.READMETab.Title = 'README';
            app.READMETextAreaLabel = uilabel(app.READMETab);
            app.READMETextAreaLabel.Text = 'README';
            app.READMETextArea = uitextarea(app.READMETab);
            app.CHANGESTab = uitab(app.TabGroup);
            app.CHANGESTab.Title = 'CHANGES';
            app.CHANGESTextAreaLabel = uilabel(app.CHANGESTab);
            app.CHANGESTextAreaLabel.Text = 'CHANGES';
            app.CHANGESTextArea = uitextarea(app.CHANGESTab);
            app.PARTICIPANTTab = uitab(app.TabGroup);
            app.PARTICIPANTTab.Title = 'PARTICIPANT';
            app.pInfoTable = uitable(app.PARTICIPANTTab);
            app.pInfoTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.pInfoTable.RowName = {};
            app.vHTPBIDSTaggerLabel = uilabel(app.BIDSTaggerUIFigure);
            app.vHTPBIDSTaggerLabel.Text = 'vHTP BIDS Tagger';
            app.SETInputDirectoryLabel = uilabel(app.BIDSTaggerUIFigure);
            app.SETInputDirectoryLabel.Text = '2. Select directory containing source SET files:';
            app.SelectdirectorycontainingsourceSETfilesEditField = uieditfield(app.BIDSTaggerUIFigure, 'text');
            app.OpenSETFileDirectoryButton = uibutton(app.BIDSTaggerUIFigure, 'push');
            app.OpenSETFileDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSETFileDirectoryButtonPushed, true);
            app.OpenSETFileDirectoryButton.Text = 'Open SET File Directory';
            app.setListTable = uitable(app.BIDSTaggerUIFigure);
            app.setListTable.ColumnName = {'FIlename'; 'FIlePath'};
            app.setListTable.RowName = {};
            app.StatusTextAreaLabel = uilabel(app.BIDSTaggerUIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.BIDSTaggerUIFigure);
            app.setlist_statusLabel = uilabel(app.BIDSTaggerUIFigure);
            app.setlist_statusLabel.Text = 'setlist_status';
            app.OpenBIDStemplateButton = uibutton(app.BIDSTaggerUIFigure, 'push');
            app.OpenBIDStemplateButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBIDStemplateButtonPushed, true);
            app.OpenBIDStemplateButton.Text = 'Open BIDS template';
            app.SelecttemplateforBIDSParametersLabel = uilabel(app.BIDSTaggerUIFigure);
            app.SelecttemplateforBIDSParametersLabel.Text = '1. Select template for BIDS Parameters:';
            app.SelecttemplateforBIDSParametersEditField = uieditfield(app.BIDSTaggerUIFigure, 'text');
            app.ListingofSETFilesLabel = uilabel(app.BIDSTaggerUIFigure);
            app.ListingofSETFilesLabel.Text = 'Listing of SET Files:';
            app.BIDSOutputDirectoryLabel = uilabel(app.BIDSTaggerUIFigure);
            app.BIDSOutputDirectoryLabel.Text = '3. Select directory to export BIDS structure:';
            app.SelectdirectorytoexportBIDSstructureEditField = uieditfield(app.BIDSTaggerUIFigure, 'text');
            app.SetExportDirectoryButton = uibutton(app.BIDSTaggerUIFigure, 'push');
            app.SetExportDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetExportDirectoryButtonPushed, true);
            app.SetExportDirectoryButton.Text = 'Set Export Directory';
            app.ExportBIDSButton = uibutton(app.BIDSTaggerUIFigure, 'push');
            app.ExportBIDSButton.ButtonPushedFcn = createCallbackFcn(app, @ExportBIDSButtonPushed, true);
            app.ExportBIDSButton.Text = 'Export BIDS';
            app.BIDSExporterforSETFilesLabel = uilabel(app.BIDSTaggerUIFigure);
            app.BIDSExporterforSETFilesLabel.Text = 'BIDS Exporter for SET Files';
            app.DependenciesLabel = uilabel(app.BIDSTaggerUIFigure);
            app.DependenciesLabel.Text = 'Dependencies:';
            app.EEGLABLampLabel = uilabel(app.BIDSTaggerUIFigure);
            app.EEGLABLampLabel.Text = 'EEGLAB';
            app.EEGLABLamp = uilamp(app.BIDSTaggerUIFigure);
            app.BIDSPluginLampLabel = uilabel(app.BIDSTaggerUIFigure);
            app.BIDSPluginLampLabel.Text = 'BIDS Plugin';
            app.BIDSPluginLamp = uilamp(app.BIDSTaggerUIFigure);
            app.RecheckButton = uibutton(app.BIDSTaggerUIFigure, 'push');
            app.RecheckButton.ButtonPushedFcn = createCallbackFcn(app, @RecheckButtonPushed, true);
            app.RecheckButton.Text = 'Recheck';
            app.EntryforBIDSParametersLabel = uilabel(app.BIDSTaggerUIFigure);
            app.EntryforBIDSParametersLabel.Text = 'Entry for BIDS Parameters:';
            app.Instructions1SelectprepopulatedBIDStemplateLabel = uilabel(app.BIDSTaggerUIFigure);
            app.Instructions1SelectprepopulatedBIDStemplateLabel.Text = '';
            app.Label = uilabel(app.BIDSTaggerUIFigure);
            app.Label.Text = {'Instructions:'; '1. Select prepopulated BIDS template.'; '2. Select SET file directory'; '3. Select BIDS export directory'; '4. Edit parameters as needed'; '5. Run Export BIDS'};
            app.OpenBIDSFolderButton = uibutton(app.BIDSTaggerUIFigure, 'push');
            app.OpenBIDSFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBIDSFolderButtonPushed, true);
            app.OpenBIDSFolderButton.Text = 'Open BIDS Folder';
            app.OpenSETFolderButton = uibutton(app.BIDSTaggerUIFigure, 'push');
            app.OpenSETFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSETFolderButtonPushed, true);
            app.OpenSETFolderButton.Text = 'Open SET Folder';
            % Show the figure after all components are created
            app.BIDSTaggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bidsTaggerGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BIDSTaggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BIDSTaggerUIFigure)
        end
    end
end