classdef htpAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenResultsButton               matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        CurrentAnalysesListBox          matlab.ui.control.ListBox
        CurrentAnalysesListBoxLabel     matlab.ui.control.Label
        AnalysisLibraryListBox          matlab.ui.control.ListBox
        AnalysisLibraryLabel            matlab.ui.control.Label
        MessagesTextArea                matlab.ui.control.TextArea
        MessagesTextAreaLabel           matlab.ui.control.Label
        ExplorePathButton               matlab.ui.control.Button
        OpenChanlocsButton              matlab.ui.control.Button
        ViewDataButton                  matlab.ui.control.Button
        OpeninEEGLABButton              matlab.ui.control.Button
        checkDependencies               matlab.ui.control.Hyperlink
        fileListTable                   matlab.ui.control.Table
        CurrentFileListInfoforSETLabel  matlab.ui.control.Label
        label_filelist                  matlab.ui.control.Label
        results_freespace               matlab.ui.control.Label
        temp_freespace                  matlab.ui.control.Label
        DatasetDetailsLabel             matlab.ui.control.Label
        resultsdir_button               matlab.ui.control.Button
        resultsdir                      matlab.ui.control.EditField
        SelectdatasetSETpathLabel_3     matlab.ui.control.Label
        tempdir                         matlab.ui.control.EditField
        SelectdatasetSETpathLabel_2     matlab.ui.control.Label
        tempdir_button                  matlab.ui.control.Button
        datadir                         matlab.ui.control.EditField
        SelectdatasetSETpathLabel       matlab.ui.control.Label
        IgnoresubfoldersCheckBox        matlab.ui.control.CheckBox
        datadir_button                  matlab.ui.control.Button
        CheckingLabel                   matlab.ui.control.Label
        DependencyStatusLamp            matlab.ui.control.Lamp
        DependencyStatusLampLabel       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        vHTPAnalysisLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        ha % htpAnalysis Class
    end
    
    methods (Access = private) 
        function res = fileTableHandler( app )
            results = app.ha.input_filelist;
            app.fileListTable.Data = [results.filename  cellfun(hideInputFolder, results.filepath, 'UniformOutput', false)];
            app.fileListTable.ColumnName = {'Filename','Subfolder'};
          
        end
        function results = view_handler(app, action)
            switch action
                case 'startupDefaults'
                    app.DependencyStatusLamp.Color = [1 0 0]; % red
                    app.CheckingLabel.Visible = 'On';
                    app.CheckingLabel.Text = 'Checking ...';
                    app.temp_freespace.Visible = 'off';
                    app.results_freespace.Visible = 'off';
                    app.label_filelist.Visible = 'On';

                case 'updateDependenciesGui'
                    isValid = app.ha.get_dependencies_status;
                    if isValid
                         app.DependencyStatusLamp.Color = [0 1 0]; % green
                         app.CheckingLabel.Visible = 'On';
                         app.CheckingLabel.Text = 'All toolkits installed.';
                         app.CheckingLabel.FontColor = [0 0 0];
                    else
                        app.DependencyStatusLamp.Color = [1 0 0]; % red
                        app.CheckingLabel.Visible = 'On';
                        app.CheckingLabel.Text = 'Run htpDoctor to fix.';
                    end

                case 'updateFileCount'
                    value = app.ha.proj_info.data_dir;
                    figure(app.UIFigure);
                    if value==0
                        app.label_filelist.Text = '...';
                        return; 
                    else
                        app.datadir.Value = value;
                        [fileCount, subfolderCount] = app.ha.createFileList(value);

                        fileCountLabel = sprintf('%d Files', fileCount);
                        if subfolderCount > 1
                            subFolderLabel = sprintf('in %d Folders.', subfolderCount);
                        else
                            subFolderLabel = '';
                        end

                        app.label_filelist.Text = [fileCountLabel subFolderLabel];
                        app.label_filelist.Visible = 'on';

                        filelist = app.ha.input_filelist;
                        if subfolderCount > 1
                            hideInputFolder = @(x) strrep(x, app.datadir.Value, '');
                        else
                            hideInputFolder = @(x) x;
                        end
                        if isempty(filelist) == false
                            app.fileListTable.Data = [filelist.filename  cellfun(hideInputFolder, filelist.filepath, 'UniformOutput', false)];
                            app.fileListTable.ColumnName = {'Filename','Subfolder'};
                        else
                            app.fileListTable.Data = [];
                            app.fileListTable.ColumnName = {'Filename','Subfolder'};
                        end

                    end     
                otherwise
                    fprintf('Unhandled action in viewHandler.')

            end

        end
        function load_current_file_selection( app )
            app.ha.getCurrentFileByRowNumber( app.fileListTable.Selection); % will store in proj_status.active_file;
            app.ha.loadActiveEegSelection;
        end

        function use_debug_mode(app)
            % set debug directory
            event.EventName = 'Dummy';
            app.datadir.Value = 'F:\Projects\MeaTest\Data';
            app.datadir_buttonButtonPushed(event);

            app.tempdir.Value = 'F:\Projects\MeaTest\Tempfiles';
            app.tempdir_buttonButtonPushed(event);

            app.resultsdir.Value = 'F:\Projects\MeaTest\Results';
            app.resultsdir_buttonButtonPushed(event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % htpAnalysis Entry Point
            app.ha = htpAnalysisClass; % htp analysis (ha)
            app.view_handler('startupDefaults');
            app.view_handler('updateDependenciesGui');

            % debug
            app.use_debug_mode()
        end

        % Value changed function: IgnoresubfoldersCheckBox
        function IgnoresubfoldersCheckBoxValueChanged(app, event)
            value = app.IgnoresubfoldersCheckBox.Value;
            app.ha.set_ignore_subdirectory_state(value);

            if ~isempty(app.datadir.Value)
                app.view_handler('updateFileCount');
            end
        end

        % Button pushed function: datadir_button
        function datadir_buttonButtonPushed(app, event)
            if event.EventName == categorical("ButtonPushed")
                value = app.ha.pick_return_directory();
            else
                value = app.datadir.Value;
            end
            
            if value==0, return; else
                app.datadir.Value = value;
                app.ha.proj_info.data_dir = value;
                app.view_handler('updateFileCount');
            end
        end

        % Button pushed function: tempdir_button
        function tempdir_buttonButtonPushed(app, event)
            if event.EventName == categorical("ButtonPushed")
                value = app.ha.pick_return_directory();
            else
                value = app.tempdir.Value;
            end

            figure(app.UIFigure);
            if value==0, return;
            else, app.tempdir.Value = value;
                app.ha.proj_info.temp_dir = value;
                app.temp_freespace.Visible = 'on';
                app.temp_freespace.Text = sprintf('%.1f GB Free', ...
                    app.ha.getUsableDiskSpace(value));
            end

        end

        % Button pushed function: resultsdir_button
        function resultsdir_buttonButtonPushed(app, event)
            if event.EventName == categorical("ButtonPushed")
                value = app.ha.pick_return_directory();
            else
                value = app.resultsdir.Value;
            end
            
            figure(app.UIFigure);
            if value==0, return; else, app.resultsdir.Value = value;
                app.ha.proj_info.results_dir = value;
                app.results_freespace.Visible = 'on';
                app.results_freespace.Text = sprintf('%.1f GB Free', ...
                    app.ha.getUsableDiskSpace(value));
            end
        end

        % Callback function: checkDependencies
        function checkDependenciesClicked(app, event)
            app.ha.check_dependencies;
            app.view_handler('updateDependenciesGui');
        end

        % Button pushed function: ViewDataButton
        function ViewDataButtonPushed(app, event)
           try
               app.load_current_file_selection;
               app.ha.singleEegHandler('openEegPlot');
           catch
           end
        end

        % Button pushed function: OpeninEEGLABButton
        function OpeninEEGLABButtonPushed(app, event)
              app.load_current_file_selection;
              app.ha.singleEegHandler('openInEeglab');
        end

        % Button pushed function: OpenChanlocsButton
        function OpenChanlocsButtonPushed(app, event)
            app.load_current_file_selection;
            app.ha.singleEegHandler('openChanLocs');
        end

        % Button pushed function: ExplorePathButton
        function ExplorePathButtonPushed(app, event)
              app.load_current_file_selection;
            app.ha.singleEegHandler('openExplorer');
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            app.ha.run_analysis_loop;
        end

        % Button pushed function: OpenResultsButton
        function OpenResultsButtonPushed(app, event)
            app.ha.openResultsFolder;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'man', 'figures', 'logo.png');
            app.vHTPAnalysisLabel = uilabel(app.UIFigure);
            app.vHTPAnalysisLabel.Text = 'vHTP Analysis';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Performs batch analysis on SET files';
            app.DependencyStatusLampLabel = uilabel(app.UIFigure);
            app.DependencyStatusLampLabel.Text = 'Dependency Status:';
            app.DependencyStatusLamp = uilamp(app.UIFigure);
            app.CheckingLabel = uilabel(app.UIFigure);
            app.CheckingLabel.Text = 'Checking ...';
            app.datadir_button = uibutton(app.UIFigure, 'push');
            app.datadir_button.ButtonPushedFcn = createCallbackFcn(app, @datadir_buttonButtonPushed, true);
            app.datadir_button.Text = 'Select';
            app.IgnoresubfoldersCheckBox = uicheckbox(app.UIFigure);
            app.IgnoresubfoldersCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoresubfoldersCheckBoxValueChanged, true);
            app.IgnoresubfoldersCheckBox.Text = 'Ignore subfolders';
            app.SelectdatasetSETpathLabel = uilabel(app.UIFigure);
            app.SelectdatasetSETpathLabel.Text = 'Data Directory (*.SET):';
            app.datadir = uieditfield(app.UIFigure, 'text');
            app.tempdir_button = uibutton(app.UIFigure, 'push');
            app.tempdir_button.ButtonPushedFcn = createCallbackFcn(app, @tempdir_buttonButtonPushed, true);
            app.tempdir_button.Text = 'Select';
            app.SelectdatasetSETpathLabel_2 = uilabel(app.UIFigure);
            app.SelectdatasetSETpathLabel_2.Text = 'Temporary Directory:';
            app.tempdir = uieditfield(app.UIFigure, 'text');
            app.SelectdatasetSETpathLabel_3 = uilabel(app.UIFigure);
            app.SelectdatasetSETpathLabel_3.Text = 'Results Directory:';
            app.resultsdir = uieditfield(app.UIFigure, 'text');
            app.resultsdir_button = uibutton(app.UIFigure, 'push');
            app.resultsdir_button.ButtonPushedFcn = createCallbackFcn(app, @resultsdir_buttonButtonPushed, true);
            app.resultsdir_button.Text = 'Select';
            app.DatasetDetailsLabel = uilabel(app.UIFigure);
            app.DatasetDetailsLabel.Text = 'Dataset Details';
            app.temp_freespace = uilabel(app.UIFigure);
            app.temp_freespace.Text = 'Free: X gb';
            app.results_freespace = uilabel(app.UIFigure);
            app.results_freespace.Text = 'Free: X gb';
            app.label_filelist = uilabel(app.UIFigure);
            app.label_filelist.Text = '...';
            app.CurrentFileListInfoforSETLabel = uilabel(app.UIFigure);
            app.CurrentFileListInfoforSETLabel.Text = 'Current File List Info for *.SET';
            app.fileListTable = uitable(app.UIFigure);
            app.fileListTable.ColumnName = {'Filename'; 'Subfolder'};
            app.fileListTable.RowName = {};
            app.fileListTable.SelectionType = 'row';
            app.fileListTable.Multiselect = 'off';
            app.checkDependencies = uihyperlink(app.UIFigure);
            app.checkDependencies.HyperlinkClickedFcn = createCallbackFcn(app, @checkDependenciesClicked, true);
            app.checkDependencies.Text = 'Click to recheck';
            app.OpeninEEGLABButton = uibutton(app.UIFigure, 'push');
            app.OpeninEEGLABButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninEEGLABButtonPushed, true);
            app.OpeninEEGLABButton.Text = 'Open in EEGLAB';
            app.ViewDataButton = uibutton(app.UIFigure, 'push');
            app.ViewDataButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton.Text = 'View Data';
            app.OpenChanlocsButton = uibutton(app.UIFigure, 'push');
            app.OpenChanlocsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenChanlocsButtonPushed, true);
            app.OpenChanlocsButton.Text = 'Open Chanlocs';
            app.ExplorePathButton = uibutton(app.UIFigure, 'push');
            app.ExplorePathButton.ButtonPushedFcn = createCallbackFcn(app, @ExplorePathButtonPushed, true);
            app.ExplorePathButton.Text = 'Explore Path';
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.AnalysisLibraryLabel = uilabel(app.UIFigure);
            app.AnalysisLibraryLabel.Text = 'Analysis Library:';
            app.AnalysisLibraryListBox = uilistbox(app.UIFigure);
            app.CurrentAnalysesListBoxLabel = uilabel(app.UIFigure);
            app.CurrentAnalysesListBoxLabel.Text = 'Current Analyses:';
            app.CurrentAnalysesListBox = uilistbox(app.UIFigure);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.Text = 'Remove';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.OpenResultsButton = uibutton(app.UIFigure, 'push');
            app.OpenResultsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenResultsButtonPushed, true);
            app.OpenResultsButton.Text = 'Open Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = htpAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end