classdef vhtp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VHTPUIFigure                    matlab.ui.Figure
        ExportOutputButton              matlab.ui.control.Button
        WorkingfromOriginalFilesCheckBox  matlab.ui.control.CheckBox
        SingleFileModeCheckBox          matlab.ui.control.CheckBox
        SelectOutputExportFolderButton  matlab.ui.control.Button
        OutputExportDirectoryEditField  matlab.ui.control.EditField
        OutputDirectoryLabel            matlab.ui.control.Label
        BaseFileorDirectoryEditField    matlab.ui.control.EditField
        BaseFileorDirectoryEditFieldLabel  matlab.ui.control.Label
        SelectFilesButton               matlab.ui.control.Button
        TestRunCheckBox                 matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.StateButton
        ProcessingStyleButtonGroup      matlab.ui.container.ButtonGroup
        ContinuationProcessStepButton   matlab.ui.control.ToggleButton
        IndividualProcessStepButton     matlab.ui.control.ToggleButton
        CompleteProcessButton           matlab.ui.control.ToggleButton
        ContinueFromDropDown            matlab.ui.control.DropDown
        ContinueFromDropDownLabel       matlab.ui.control.Label
        IndividualStepsListBox          matlab.ui.control.ListBox
        IndividualStepsLabel            matlab.ui.control.Label
        ParadigmsListBox                matlab.ui.control.ListBox
        ParadigmsLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        PARAMS=struct(); % Description
        DRYRUN=0;
        PROCESS='';
        SOURCEFILES=0;
    end

    methods (Access = private)
    
        function allowProcessStyle(app)
            app.ProcessingStyleButtonGroup.Visible = 'on';
            app.CompleteProcessButton.Visible = 'on';
            app.IndividualProcessStepButton.Visible = 'on';
            app.ContinuationProcessStepButton.Visible = 'on';
            app.CompleteProcessButton.Enable=1;
            app.IndividualProcessStepButton.Enable=1;
            app.ContinuationProcessStepButton.Enable=1;
            app.CompleteProcessButton.Value=1;
            app.IndividualProcessStepButton.Value=0;
            app.ContinuationProcessStepButton.Value=0;
        end
        
        function denyProcessStyle(app)
            app.ProcessingStyleButtonGroup.Visible = 'off';
            app.CompleteProcessButton.Visible = 'off';
            app.IndividualProcessStepButton.Visible = 'off';
            app.ContinuationProcessStepButton.Visible = 'off';
            app.CompleteProcessButton.Enable=0;
            app.IndividualProcessStepButton.Enable=0;
            app.ContinuationProcessStepButton.Enable=0;
            app.CompleteProcessButton.Value=0;
            app.IndividualProcessStepButton.Value=0;
            app.ContinuationProcessStepButton.Value=0;
        end
        
        function allowParadigms(app)
            paradigms = string(regexp(string(char(ls(fullfile(fileparts(mfilename('fullpath')),'paradigms','*.m')))),'(?<=paradigm_)\w*','match'));
            app.ParadigmsListBox.Items = paradigms;
            app.ParadigmsListBox.ItemsData = paradigms;
            app.ParadigmsListBox.Visible = 'on';
            app.ParadigmsListBox.Enable = 'on';
            
        end
    
        function denyParadigms(app)
            app.ParadigmsListBox.Items = {};
            app.ParadigmsListBox.ItemsData = {};
            app.ParadigmsListBox.Visible = 'off';
            app.ParadigmsListBox.Enable = 'off'; 
            
        end
    
        function allowIndividualSteps(app)
            individualsteps = fieldnames(app.PARAMS);
            app.IndividualStepsListBox.Items = individualsteps;
            app.IndividualStepsListBox.ItemsData = individualsteps;
            app.IndividualStepsListBox.Visible = 'on';
            app.IndividualStepsListBox.Enable = 'on';
            
        end
    
        function denyIndividualSteps(app)
            app.IndividualStepsListBox.Items = {};
            app.IndividualStepsListBox.ItemsData = {};
            app.IndividualStepsListBox.Visible = 'off';
            app.IndividualStepsListBox.Enable = 'off';
        end
    
        function allowContinuationSteps(app)
            steps=fieldnames(app.PARAMS);
%             if ~app.SOURCEFILES
%                 rerunsteps = string(steps);
%             else
%                 rerunsteps = [string(steps)];
%             end
            rerunsteps = string(steps);
            
            app.ContinueFromDropDown.Items = rerunsteps;
            app.ContinueFromDropDown.ItemsData = rerunsteps;
            app.ContinueFromDropDown.Visible = 'on';
            app.ContinueFromDropDown.Enable = 'on';
        end
        
        function denyContinuationSteps(app)
            app.ContinueFromDropDown.Items = {};
            app.ContinueFromDropDown.ItemsData = {};
            app.ContinueFromDropDown.Visible = 'off';
            app.ContinueFromDropDown.Enable = 'off';
        end
        
        function denyAll(app)
            denyParadigms(app);
            denyProcessStyle(app);
            denyIndividualSteps(app);
            denyContinuationSteps(app); 
            app.RunButton.Value = 0;
            
        end
        
        function initializePreviousFileDirectories(app,prev_file_dir)
            stepnames = fieldnames(app.PARAMS);
            if app.SOURCEFILES && ~exist(fullfile(prev_file_dir,'source'),'dir')
                mkdir(fullfile(prev_file_dir,'source'));
            end
            for i=1:length(stepnames)
                if ~exist(fullfile(prev_file_dir,stepnames{i}),'dir')
                    mkdir(fullfile(prev_file_dir,stepnames{i}));
                end
            end
        end
        
        function moveFiles(app,prev_file_dir,files_to_move)
            if any(strcmp(app.PROCESS,{'IndividualStep'}))
                if app.SingleFileModeCheckBox.Value
                    cellfun(@(x) movefile(x,prev_file_dir),files_to_move);
                else
                    arrayfun(@(x) movefile(fullfile(x.folder,x.name),prev_file_dir),files_to_move);
                end
            else
                
                folders = arrayfun(@(x) regexp(x,fieldnames(app.PARAMS),'match','once'),string(char(files_to_move(:).name)),'UniformOutput',false);
                if length(fieldnames(app.PARAMS))>1
                    temp=cellfun(@(x) ismissing(x),folders,'UniformOutput',false);
                    folders(cellfun(@(x) all(x),temp))={"source"};
                    folders=cellfun(@(x) rmmissing(x),folders);
                else
                    folders(ismissing(string(folders))) = {"source"};
                end
                for i=1:length(files_to_move)
                    movefile(fullfile(files_to_move(i).folder,files_to_move(i).name),fullfile(prev_file_dir,folders{i}));
                end
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                eeglab nogui;
            catch
                app.delete()
                error(sprintf("PLEASE MAKE SURE YOUR EEGLAB DIRECTORY IS ON THE PATH.\n"))
                
            end
            addpath(genpath(pwd));
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            startFolder = pwd;
            if app.SingleFileModeCheckBox.Value
                [file,folder] = uigetfile('*.set','Select a File for Input');
                if file==0
                    figure(app.VHTPUIFigure);
                    return;
                end
                fullFileName = fullfile(folder,file);
                app.BaseFileorDirectoryEditField.Value = fullFileName;
            else
                [folder] = uigetdir('Select a Folder for Input');
                if folder==0
                    figure(app.VHTPUIFigure);
                    return;
                end
                 app.BaseFileorDirectoryEditField.Value = folder;
                 
            end
            figure(app.VHTPUIFigure);
            if ~isempty(app.OutputExportDirectoryEditField.Value)
                allowParadigms(app);
            end
        end

        % Value changed function: SingleFileModeCheckBox
        function SingleFileButtonPushed(app, event)
            value = app.SingleFileModeCheckBox.Value;
            if value
                if ~isempty(app.BaseFileorDirectoryEditField.Value)
                    app.BaseFileorDirectoryEditField.Value = '';
                    denyAll(app);
                    
                end
                app.SelectFilesButton.Text = 'Select Files';
            else
                if ~isempty(app.BaseFileorDirectoryEditField.Value)
                    app.BaseFileorDirectoryEditField.Value = '';
                    denyAll(app);
                end
                app.SelectFilesButton.Text = 'Select Folder';
            end
        end

        % Button pushed function: SelectOutputExportFolderButton
        function OutputFolderButtonPushed(app, event)
            [folder] = uigetdir('Select a Folder for Output');
            if folder==0
                figure(app.VHTPUIFigure);
                return;
            end
            app.OutputExportDirectoryEditField.Value = folder;
            figure(app.VHTPUIFigure);
            if ~isempty(app.BaseFileorDirectoryEditField.Value)
                allowParadigms(app);
            end
                 
        end

        % Value changed function: ParadigmsListBox
        function ParadigmListBoxChanged(app, event)
            value = app.ParadigmsListBox.Value;
            if ~isempty(value)
                func = str2func(strcat("parameters_",value));
                app.PARAMS=func();
                allowProcessStyle(app);
                app.PROCESS='All';
                denyContinuationSteps(app);
                denyIndividualSteps(app);
            else
                denyAll(app);
            end
        end

        % Value changed function: BaseFileorDirectoryEditField
        function BaseFileorDirectoryEditFieldChanged(app, event)
            value = app.BaseFileorDirectoryEditField.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Value changed function: OutputExportDirectoryEditField
        function OutputExportDirectoryEditFieldChanged(app, event)
            value = app.OutputExportDirectoryEditField.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Selection changed function: ProcessingStyleButtonGroup
        function ProcessingStyleButtonGroupChanged(app, event)
            selectedButton = app.ProcessingStyleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Individual Process Step')
                allowIndividualSteps(app);
                denyContinuationSteps(app);
                app.PROCESS = 'IndividualStep';
            elseif strcmp(selectedButton.Text, 'Continuation Process Step')
               allowContinuationSteps(app);
               denyIndividualSteps(app);
               app.PROCESS = 'Continuation';
            else
                
                denyIndividualSteps(app);
                denyContinuationSteps(app);
                app.PROCESS = 'All';
            end
        end

        % Value changed function: TestRunCheckBox
        function TestRunCheckBoxChanged(app, event)
            value = app.TestRunCheckBox.Value;
            app.DRYRUN = value;
        end

        % Value changed function: RunButton
        function RunButtonChanged(app, event)
            value = app.RunButton.Value;
            if value
                if ~isempty(fieldnames(app.PARAMS))
                    func=str2func(strcat("paradigm_",app.ParadigmsListBox.Value));
                    if app.SingleFileModeCheckBox.Value
                        [folder,file,~] = fileparts(app.BaseFileorDirectoryEditField.Value);
                        prev_file_dir = fullfile(folder,'prev_files');
                        files_to_move={app.BaseFileorDirectoryEditField.Value; fullfile(folder,[file '.fdt']);};
                        
                    else
                        prev_file_dir = fullfile(app.BaseFileorDirectoryEditField.Value,'prev_files');
                        files_to_move = dir(fullfile(app.BaseFileorDirectoryEditField.Value));
                        files_to_move = files_to_move(~[files_to_move.isdir]);
                    end
                    if ~exist(prev_file_dir,'dir')
                        mkdir(prev_file_dir);
                    end
                    initializePreviousFileDirectories(app,prev_file_dir);
                    if strcmp(app.PROCESS,'Continuation')
                       
                       steps = fieldnames(app.PARAMS);
                       stepsMatch = regexp(app.ContinueFromDropDown.Value,steps,'match','once');
                       startStep = find(~ismissing(stepsMatch));
                       continuationSteps = struct();
                       for i=startStep:length(steps)
                           continuationSteps.(steps{i}) = app.PARAMS.(steps{i}); 
                       end
                       func(app.BaseFileorDirectoryEditField.Value,continuationSteps,app.PROCESS,'ext','.set','outputdir',prev_file_dir,'dryrun',false,'continuationstep', char(app.ContinueFromDropDown.Value) );
                       if app.SingleFileModeCheckBox.Value
                           [folder,file,~] = fileparts(app.BaseFileorDirectoryEditField.Value);
                           prev_file_dir = fullfile(folder,'prev_files');
                           files_to_move=dir(fullfile(folder,[file '*']));
                           
                           if app.SOURCEFILES
                               subjectName = regexprep(files_to_move(1).name,'^*\.\w+$','');
                               
                           else
                               subjectName = files_to_move(1).name(1:cell2mat((regexp(files_to_move(1).name,steps)))-2);
                               
                           end

                           files_to_move=dir(fullfile(folder,[subjectName '*']));
                       else
                           files_to_move = dir(fullfile(app.BaseFileorDirectoryEditField.Value));
                           files_to_move = files_to_move(~[files_to_move.isdir]);
                       end
                       moveFiles(app,prev_file_dir,files_to_move);
                    elseif strcmp(app.PROCESS,'IndividualStep')      
                        if app.SOURCEFILES
                            prev_file_dir = fullfile(prev_file_dir,'source');
                        else
                           steps = fieldnames(app.PARAMS);
                           if app.SingleFileModeCheckBox.Value
                               step_in_use = regexp(files_to_move{1},fieldnames(app.PARAMS));
                           else
                               step_in_use = regexp(files_to_move(1).name,fieldnames(app.PARAMS));
                           end
                           prev_file_dir = char(fullfile(prev_file_dir, steps(cellfun(@(x) ~isempty(x),step_in_use))));
                           steps=[];
                           step_in_use=[];
                        end
                        func(app.BaseFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',prev_file_dir,'dryrun',false,'individualstep',char(app.IndividualStepsListBox.Value));
                        moveFiles(app,prev_file_dir,files_to_move);
                    else
                        if ~app.SOURCEFILES
                            uialert(app.VHTPUIFigure, {'Please Mark that these are Original Files','','Original File Checkbox Missing Check.'},'Error','Icon','error');
                            return;
                        end
                        func(app.BaseFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',app.OutputExportDirectoryEditField.Value,'dryrun',false);
                        if app.SingleFileModeCheckBox.Value
                            [folder,file,~] = fileparts(app.BaseFileorDirectoryEditField.Value);
                            prev_file_dir = fullfile(folder,'prev_files');
                            files_to_move=dir(fullfile(folder,[file '*']));
                        else
                            files_to_move = dir(fullfile(app.BaseFileorDirectoryEditField.Value));
                            files_to_move = files_to_move(~[files_to_move.isdir]);
                        end
                        moveFiles(app,prev_file_dir,files_to_move);
                    end
                    
                end
            end
        end

        % Value changed function: WorkingfromOriginalFilesCheckBox
        function WorkingfromOriginalFilesCheckBoxChanged(app, event)
            value = app.WorkingfromOriginalFilesCheckBox.Value;
            app.SOURCEFILES = value;
        end

        % Button pushed function: ExportOutputButton
        function ExportOutputButtonPushed(app, event)
           outputvalue=app.OutputExportDirectoryEditField.Value;
           if ~isempty(outputvalue) && ~isempty(app.BaseFileorDirectoryEditField.Value)
               if ~app.SingleFileModeCheckBox.Value
                   inputvalue = app.BaseFileorDirectoryEditField.Value;
                   inputvalue = fullfile(inputvalue,'prev_files');
               else
                   [inputvalue,~,~] = fileparts(app.BaseFileorDirectoryEditField.Value);
                   inputvalue = fullfile(inputvalue,'prev_files');
               end
               sprintf("Exporting Data.\n")
               if isfolder(outputvalue) && isfolder(inputvalue)
                   zip(fullfile(outputvalue,strjoin([app.ParadigmsListBox.Value,datestr(now, 'yyyy_mm_dd_HH_MM_SS')],'_')),inputvalue);
                   uialert(app.VHTPUIFigure, {'Output Files successfully exported.'},'Output Export','Icon','success');
               else
                   uialert(app.VHTPUIFigure, {'Export not completed due to non-existent input or output directory.','','Ensure that you have selected a valid base directory/file and/or output directory.'},'Output Export','Icon','error');
               end
           else
               uialert(app.VHTPUIFigure, {'Export not completed due to missing input or output values.','','Ensure that you have selected a valid base directory/file and/or output directory.'},'Output Export','Icon','error');
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VHTPUIFigure = uifigure('Visible', 'off');
            app.VHTPUIFigure.Name = 'VHTP';
            app.ParadigmsLabel = uilabel(app.VHTPUIFigure);
            app.ParadigmsLabel.Text = 'Paradigms:';
            app.ParadigmsListBox = uilistbox(app.VHTPUIFigure);
            app.ParadigmsListBox.Items = {'{}'};
            app.ParadigmsListBox.ItemsData = {'{}'};
            app.ParadigmsListBox.ValueChangedFcn = createCallbackFcn(app, @ParadigmListBoxChanged, true);
            app.ParadigmsListBox.Enable = 'off';
            app.ParadigmsListBox.Visible = 'off';
            app.ParadigmsListBox.Value = {};
            app.IndividualStepsLabel = uilabel(app.VHTPUIFigure);
            app.IndividualStepsLabel.Text = 'Individual Steps:';
            app.IndividualStepsListBox = uilistbox(app.VHTPUIFigure);
            app.IndividualStepsListBox.Items = {'{}'};
            app.IndividualStepsListBox.ItemsData = {'{}'};
            app.IndividualStepsListBox.Enable = 'off';
            app.IndividualStepsListBox.Visible = 'off';
            app.IndividualStepsListBox.Value = '{}';
            app.ContinueFromDropDownLabel = uilabel(app.VHTPUIFigure);
            app.ContinueFromDropDownLabel.Text = 'Continue From:';
            app.ContinueFromDropDown = uidropdown(app.VHTPUIFigure);
            app.ContinueFromDropDown.Items = {'[ ]'};
            app.ContinueFromDropDown.Enable = 'off';
            app.ContinueFromDropDown.Visible = 'off';
            app.ContinueFromDropDown.Value = '[ ]';
            app.ProcessingStyleButtonGroup = uibuttongroup(app.VHTPUIFigure);
            app.ProcessingStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessingStyleButtonGroupChanged, true);
            app.ProcessingStyleButtonGroup.Title = 'Processing Style';
            app.CompleteProcessButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.CompleteProcessButton.Enable = 'off';
            app.CompleteProcessButton.Visible = 'off';
            app.CompleteProcessButton.Text = 'Complete Process';
            app.IndividualProcessStepButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.IndividualProcessStepButton.Enable = 'off';
            app.IndividualProcessStepButton.Visible = 'off';
            app.IndividualProcessStepButton.Text = 'Individual Process Step';
            app.IndividualProcessStepButton.Value = true;
            app.ContinuationProcessStepButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.ContinuationProcessStepButton.Enable = 'off';
            app.ContinuationProcessStepButton.Visible = 'off';
            app.ContinuationProcessStepButton.Text = 'Continuation Process Step';
            app.RunButton = uibutton(app.VHTPUIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonChanged, true);
            app.RunButton.Text = 'Run';
            app.TestRunCheckBox = uicheckbox(app.VHTPUIFigure);
            app.TestRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestRunCheckBoxChanged, true);
            app.TestRunCheckBox.Text = 'Test Run?';
            app.SelectFilesButton = uibutton(app.VHTPUIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.BaseFileorDirectoryEditFieldLabel = uilabel(app.VHTPUIFigure);
            app.BaseFileorDirectoryEditFieldLabel.Text = 'Base File or Directory:';
            app.BaseFileorDirectoryEditField = uieditfield(app.VHTPUIFigure, 'text');
            app.BaseFileorDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @BaseFileorDirectoryEditFieldChanged, true);
            app.OutputDirectoryLabel = uilabel(app.VHTPUIFigure);
            app.OutputDirectoryLabel.Text = 'Output Export Directory:';
            app.OutputExportDirectoryEditField = uieditfield(app.VHTPUIFigure, 'text');
            app.OutputExportDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @OutputExportDirectoryEditFieldChanged, true);
            app.SelectOutputExportFolderButton = uibutton(app.VHTPUIFigure, 'push');
            app.SelectOutputExportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.SelectOutputExportFolderButton.Text = 'Select Output Export Folder';
            app.SingleFileModeCheckBox = uicheckbox(app.VHTPUIFigure);
            app.SingleFileModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleFileButtonPushed, true);
            app.SingleFileModeCheckBox.Text = 'Single File Mode?';
            app.WorkingfromOriginalFilesCheckBox = uicheckbox(app.VHTPUIFigure);
            app.WorkingfromOriginalFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @WorkingfromOriginalFilesCheckBoxChanged, true);
            app.WorkingfromOriginalFilesCheckBox.Text = 'Working from Original File(s)?';
            app.ExportOutputButton = uibutton(app.VHTPUIFigure, 'push');
            app.ExportOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ExportOutputButtonPushed, true);
            app.ExportOutputButton.Text = 'Export Output';
            % Show the figure after all components are created
            app.VHTPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vhtp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VHTPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VHTPUIFigure)
        end
    end
end