classdef vhtpAnalysisGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        OpenParametersButton            matlab.ui.control.Button
        MaxtrialsCheckBox               matlab.ui.control.CheckBox
        NumofEpochsSpinner              matlab.ui.control.Spinner
        NumofEpochsSpinnerLabel         matlab.ui.control.Label
        loadLastFolders                 matlab.ui.control.Hyperlink
        saveCurrentFolders              matlab.ui.control.Hyperlink
        PreprocessingButton             matlab.ui.control.Button
        ManualScriptButton              matlab.ui.control.Button
        MultiselectOnLabel              matlab.ui.control.Label
        SelectoneButtonGroup            matlab.ui.container.ButtonGroup
        WorkflowStyleLabel              matlab.ui.control.Label
        ContinuationStepButton          matlab.ui.control.ToggleButton
        SingleStepButton                matlab.ui.control.ToggleButton
        AllStepsButton                  matlab.ui.control.ToggleButton
        EditParametersButton            matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        CreateRProjectButton            matlab.ui.control.Button
        RStatConnectorLabel             matlab.ui.control.Label
        OpenResultsButton               matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        AnalysisStepsListBox            matlab.ui.control.ListBox
        AnalysisStepsListBoxLabel       matlab.ui.control.Label
        AnalysisTemplatesListBox        matlab.ui.control.ListBox
        AnalysisLibraryLabel            matlab.ui.control.Label
        MessagesTextArea                matlab.ui.control.TextArea
        MessagesTextAreaLabel           matlab.ui.control.Label
        ExplorePathButton               matlab.ui.control.Button
        OpenChanlocsButton              matlab.ui.control.Button
        ViewDataButton                  matlab.ui.control.Button
        OpeninEEGLABButton              matlab.ui.control.Button
        checkDependencies               matlab.ui.control.Hyperlink
        fileListTable                   matlab.ui.control.Table
        CurrentFileListInfoforSETLabel  matlab.ui.control.Label
        label_filelist                  matlab.ui.control.Label
        results_freespace               matlab.ui.control.Label
        temp_freespace                  matlab.ui.control.Label
        DatasetDetailsLabel             matlab.ui.control.Label
        resultsdir_button               matlab.ui.control.Button
        resultsdir                      matlab.ui.control.EditField
        SelectdatasetSETpathLabel_3     matlab.ui.control.Label
        tempdir                         matlab.ui.control.EditField
        SelectdatasetSETpathLabel_2     matlab.ui.control.Label
        tempdir_button                  matlab.ui.control.Button
        datadir                         matlab.ui.control.EditField
        SelectdatasetSETpathLabel       matlab.ui.control.Label
        IgnoresubfoldersCheckBox        matlab.ui.control.CheckBox
        datadir_button                  matlab.ui.control.Button
        CheckingLabel                   matlab.ui.control.Label
        DependencyStatusLamp            matlab.ui.control.Lamp
        DependencyStatusLampLabel       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        vHTPAnalysisLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        ha % htpAnalysis Class
        WorkflowButtonLabel % Description
         % Description
    end
    
    methods (Access = private) 

        function results = view_handler(app, action)
            switch action
                case 'startupDefaults'
                    app.DependencyStatusLamp.Color = [1 0 0]; % red
                    app.CheckingLabel.Visible = 'On';
                    app.CheckingLabel.Text = 'Checking ...';
                    app.temp_freespace.Visible = 'off';
                    app.results_freespace.Visible = 'off';
                    app.label_filelist.Visible = 'On';

                    app.setup_analysis_parameters();

                    % define Workflow style buttons
                    app.WorkflowButtonLabel = struct();
                    app.WorkflowButtonLabel.All       = 'All Steps (in Order)';
                    app.WorkflowButtonLabel.Single    = 'Selected Step';
                    app.WorkflowButtonLabel.Continue  = 'Continue from Step';

                    app.AllStepsButton.Text = app.WorkflowButtonLabel.All;
                    app.SingleStepButton.Text = app.WorkflowButtonLabel.Single;
                    app.ContinuationStepButton.Text = app.WorkflowButtonLabel.Continue;
                    
                    app.AllStepsButton.UserData.Flag = "All";
                    app.SingleStepButton.UserData.Flag = "IndividualStep";
                    app.ContinuationStepButton.UserData.Flag = "Continuation";

                    % selected processing workflow (default all)
                    event = struct();
                    SelectoneButtonGroupSelectionChanged(app, event);

                    app.MaxtrialsCheckBoxValueChanged;

                    if app.ha.proj_status.isLoadFromPreprocessingApp
                        app.loadFromPreprocessingGui;
                    end


                case 'updateDependenciesGui'
                    isValid = app.ha.get_dependencies_status;
                    if isValid
                         app.DependencyStatusLamp.Color = [0 1 0]; % green
                         app.CheckingLabel.Visible = 'On';
                         app.CheckingLabel.Text = 'All toolkits installed.';
                         app.CheckingLabel.FontColor = [0 0 0];
                    else
                        app.DependencyStatusLamp.Color = [1 0 0]; % red
                        app.CheckingLabel.Visible = 'On';
                        app.CheckingLabel.Text = 'Run htpDoctor to fix.';
                    end

                case 'updateFileCount'
                    value = app.ha.proj_status.data_dir;
                    figure(app.UIFigure);
                    if value==0
                        app.label_filelist.Text = '...';
                        return; 
                    else
                        app.datadir.Value = value;
                        [fileCount, subfolderCount] = app.ha.createFileList(value);

                        fileCountLabel = sprintf('%d Files', fileCount);
                        if subfolderCount > 1
                            subFolderLabel = sprintf('in %d Folders.', subfolderCount);
                        else
                            subFolderLabel = '';
                        end

                        app.label_filelist.Text = [fileCountLabel subFolderLabel];
                        app.label_filelist.Visible = 'on';

                        filelist = app.ha.input_filelist;
                        if subfolderCount > 1
                            hideInputFolder = @(x) strrep(x, app.datadir.Value, '');
                        else
                            hideInputFolder = @(x) x;
                        end
                        if isempty(filelist) == false
                              eegtable = table();

                              for i = 1 :  size(filelist,1)
                                  eegrow = table();
                                  eeginfo = pop_loadset('filename',  filelist{i,2}{1}, 'filepath', filelist{i,1}{1}, 'loadmode', 'info');

                                  eegrow.filename = string(eeginfo.filename);
                                  eegrow.epochs = eeginfo.trials;
                                  eegrow.xmax = eeginfo.xmax;
                                  eegrow.srate = eeginfo.srate;
                                  eegrow.nbchan = eeginfo.nbchan;
                                  eegrow.subfolder = string(eeginfo.filepath);

                                  eegtable = vertcat( eegtable, eegrow);
                              end
                              app.fileListTable.Data = eegtable; % [filelist.filename  cellfun(hideInputFolder, filelist.filepath, 'UniformOutput', false)];
                              app.fileListTable.ColumnName = eegtable.Properties.VariableNames; %{'Filename','Subfolder'};
                              app.ha.proj_status.minTrials = min(eegtable.epochs);
                              app.NumofEpochsSpinner.Value =  app.ha.proj_status.minTrials;
                        else
                            app.fileListTable.Data = [];
                            app.fileListTable.ColumnName = {'Filename','Epochs', 'xmax', 'srate', 'nbchan', 'Subfolder'};
                        end

                    end
                case 'refreshParameters'

                    app.ha.extractCurrentParameterFile(app.AnalysisTemplatesListBox.Value);
                    app.AnalysisStepsListBox.Items = app.ha.parameterHandler('listParametersForTable');
                    app.AnalysisStepsListBox.ItemsData = app.ha.parameterHandler('listParametersForTable');

                otherwise
                    fprintf('Unhandled action in viewHandler.')

            end

        end
        function load_current_file_selection( app )
            if isempty(app.fileListTable.Selection)
                current_sel = 1;
            else
                current_sel = app.fileListTable.Selection;
            end
            app.ha.getCurrentFileByRowNumber( current_sel ); % will store in proj_status.active_file;
            app.ha.loadActiveEegSelection;
        end

        function use_debug_mode(app)
            % set debug directory
            event.EventName = 'Dummy';
            app.datadir.Value = 'F:\Projects\MeaTest\Data';
            app.datadir_buttonButtonPushed(event);

            app.tempdir.Value = 'F:\Projects\MeaTest\Tempfiles';
            app.tempdir_buttonButtonPushed(event);

            app.resultsdir.Value = 'F:\Projects\MeaTest\Results';
            app.resultsdir_buttonButtonPushed(event);
        end
        function setup_analysis_parameters( app )

            available_templates = app.ha.listAnalysisParameterFiles;
            app.AnalysisTemplatesListBox.Items = available_templates;
            app.AnalysisTemplatesListBox.ItemsData = available_templates;
            app.AnalysisTemplatesListBox.Visible = 'on';
            app.AnalysisTemplatesListBox.Enable = 'on';

            app.view_handler('refreshParameters')

        end
        function saveLastDirectory(app)
            vhtpAnalysis_snapshot = struct();
            vhtpAnalysis_snapshot.datadir.Value = app.datadir.Value;
            vhtpAnalysis_snapshot.tempdir.Value = app.tempdir.Value;
            vhtpAnalysis_snapshot.resultsdir.Value = app.resultsdir.Value;
            try
                save('vhtp_gui_savedata.mat', 'vhtpAnalysis_snapshot', '-append');
            catch
                save('vhtp_gui_savedata.mat', 'vhtpAnalysis_snapshot');
            end
        end
        function loadFromPreprocessingGui(app)
            
            event.EventName = 'Dummy';
            app.datadir.Value       = app.ha.proj_status.preprocessingOutputDirectory;
            app.datadir_buttonButtonPushed(event);

        end
        function loadLastDirectory(app)
            load('vhtp_gui_savedata.mat', 'vhtpAnalysis_snapshot');
            
            event.EventName = 'Dummy';
            app.datadir.Value       = vhtpAnalysis_snapshot.datadir.Value;
            app.tempdir.Value       = vhtpAnalysis_snapshot.tempdir.Value;
            app.resultsdir.Value    = vhtpAnalysis_snapshot.resultsdir.Value;

            app.datadir_buttonButtonPushed(event);
            app.tempdir_buttonButtonPushed(event);
            app.resultsdir_buttonButtonPushed(event);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, preprocessing_directory)
            % htpAnalysis Entry Point

            app.ha = htpAnalysisClass; % htp analysis (ha)
            
            if nargin > 1
                app.ha.proj_status.isLoadFromPreprocessingApp = true;
                app.ha.proj_status.preprocessingOutputDirectory = preprocessing_directory;
            else
                app.ha.proj_status.isLoadFromPreprocessingApp = false;

            end

            app.view_handler('startupDefaults');
            app.view_handler('updateDependenciesGui');

            % debug
            % app.use_debug_mode()
        end

        % Value changed function: IgnoresubfoldersCheckBox
        function IgnoresubfoldersCheckBoxValueChanged(app, event)
            value = app.IgnoresubfoldersCheckBox.Value;
            app.ha.set_ignore_subdirectory_state(value);

            if ~isempty(app.datadir.Value)
                app.view_handler('updateFileCount');
            end
        end

        % Button pushed function: datadir_button
        function datadir_buttonButtonPushed(app, event)
            if event.EventName == categorical("ButtonPushed")
                desc = sprintf("Choose a data directory:");
                value = uigetdir(app.ha.proj_status.data_dir, desc);
                %value = app.ha.pick_return_directory();
            else
                value = app.datadir.Value;
            end
            
            if value==0, return; else
                app.datadir.Value = value;
                app.ha.proj_status.data_dir = value;
                app.view_handler('updateFileCount');
            end
        end

        % Button pushed function: tempdir_button
        function tempdir_buttonButtonPushed(app, event)
            if event.EventName == categorical("ButtonPushed")
                desc = sprintf("Choose a temporary directory:");
                if ~isempty(app.ha.proj_status.data_dir)
                    value = uigetdir(app.ha.proj_status.data_dir, desc);
                else
                    value = uigetdir(app.ha.proj_status.temp_dir, desc);
                end
                % o.proj_status.last_directory = dir_name;
                %value = app.ha.pick_return_directory();
            else
                value = app.tempdir.Value;
            end

            figure(app.UIFigure);
            if value==0, return;
            else, app.tempdir.Value = value;
                app.ha.proj_status.temp_dir = value;
                app.temp_freespace.Visible = 'on';
                app.temp_freespace.Text = sprintf('%.1f GB Free', ...
                    app.ha.getUsableDiskSpace(value));
            end

        end

        % Button pushed function: resultsdir_button
        function resultsdir_buttonButtonPushed(app, event)
            if event.EventName == categorical("ButtonPushed")
                % value = app.ha.pick_return_directory();
                desc = sprintf("Choose a results directory:");
                if ~isempty(app.ha.proj_status.data_dir)
                    value = uigetdir(app.ha.proj_status.data_dir, desc);
                else
                    value = uigetdir(app.ha.proj_status.results_dir, desc);
                end
            else
                value = app.resultsdir.Value;
            end
            
            figure(app.UIFigure);
            if value==0, return; else, app.resultsdir.Value = value;
                app.ha.proj_status.results_dir = value;
                app.results_freespace.Visible = 'on';
                app.results_freespace.Text = sprintf('%.1f GB Free', ...
                    app.ha.getUsableDiskSpace(value));
            end
        end

        % Callback function: checkDependencies
        function checkDependenciesClicked(app, event)
            app.ha.check_dependencies;
            app.view_handler('updateDependenciesGui');
        end

        % Button pushed function: ViewDataButton
        function ViewDataButtonPushed(app, event)
           try
               app.load_current_file_selection;
               app.ha.singleEegHandler('openEegPlot');
           catch
           end
        end

        % Button pushed function: OpeninEEGLABButton
        function OpeninEEGLABButtonPushed(app, event)
              app.load_current_file_selection;
              app.ha.singleEegHandler('openInEeglab');
        end

        % Button pushed function: OpenChanlocsButton
        function OpenChanlocsButtonPushed(app, event)
            app.load_current_file_selection;
            app.ha.singleEegHandler('openChanLocs');
        end

        % Button pushed function: ExplorePathButton
        function ExplorePathButtonPushed(app, event)
              app.load_current_file_selection;
            app.ha.singleEegHandler('openExplorer');
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
           app.ha.assignProcessingSteps(app.AnalysisStepsListBox.Value);
            
           app.ha.run_analysis_loop;

        end

        % Button pushed function: OpenResultsButton
        function OpenResultsButtonPushed(app, event)
            app.ha.openResultsFolder;
        end

        % Value changed function: AnalysisTemplatesListBox
        function AnalysisTemplatesListBoxValueChanged(app, event)
            value = app.AnalysisTemplatesListBox.Value;
            
            % app.ha.extractCurrentParameterFile(value);
            app.view_handler('refreshParameters')

        end

        % Button pushed function: EditParametersButton
        function EditParametersButtonPushed(app, event)
            app.ha.parameterHandler('openParameterFileForEditing');            
        end

        % Selection changed function: SelectoneButtonGroup
        function SelectoneButtonGroupSelectionChanged(app, event)

            selectedButton = app.SelectoneButtonGroup.SelectedObject;
            app.ha.assignProcessingType(selectedButton.UserData.Flag);

            switch selectedButton.UserData.Flag
                case 'All'
                    app.AnalysisStepsListBox.Multiselect = false;
                    app.AnalysisStepsListBox.Enable = false;
                    app.MultiselectOnLabel.Visible = 0;
                case 'IndividualStep'
                    app.MultiselectOnLabel.Visible = 1;
                    app.AnalysisStepsListBox.Multiselect = true;
                    app.AnalysisStepsListBox.Enable = true;
                case 'Continuation'
                    app.MultiselectOnLabel.Visible = 0;
                    app.AnalysisStepsListBox.Multiselect = false;
                    app.AnalysisStepsListBox.Enable = true;
            end

        end

        % Button pushed function: ManualScriptButton
        function ManualScriptButtonPushed(app, event)
            app.ha.createManualTemplate;
        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            vhtpPreprocessGui(app.datadir.Value, app.tempdir.Value);
        end

        % Callback function: saveCurrentFolders
        function saveCurrentFoldersHyperlinkClicked(app, event)
            app.saveLastDirectory;
        end

        % Callback function: loadLastFolders
        function loadLastFoldersHyperlinkClicked(app, event)
            app.loadLastDirectory;
        end

        % Value changed function: MaxtrialsCheckBox
        function MaxtrialsCheckBoxValueChanged(app, event)
            value = app.MaxtrialsCheckBox.Value;
            app.ha.proj_status.useMaxEpochs = value;
          
            if value
               app.NumofEpochsSpinner.Enable = 0;
            else
                app.NumofEpochsSpinner.Enable = 1;
            end
        end

        % Value changed function: NumofEpochsSpinner
        function NumofEpochsSpinnerValueChanged(app, event)
            value = app.NumofEpochsSpinner.Value;
            app.ha.proj_status.minTrials = value;
        end

        % Button pushed function: OpenParametersButton
        function OpenParametersButtonPushed(app, event)
  
        end

        % Callback function: Hyperlink2
        function Hyperlink2Clicked(app, event)
            app.setup_analysis_parameters();
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            app.ha.openPathInExplorer( fullfile(app.ha.proj_status.vhtp_dir, 'parameters' ) );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'man', 'figures', 'logo.png');
            app.vHTPAnalysisLabel = uilabel(app.UIFigure);
            app.vHTPAnalysisLabel.Text = 'vHTP Analysis';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Performs batch analysis on SET files';
            app.DependencyStatusLampLabel = uilabel(app.UIFigure);
            app.DependencyStatusLampLabel.Text = 'Dependency Status:';
            app.DependencyStatusLamp = uilamp(app.UIFigure);
            app.CheckingLabel = uilabel(app.UIFigure);
            app.CheckingLabel.Text = 'Checking ...';
            app.datadir_button = uibutton(app.UIFigure, 'push');
            app.datadir_button.ButtonPushedFcn = createCallbackFcn(app, @datadir_buttonButtonPushed, true);
            app.datadir_button.Text = 'Select';
            app.IgnoresubfoldersCheckBox = uicheckbox(app.UIFigure);
            app.IgnoresubfoldersCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoresubfoldersCheckBoxValueChanged, true);
            app.IgnoresubfoldersCheckBox.Text = 'Ignore subfolders';
            app.SelectdatasetSETpathLabel = uilabel(app.UIFigure);
            app.SelectdatasetSETpathLabel.Text = 'Data Directory (*.SET):';
            app.datadir = uieditfield(app.UIFigure, 'text');
            app.tempdir_button = uibutton(app.UIFigure, 'push');
            app.tempdir_button.ButtonPushedFcn = createCallbackFcn(app, @tempdir_buttonButtonPushed, true);
            app.tempdir_button.Text = 'Select';
            app.SelectdatasetSETpathLabel_2 = uilabel(app.UIFigure);
            app.SelectdatasetSETpathLabel_2.Text = 'Temporary Directory:';
            app.tempdir = uieditfield(app.UIFigure, 'text');
            app.SelectdatasetSETpathLabel_3 = uilabel(app.UIFigure);
            app.SelectdatasetSETpathLabel_3.Text = 'Results Directory:';
            app.resultsdir = uieditfield(app.UIFigure, 'text');
            app.resultsdir_button = uibutton(app.UIFigure, 'push');
            app.resultsdir_button.ButtonPushedFcn = createCallbackFcn(app, @resultsdir_buttonButtonPushed, true);
            app.resultsdir_button.Text = 'Select';
            app.DatasetDetailsLabel = uilabel(app.UIFigure);
            app.DatasetDetailsLabel.Text = 'Dataset Details';
            app.temp_freespace = uilabel(app.UIFigure);
            app.temp_freespace.Text = 'Free: X gb';
            app.results_freespace = uilabel(app.UIFigure);
            app.results_freespace.Text = 'Free: X gb';
            app.label_filelist = uilabel(app.UIFigure);
            app.label_filelist.Text = '...';
            app.CurrentFileListInfoforSETLabel = uilabel(app.UIFigure);
            app.CurrentFileListInfoforSETLabel.Text = 'Current File List Info for *.SET';
            app.fileListTable = uitable(app.UIFigure);
            app.fileListTable.ColumnName = {'Filename'; 'Epochs'; 'Subfolder'};
            app.fileListTable.RowName = {};
            app.fileListTable.SelectionType = 'row';
            app.fileListTable.Multiselect = 'off';
            app.checkDependencies = uihyperlink(app.UIFigure);
            app.checkDependencies.HyperlinkClickedFcn = createCallbackFcn(app, @checkDependenciesClicked, true);
            app.checkDependencies.Text = 'Click to recheck';
            app.OpeninEEGLABButton = uibutton(app.UIFigure, 'push');
            app.OpeninEEGLABButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninEEGLABButtonPushed, true);
            app.OpeninEEGLABButton.Text = 'Open in EEGLAB';
            app.ViewDataButton = uibutton(app.UIFigure, 'push');
            app.ViewDataButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton.Text = 'View Data';
            app.OpenChanlocsButton = uibutton(app.UIFigure, 'push');
            app.OpenChanlocsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenChanlocsButtonPushed, true);
            app.OpenChanlocsButton.Text = 'Open Chanlocs';
            app.ExplorePathButton = uibutton(app.UIFigure, 'push');
            app.ExplorePathButton.ButtonPushedFcn = createCallbackFcn(app, @ExplorePathButtonPushed, true);
            app.ExplorePathButton.Text = 'Explore Path';
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.AnalysisLibraryLabel = uilabel(app.UIFigure);
            app.AnalysisLibraryLabel.Text = 'Analysis Templates:';
            app.AnalysisTemplatesListBox = uilistbox(app.UIFigure);
            app.AnalysisTemplatesListBox.ValueChangedFcn = createCallbackFcn(app, @AnalysisTemplatesListBoxValueChanged, true);
            app.AnalysisStepsListBoxLabel = uilabel(app.UIFigure);
            app.AnalysisStepsListBoxLabel.Text = 'Analysis Steps:';
            app.AnalysisStepsListBox = uilistbox(app.UIFigure);
            app.AnalysisStepsListBox.Multiselect = 'on';
            app.AnalysisStepsListBox.Value = {'Item 1'};
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.OpenResultsButton = uibutton(app.UIFigure, 'push');
            app.OpenResultsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenResultsButtonPushed, true);
            app.OpenResultsButton.Text = 'Open Results';
            app.RStatConnectorLabel = uilabel(app.UIFigure);
            app.RStatConnectorLabel.Text = 'R Stat Connector';
            app.CreateRProjectButton = uibutton(app.UIFigure, 'push');
            app.CreateRProjectButton.Text = 'Create R Project';
            app.Button = uibutton(app.UIFigure, 'push');
            app.EditParametersButton = uibutton(app.UIFigure, 'push');
            app.EditParametersButton.ButtonPushedFcn = createCallbackFcn(app, @EditParametersButtonPushed, true);
            app.EditParametersButton.Text = 'Edit Parameters';
            app.SelectoneButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectoneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectoneButtonGroupSelectionChanged, true);
            app.SelectoneButtonGroup.Title = 'Select one:';
            app.AllStepsButton = uitogglebutton(app.SelectoneButtonGroup);
            app.AllStepsButton.Text = 'All Steps';
            app.AllStepsButton.Value = true;
            app.SingleStepButton = uitogglebutton(app.SelectoneButtonGroup);
            app.SingleStepButton.Text = 'Single Step';
            app.ContinuationStepButton = uitogglebutton(app.SelectoneButtonGroup);
            app.ContinuationStepButton.Text = 'Continuation Step';
            app.WorkflowStyleLabel = uilabel(app.SelectoneButtonGroup);
            app.WorkflowStyleLabel.Text = '6. Workflow Style:';
            app.MultiselectOnLabel = uilabel(app.UIFigure);
            app.MultiselectOnLabel.Text = 'Multiselect On';
            app.ManualScriptButton = uibutton(app.UIFigure, 'push');
            app.ManualScriptButton.ButtonPushedFcn = createCallbackFcn(app, @ManualScriptButtonPushed, true);
            app.ManualScriptButton.Text = 'Manual Script';
            app.PreprocessingButton = uibutton(app.UIFigure, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.saveCurrentFolders = uihyperlink(app.UIFigure);
            app.saveCurrentFolders.HyperlinkClickedFcn = createCallbackFcn(app, @saveCurrentFoldersHyperlinkClicked, true);
            app.saveCurrentFolders.Text = 'Save Snapshot';
            app.loadLastFolders = uihyperlink(app.UIFigure);
            app.loadLastFolders.HyperlinkClickedFcn = createCallbackFcn(app, @loadLastFoldersHyperlinkClicked, true);
            app.loadLastFolders.Text = 'Load Snapshot';
            app.NumofEpochsSpinnerLabel = uilabel(app.UIFigure);
            app.NumofEpochsSpinnerLabel.Text = 'Num. of Epochs:';
            app.NumofEpochsSpinner = uispinner(app.UIFigure);
            app.NumofEpochsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumofEpochsSpinnerValueChanged, true);
            app.MaxtrialsCheckBox = uicheckbox(app.UIFigure);
            app.MaxtrialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaxtrialsCheckBoxValueChanged, true);
            app.MaxtrialsCheckBox.Text = 'Max trials';
            app.OpenParametersButton = uibutton(app.UIFigure, 'push');
            app.OpenParametersButton.ButtonPushedFcn = createCallbackFcn(app, @OpenParametersButtonPushed, true);
            app.OpenParametersButton.Text = 'Open Parameters';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Open Parameter Folder';
            app.Hyperlink2 = uihyperlink(app.UIFigure);
            app.Hyperlink2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2Clicked, true);
            app.Hyperlink2.Text = 'Refresh Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vhtpAnalysisGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end