classdef vhtpImportGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vHTPImportUtilityUIFigure       matlab.ui.Figure
        ConfigureMenu                   matlab.ui.container.Menu
        OpenEEGsystemcatalogMenu        matlab.ui.container.Menu
        NegateFilterCheckBox            matlab.ui.control.CheckBox
        KeyWordFiilterEditField         matlab.ui.control.EditField
        KeyWordFiilterEditFieldLabel    matlab.ui.control.Label
        BIOSIGLamp                      matlab.ui.control.Lamp
        BIOSIGLampLabel                 matlab.ui.control.Label
        addBioSigExt                    matlab.ui.control.Button
        FileImportTableLabel            matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        AdvancetoPreprocessingModuleButton  matlab.ui.control.Button
        AdvancetoQualityAssuranceModuleButton  matlab.ui.control.Button
        OpenInputFolderButton           matlab.ui.control.Button
        ReadytoImportLamp               matlab.ui.control.Lamp
        ReadytoImportLampLabel          matlab.ui.control.Label
        SelectSystemandScanFilesButton  matlab.ui.control.Button
        label_freespace                 matlab.ui.control.Label
        FixEegLabPath                   matlab.ui.control.Button
        RequirementsLabel               matlab.ui.control.Label
        EEGLABLamp                      matlab.ui.control.Lamp
        EEGLABLampLabel                 matlab.ui.control.Label
        label_filelist                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        IgnoresubfoldersCheckBox        matlab.ui.control.CheckBox
        OpenOutputFolderButton          matlab.ui.control.Button
        ConverttoSETButton              matlab.ui.control.Button
        TestImportButton                matlab.ui.control.Button
        SelectEEGSystemDropDown         matlab.ui.control.DropDown
        SelectEEGSystemDropDownLabel    matlab.ui.control.Label
        OutputSETDirectoryEditField     matlab.ui.control.EditField
        OutputSETDirectoryEditFieldLabel  matlab.ui.control.Label
        SelectPathButton_2              matlab.ui.control.Button
        SelectPathButton                matlab.ui.control.Button
        inputPath                       matlab.ui.control.EditField
        SelectdirectoryofmachinespecificEEGorSETfilesLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        vHTPImportToolLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        EegLabIsAvailable % Description
        dependencyChecks % Description
        BioSigIsAvailable % Description
        CurrentKeyword % Current Filter Keyword
        NotKeyword % T/F
    end

    methods (Access = private)

        % Private Methods
        function  [fileCount, subFolderCount]  = countFileList(app)
            try
                if isempty(app.CurrentKeyword)
                    app.CurrentKeyword = '';
                end
            [results] = util_htpImportEeg(app.inputPath.Value, ...
                'nettype', app.SelectEEGSystemDropDown.Value, 'outputdir', ...
                app.OutputSETDirectoryEditField.Value, ...
                'subdirOn', ~app.IgnoresubfoldersCheckBox.Value, ...
                'keyword', app.CurrentKeyword, ...
                'notKeyword', app.NotKeyword, ...
                'dryrun', false, 'listing', true );
            catch
                disp("Error: Check if form fields are complete.")
            end
            hideInputFolder = @(x) strrep(x, app.inputPath.Value, '');
            if isempty(results) == false
                fileCount = height(results);
                subFolderCount = height(unique(results.filepath));
                app.UITable.Data = [results.filename  cellfun(hideInputFolder, results.filepath, 'UniformOutput', false)];
                app.UITable.ColumnName = {'Filename','Subfolder'};
            else
                fileCount = 0;
                subFolderCount = 0;
                app.UITable.Data = [];
                app.UITable.ColumnName = {'Filename','Subfolder'};
            end
        end

        function checkIfEegLabAvailable( app )
            app.EegLabIsAvailable = htpDoctor('check_eeglab');
        end
        function checkIfBioSigAvailable( app )
            app.BioSigIsAvailable = htpDoctor('check_biosig');
            if app.BioSigIsAvailable
                app.actionHandler('bioSigExtAvailable');
            end
        end
        function fixBioSigExt( app )
            while ~app.BioSigIsAvailable
                try
                    app.checkIfBioSigAvailable;  
                    assert(app.BioSigIsAvailable);
                catch
                    try
                        res = htpDoctor('fix_biosig');
                        assert(res);
                        app.checkIfBioSigAvailable;
                    catch
                        error('Biosig Toolbox autoinstall failure. Please add through EEGLAB and restart.')
                    end
                end
            end
        end
        function fixEegLabPath( app )
            app.EegLabIsAvailable = false;
            while app.EegLabIsAvailable == false
                try
                    app.checkIfEegLabAvailable();
                    assert( app.EegLabIsAvailable );
                    app.actionHandler('eegLabIsAvailable')
                catch
                    htpDoctor('fix_eeglab');
                end
            end
        end
        function space_in_gb = getUsableDiskSpace(app)
            FileObj      = java.io.File(app.OutputSETDirectoryEditField.Value);
            space_in_gb = FileObj.getUsableSpace / 1e9;
        end

        function actionHandler(app, action)
            switch action
                case 'startup'
                    app.label_freespace.Text = "";
                    app.label_filelist.Text = "";

                    app.CurrentKeyword = [];
                    app.NotKeyword = false;

                    % lamps
                    app.ReadytoImportLamp.Color = [1,0,0];

                    % buttons
                    app.FixEegLabPath.Enable = "on";
                    app.TestImportButton.Enable = "off";
                    app.ConverttoSETButton.Enable = "off";
                    app.OpenOutputFolderButton.Enable = "off";
                    app.OpenInputFolderButton.Enable = "off";

                    %table
                    app.UITable.ColumnName = {'Filename','Subfolder'};

                    % populate channel types
                    netCatalog = util_htpReadNetCatalog;
                    app.SelectEEGSystemDropDown.Items =  {netCatalog.net_displayname};
                    app.SelectEEGSystemDropDown.ItemsData =  {netCatalog.net_name};

                    if app.EegLabIsAvailable
                        app.actionHandler('eegLabIsAvailable');
                    end

                    if app.BioSigIsAvailable
                        app.actionHandler('bioSigExtAvailable');
                    end


                case 'freespace'
                    app.label_freespace.Text = sprintf('%.1f GB Free', ...
                        app.getUsableDiskSpace());

                case 'eegLabIsAvailable'
                    app.EEGLABLamp.Color = [0,1,0];
                    app.FixEegLabPath.Enable = "off";

                case 'bioSigExtAvailable'
                    app.BIOSIGLamp.Color = [0,1,0];
                    app.addBioSigExt.Enable = "off";

                case 'updateFileListCount'
                    [fileCount, subfolderCount] = app.countFileList;
                    app.label_filelist.Text =  sprintf('%d Files. %d Subfolders.', ...
                        fileCount,subfolderCount);

                    if fileCount == 0
                        app.actionHandler('notReadyToImport');
                    else
                        app.actionHandler('readyToImport');
                    end

                case 'readyToImport'
                    app.TestImportButton.Enable = "on";
                    app.ConverttoSETButton.Enable = "on";
                    app.ReadytoImportLamp.Color = [0,1,0];
                    app.label_filelist.FontColor = [0,1,0];

                case 'notReadyToImport'
                    app.TestImportButton.Enable = "off";
                    app.ConverttoSETButton.Enable = "off";
                    app.ReadytoImportLamp.Color = [1,0,0];
                    app.label_filelist.FontColor = [1,0,0];


            end
        end

        function crossplatform_open(app,fileOrFolder)
            if ispc
                % Code to run on Windows platform
                winopen(fileOrFolder);
            elseif ismac
                % Code to run on Mac platform
                cmd = sprintf('open "%s"', fileOrFolder);
                system(cmd);
            elseif isunix
                % Code to run on Linux platform
                cmd = sprintf('xdg-open "%s"', fileOrFolder);
                system(cmd);
            else
                error('Platform not supported');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % setup up toolbox paths
            app.checkIfEegLabAvailable;
            app.checkIfBioSigAvailable;

            [vhtpfolder, ~, ~] = fileparts(which(mfilename));
            addpath(genpath(fullfile( vhtpfolder )));

            app.Image.ImageSource = "Import@2x.png";
            app.vHTPImportUtilityUIFigure.Icon = "cbllogo.png";

            app.dependencyChecks = htpDoctor; 


            % setup environment
            app.actionHandler( 'startup' );

        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)

            [folder] = uigetdir('Select a Folder for Input');
            if folder==0
                figure(app.vHTPImportUtilityUIFigure);
                return;
            end
            app.inputPath.Value = folder;
            app.OpenInputFolderButton.Enable = "on";
            app.actionHandler('notReadyToImport');

            figure(app.vHTPImportUtilityUIFigure);
        end

        % Button pushed function: SelectPathButton_2
        function SelectPathButton_2Pushed(app, event)
            [folder] = uigetdir('Select a Folder for Input');
            if folder==0
                figure(app.vHTPImportUtilityUIFigure);
                return;
            end
            app.OutputSETDirectoryEditField.Value = folder;
            figure(app.vHTPImportUtilityUIFigure);

            app.actionHandler( 'freespace' );
            app.OpenOutputFolderButton.Enable = "on";
            app.actionHandler('notReadyToImport');

            % app.checkFileList;
        end

        % Button pushed function: TestImportButton
        function TestImportButtonPushed(app, event)
            [results] = util_htpImportEeg(app.inputPath.Value, ...
                'nettype', app.SelectEEGSystemDropDown.Value, ...
                'subdirOn', ~app.IgnoresubfoldersCheckBox.Value, ...
                'keyword', app.CurrentKeyword, ...
                'notKeyword', app.NotKeyword, ...
                'outputdir', app.OutputSETDirectoryEditField.Value, ...
                'dryrun', true );

        end

        % Button pushed function: ConverttoSETButton
        function ConverttoSETButtonPushed(app, event)
            try
                [results] = util_htpImportEeg(app.inputPath.Value, ...
                    'nettype', app.SelectEEGSystemDropDown.Value, ...
                    'outputdir', app.OutputSETDirectoryEditField.Value, ...
                    'subdirOn', ~app.IgnoresubfoldersCheckBox.Value, ...
                    'keyword', app.CurrentKeyword, ...
                    'notKeyword', app.NotKeyword, ...
                    'dryrun', false );
                % write CSV to path
            catch ME
                disp("Error in ConverttoSETButtonPushed");
                disp(ME.message);
            end
        end

        % Button pushed function: OpenOutputFolderButton
        function OpenOutputFolderButtonPushed(app, event)
            app.crossplatform_open(app.OutputSETDirectoryEditField.Value);
        end

        % Value changed function: IgnoresubfoldersCheckBox
        function IgnoresubfoldersCheckBoxValueChanged(app, event)
            value = app.IgnoresubfoldersCheckBox.Value;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            web('http://github.com/cincibrainlab/vhtp');
        end

        % Menu selected function: OpenEEGsystemcatalogMenu
        function OpenEEGsystemcatalogMenuSelected(app, event)
            open('cfg_htpEegSystems.xml');
        end

        % Button pushed function: FixEegLabPath
        function FixEegLabPathButtonPushed(app, event)
            app.fixEegLabPath;
        end

        % Button pushed function: SelectSystemandScanFilesButton
        function SelectSystemandScanFilesButtonPushed(app, event)
            app.actionHandler( 'updateFileListCount' );

        end

        % Button pushed function: OpenInputFolderButton
        function OpenInputFolderButtonPushed(app, event)
            app.crossplatform_open(app.inputPath.Value);

        end

        % Button pushed function: addBioSigExt
        function addBioSigExtButtonPushed(app, event)
            % check if biosig is available
            app.fixBioSigExt;
        end

        % Value changed function: KeyWordFiilterEditField
        function KeyWordFiilterEditFieldValueChanged(app, event)
            value = app.KeyWordFiilterEditField.Value;
            app.CurrentKeyword = value;
        end

        % Value changed function: NegateFilterCheckBox
        function NegateFilterCheckBoxValueChanged(app, event)
            value = app.NegateFilterCheckBox.Value;
            app.NotKeyword = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.vHTPImportUtilityUIFigure = uifigure('Visible', 'off');
            app.vHTPImportUtilityUIFigure.Name = 'vHTP Import Utility';
            app.vHTPImportUtilityUIFigure.Icon = fullfile(pathToMLAPP, 'man', '87449739.png');
            app.ConfigureMenu = uimenu(app.vHTPImportUtilityUIFigure);
            app.ConfigureMenu.Text = 'Configure';
            app.OpenEEGsystemcatalogMenu = uimenu(app.ConfigureMenu);
            app.OpenEEGsystemcatalogMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenEEGsystemcatalogMenuSelected, true);
            app.OpenEEGsystemcatalogMenu.Text = 'Open EEG system catalog';
            app.vHTPImportToolLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.vHTPImportToolLabel.Text = 'vHTP Import Tool';
            app.Label = uilabel(app.vHTPImportUtilityUIFigure);
            app.Label.Text = 'Imports machine-specific EEG files to EEGLAB SET.';
            app.SelectdirectoryofmachinespecificEEGorSETfilesLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.SelectdirectoryofmachinespecificEEGorSETfilesLabel.Text = 'Select directory of machine-specific EEG or SET files:';
            app.inputPath = uieditfield(app.vHTPImportUtilityUIFigure, 'text');
            app.SelectPathButton = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.SelectPathButton_2 = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.SelectPathButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_2Pushed, true);
            app.SelectPathButton_2.Text = 'Select Path';
            app.OutputSETDirectoryEditFieldLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.OutputSETDirectoryEditFieldLabel.Text = 'Output SET Directory:';
            app.OutputSETDirectoryEditField = uieditfield(app.vHTPImportUtilityUIFigure, 'text');
            app.SelectEEGSystemDropDownLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.SelectEEGSystemDropDownLabel.Text = 'Select EEG System:';
            app.SelectEEGSystemDropDown = uidropdown(app.vHTPImportUtilityUIFigure);
            app.SelectEEGSystemDropDown.Items = {'EGI Hydrocel 128'};
            app.SelectEEGSystemDropDown.ItemsData = {'EGI128'};
            app.SelectEEGSystemDropDown.Value = 'EGI128';
            app.TestImportButton = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.TestImportButton.ButtonPushedFcn = createCallbackFcn(app, @TestImportButtonPushed, true);
            app.TestImportButton.Text = 'Test Import';
            app.ConverttoSETButton = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.ConverttoSETButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoSETButtonPushed, true);
            app.ConverttoSETButton.Text = 'Convert to SET';
            app.OpenOutputFolderButton = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.OpenOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenOutputFolderButtonPushed, true);
            app.OpenOutputFolderButton.Text = 'Open Output Folder';
            app.IgnoresubfoldersCheckBox = uicheckbox(app.vHTPImportUtilityUIFigure);
            app.IgnoresubfoldersCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoresubfoldersCheckBoxValueChanged, true);
            app.IgnoresubfoldersCheckBox.Text = 'Ignore subfolders';
            app.Image = uiimage(app.vHTPImportUtilityUIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'man', 'figures', 'Import@2x.png');
            app.label_filelist = uilabel(app.vHTPImportUtilityUIFigure);
            app.label_filelist.Text = 'X files';
            app.EEGLABLampLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.EEGLABLampLabel.Text = 'EEGLAB :';
            app.EEGLABLamp = uilamp(app.vHTPImportUtilityUIFigure);
            app.RequirementsLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.RequirementsLabel.Text = 'Requirements:';
            app.FixEegLabPath = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.FixEegLabPath.ButtonPushedFcn = createCallbackFcn(app, @FixEegLabPathButtonPushed, true);
            app.FixEegLabPath.Text = 'Fix Path';
            app.label_freespace = uilabel(app.vHTPImportUtilityUIFigure);
            app.label_freespace.Text = 'Free: X gb';
            app.SelectSystemandScanFilesButton = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.SelectSystemandScanFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSystemandScanFilesButtonPushed, true);
            app.SelectSystemandScanFilesButton.Text = 'Select System and Scan Files';
            app.ReadytoImportLampLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.ReadytoImportLampLabel.Text = 'Ready to Import:';
            app.ReadytoImportLamp = uilamp(app.vHTPImportUtilityUIFigure);
            app.OpenInputFolderButton = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.OpenInputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenInputFolderButtonPushed, true);
            app.OpenInputFolderButton.Text = 'Open Input Folder';
            app.AdvancetoQualityAssuranceModuleButton = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.AdvancetoQualityAssuranceModuleButton.Text = 'Advance to Quality Assurance Module';
            app.AdvancetoPreprocessingModuleButton = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.AdvancetoPreprocessingModuleButton.Text = 'Advance to Preprocessing Module';
            app.UITable = uitable(app.vHTPImportUtilityUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.FileImportTableLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.FileImportTableLabel.Text = 'File Import Table:';
            app.addBioSigExt = uibutton(app.vHTPImportUtilityUIFigure, 'push');
            app.addBioSigExt.ButtonPushedFcn = createCallbackFcn(app, @addBioSigExtButtonPushed, true);
            app.addBioSigExt.Text = 'Fix Toolkit';
            app.BIOSIGLampLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.BIOSIGLampLabel.Text = 'BIOSIG:';
            app.BIOSIGLamp = uilamp(app.vHTPImportUtilityUIFigure);
            app.KeyWordFiilterEditFieldLabel = uilabel(app.vHTPImportUtilityUIFigure);
            app.KeyWordFiilterEditFieldLabel.Text = 'Key Word Fiilter:';
            app.KeyWordFiilterEditField = uieditfield(app.vHTPImportUtilityUIFigure, 'text');
            app.KeyWordFiilterEditField.ValueChangedFcn = createCallbackFcn(app, @KeyWordFiilterEditFieldValueChanged, true);
            app.NegateFilterCheckBox = uicheckbox(app.vHTPImportUtilityUIFigure);
            app.NegateFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NegateFilterCheckBoxValueChanged, true);
            app.NegateFilterCheckBox.Text = 'Negate Filter';
            % Show the figure after all components are created
            app.vHTPImportUtilityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vhtpImportGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.vHTPImportUtilityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vHTPImportUtilityUIFigure)
        end
    end
end