classdef vhtpLaunchGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Hyperlink2      matlab.ui.control.Hyperlink
        Hyperlink       matlab.ui.control.Hyperlink
        importGui       matlab.ui.control.Image
        preprocessGui   matlab.ui.control.Image
        analyzeGui      matlab.ui.control.Image
        qualityControl  matlab.ui.control.Image
        Image           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            checks = htpDoctor;
            %htpDoctor('fix_spectralevents')
            fixfield = fieldnames(checks);
            for i = 1 : numel(fixfield)
                status = checks.(fixfield{i});
                if ~status
                    evalc(sprintf('htpDoctor(''fix_%s'')',fixfield{i} ));
                end
            end

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            
        end

        % Image clicked function: importGui
        function importGuiClicked(app, event)
            vhtpImportGui;
        end

        % Image clicked function: qualityControl
        function qualityControlImageClicked(app, event)
            vhtpQualityControlGui;
        end

        % Image clicked function: preprocessGui
        function preprocessGuiImageClicked(app, event)
            vhtpPreprocessGui;
        end

        % Image clicked function: analyzeGui
        function analyzeGuiImageClicked(app, event)
            vhtpAnalysisGui;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'man', 'figures', 'logo.png');
            app.qualityControl = uiimage(app.UIFigure);
            app.qualityControl.ImageClickedFcn = createCallbackFcn(app, @qualityControlImageClicked, true);
            app.qualityControl.ImageSource = 'QC@2x.png';
            app.analyzeGui = uiimage(app.UIFigure);
            app.analyzeGui.ImageClickedFcn = createCallbackFcn(app, @analyzeGuiImageClicked, true);
            app.analyzeGui.ImageSource = 'Analyze@2x.png';
            app.preprocessGui = uiimage(app.UIFigure);
            app.preprocessGui.ImageClickedFcn = createCallbackFcn(app, @preprocessGuiImageClicked, true);
            app.preprocessGui.ImageSource = 'Preprocess@2x.png';
            app.importGui = uiimage(app.UIFigure);
            app.importGui.ImageClickedFcn = createCallbackFcn(app, @importGuiClicked, true);
            app.importGui.ImageSource = 'Import@2x.png';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.URL = 'http://github.com/cincibrainlab';
            app.Hyperlink.Text = 'Github: CinciBrainLab';
            app.Hyperlink2 = uihyperlink(app.UIFigure);
            app.Hyperlink2.URL = 'http://lonelyneuron.substack.com';
            app.Hyperlink2.Text = 'Data Science Blog';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vhtpLaunchGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end