classdef vhtpPreprocessGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VHTPUIFigure                   matlab.ui.Figure
        ConfigureMenu                  matlab.ui.container.Menu
        EditEEGsystemcatalogXMLMenu    matlab.ui.container.Menu
        label_total_size               matlab.ui.control.Label
        ExportLastSteptoOutputFolderButton  matlab.ui.control.Button
        OpeninAnalysisButton           matlab.ui.control.Button
        QIPanel                        matlab.ui.container.Panel
        ChanDropDown                   matlab.ui.control.DropDown
        ChanDropDownLabel              matlab.ui.control.Label
        GoButton                       matlab.ui.control.Button
        ActionDropDown                 matlab.ui.control.DropDown
        ActionDropDownLabel            matlab.ui.control.Label
        selectSet2                     matlab.ui.control.Button
        selectSet1                     matlab.ui.control.Button
        SET2EditField                  matlab.ui.control.EditField
        SET2EditFieldLabel             matlab.ui.control.Label
        SET1EditField                  matlab.ui.control.EditField
        SET1EditFieldLabel             matlab.ui.control.Label
        ClearParadigmFilesButton       matlab.ui.control.Button
        PlotSourceButton               matlab.ui.control.Button
        loadLastFolders                matlab.ui.control.Hyperlink
        saveCurrentFolders             matlab.ui.control.Hyperlink
        OpeninEEGLABButton             matlab.ui.control.Button
        PlotDataButton                 matlab.ui.control.Button
        IgnoresubfoldersCheckBox       matlab.ui.control.CheckBox
        ExecuteButton                  matlab.ui.control.Button
        PreprocessingWorkflowLabel     matlab.ui.control.Label
        ConfigureDatasetsLabel         matlab.ui.control.Label
        ExploreProgressLabel           matlab.ui.control.Label
        viewOutputFolder               matlab.ui.control.Button
        viewInputFolder                matlab.ui.control.Button
        ExecuteWorkflowLabel           matlab.ui.control.Label
        WorkflowStyleLabel             matlab.ui.control.Label
        StepDetailsTextArea            matlab.ui.control.TextArea
        StepDetailsLabel               matlab.ui.control.Label
        openFunction                   matlab.ui.control.Hyperlink
        editParameters                 matlab.ui.control.Hyperlink
        openParadigmFolder             matlab.ui.control.Hyperlink
        reloadParadigms                matlab.ui.control.Hyperlink
        label_freespace                matlab.ui.control.Label
        label_filelist                 matlab.ui.control.Label
        SingleFileModeSwitch           matlab.ui.control.Switch
        SingleFileModeSwitchLabel      matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        EEGLABLamp                     matlab.ui.control.Lamp
        EEGLABLampLabel                matlab.ui.control.Label
        FixEegLabPath                  matlab.ui.control.Button
        RequirementsLabel              matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        VHTPPreprocessingUtilityLabel  matlab.ui.control.Label
        ArchiveEntireDatasettoZipButton  matlab.ui.control.Button
        StartNewCheckBox               matlab.ui.control.CheckBox
        OpenExportFolder               matlab.ui.control.Button
        outputDirectory                matlab.ui.control.EditField
        OutputDirectoryLabel           matlab.ui.control.Label
        baseDirectory                  matlab.ui.control.EditField
        SelectdirectoryofSETFilestoPreprocessLabel  matlab.ui.control.Label
        OpenImportFolder               matlab.ui.control.Button
        DryRunCheckBox                 matlab.ui.control.CheckBox
        SelectoneButtonGroup           matlab.ui.container.ButtonGroup
        ContinuationStepButton         matlab.ui.control.ToggleButton
        SingleStepButton               matlab.ui.control.ToggleButton
        AllStepsButton                 matlab.ui.control.ToggleButton
        ContinueFromDropDown           matlab.ui.control.DropDown
        ContinueFromDropDownLabel      matlab.ui.control.Label
        PreprocessingStepsListBox      matlab.ui.control.ListBox
        IndividualStepsLabel           matlab.ui.control.Label
        InstructionFileListBox         matlab.ui.control.ListBox
        ParadigmsLabel                 matlab.ui.control.Label
    end


    properties (Access = private)
        PARAMS=struct(); % Description
        DRYRUN=false;
        PROCESS='';
        SOURCEFILES=0;
        EegLabIsAvailable; % Description
        note; % Description
        singleFileModeOn; % Description
        lastImportFolder; % Description
        lastOutputFolder; % Description
        vhtpfolder; % Description
        currentParadigmFile; % Description
        currentParametersFile; % Description
        currentParametersSteps; % Description
        currentParametersCode; % Description
        currentPreprocessingStep; % Description
        currentProcessedFiles;
        WorkflowButtonLabel; % Description
        ignoreSubfolders; % Description
        EEG1 % Description
        EEG2
        isLoadFromAnalysisApp % Description
        analysisOutputDirectory;
        analysisBaseDirectory;
        lastSuccessfulStep;
    end

    methods (Access = private)

        function allowProcessStyle(app)
            app.SelectoneButtonGroup.Visible = 'on';
            app.AllStepsButton.Visible = 'on';
            app.SingleStepButton.Visible = 'on';
            app.ContinuationStepButton.Visible = 'on';
            app.AllStepsButton.Enable=1;
            app.SingleStepButton.Enable=1;
            app.ContinuationStepButton.Enable=1;
            app.AllStepsButton.Value=1;
            app.SingleStepButton.Value=0;
            app.ContinuationStepButton.Value=0;
        end

        function denyProcessStyle(app)
            app.SelectoneButtonGroup.Visible = 'off';
            app.AllStepsButton.Visible = 'off';
            app.SingleStepButton.Visible = 'off';
            app.ContinuationStepButton.Visible = 'off';
            app.AllStepsButton.Enable=0;
            app.SingleStepButton.Enable=0;
            app.ContinuationStepButton.Enable=0;
            app.AllStepsButton.Value=0;
            app.SingleStepButton.Value=0;
            app.ContinuationStepButton.Value=0;
        end

        function allowParadigms(app)
            app.InstructionFileListBox.Enable = "on";
        end

        function setParadigmScript( app, listItem )

            app.currentParadigmFile = fullfile(app.vhtpfolder, 'paradigms/', ['paradigm_' char(listItem) '.m']);

        end

        function setParametersScript( app, listItem )

            app.currentParametersFile = fullfile(app.vhtpfolder, 'parameters/', ['parameters_' char(listItem) '.m']);

        end

        function listParadigms(app)
            filelist = util_htpDirListing(fullfile(app.vhtpfolder, 'parameters/'), 'ext', '.m', 'keyword', 'parameters');
            paradigms = string(regexp(filelist{:,2},'(?<=parameters_)\w*','match'));

            app.InstructionFileListBox.Items = paradigms;
            app.InstructionFileListBox.ItemsData = paradigms;
            app.InstructionFileListBox.Visible = 'on';
            app.InstructionFileListBox.Enable = 'on';

        end

        function denyParadigms(app)
            app.InstructionFileListBox.Enable = 'off';

        end

        function listIndividualSteps(app)

            app.PreprocessingStepsListBox.Items = app.currentParametersSteps;
            app.PreprocessingStepsListBox.ItemsData = app.currentParametersSteps;

        end

        function allowIndividualSteps(app)

            app.PreprocessingStepsListBox.Enable = 'on';
        end

        function denyIndividualSteps(app)
            app.PreprocessingStepsListBox.Enable = 'off';
        end

        function disableSpaceStats(app)
            app.label_freespace.Text='';
            app.label_total_size.Text='';
        end

        function allowContinuationSteps(app)
            steps=fieldnames(app.PARAMS);
            rerunsteps = string(steps);

            app.ContinueFromDropDown.Items = rerunsteps;
            app.ContinueFromDropDown.ItemsData = rerunsteps;
            app.ContinueFromDropDown.Visible = 'on';
            app.ContinueFromDropDown.Enable = 'on';
        end

        function denyContinuationSteps(app)
            app.ContinueFromDropDown.Enable = 'off';
        end

        function denyAll(app)
            denyParadigms(app);
            denyProcessStyle(app);
            denyIndividualSteps(app);
            denyContinuationSteps(app);
            disableSpaceStats(app);
        end

        function initializePreviousFileDirectories(app,prev_file_dir)
            stepnames = fieldnames(app.PARAMS);
            for i=1:length(stepnames)
                if ~exist(fullfile(prev_file_dir,char(app.PARAMS.(stepnames{i}).function)),'dir')
                    mkdir(fullfile(prev_file_dir,char(app.PARAMS.(stepnames{i}).function)));
                end
            end
        end

        % Revision EP, new utility functions

        function actionHandler(app, action)

            switch action
                case 'startup'
                    % setting up eeglab path
                    app.EEGLABLamp.Color = [1,0,0];
                    app.FixEegLabPath.Enable = 'on';
                    if app.EegLabIsAvailable
                        app.actionHandler('eegLabIsAvailable');
                    end

                    % setting up single / folder mode
                    app.singleFileModeOn = false;
                    app.SingleFileModeSwitch.Value = 'Off';
                    app.actionHandler('singleFileModeOn');

                    % setup default folders
                    app.lastImportFolder = pwd;
                    app.lastOutputFolder = pwd;

                    % set to ignore subfolder by defalt
                    app.IgnoresubfoldersCheckBox.Value = true;
                    app.ignoreSubfolders = app.IgnoresubfoldersCheckBox.Value;

                    % setup freespace
                    app.label_freespace.Text = '';
                    app.label_total_size.Text = '';
                    app.label_filelist.Text = '';

                    app.listParadigms();
                    app.denyParadigms();

                    % define Workflow style buttons
                    app.WorkflowButtonLabel = struct();
                    app.WorkflowButtonLabel.All       = 'All Steps (in Order)';
                    app.WorkflowButtonLabel.Single    = 'Selected Step';
                    app.WorkflowButtonLabel.Continue  = 'Continue from Step';

                    app.AllStepsButton.Text = app.WorkflowButtonLabel.All;
                    app.SingleStepButton.Text = app.WorkflowButtonLabel.Single;
                    app.ContinuationStepButton.Text = app.WorkflowButtonLabel.Continue;

                    % turn off late functions until directories are
                    % selected
                    app.toggleLateFunctions( 'off' );

                    % source files
                    app.SOURCEFILES = true;

                    % QI init
                    app.actionHandler('defineQiFunctions');

                    % transfer from analysis GUI
                    if app.isLoadFromAnalysisApp
                        app.loadFromAnalysisApp(app.analysisBaseDirectory, app.analysisOutputDirectory);
                    end

                case 'defineQiFunctions'
                    qiFunctions = ...
                        {'Plot Set 1', 'Plot Set 2', 'Plot Erp 1', 'Plot Erp 2', 'MNE 1', 'MNE 2', 'Pair Erp','Superimpose Plot', ...
                        'Compare Sets with QI Tool', 'Copy code Set 1', 'Copy code Set 2'};
                    qiFunctionCode = ...
                        {'plot1', 'plot2', 'erp1', 'erp2', 'source1', 'source2', 'pairerp', 'plotpair', 'qitool', 'clipboard1', 'clipboard2'};

                    app.ActionDropDown.Items = qiFunctions;
                    app.ActionDropDown.ItemsData = qiFunctionCode;
                    app.ActionDropDown.Value = 'plotpair';


                case 'eegLabIsAvailable'
                    % setting up eeglab path
                    app.EEGLABLamp.Color = [0,1,0];
                    app.FixEegLabPath.Enable = 'off';

                case 'singleFileModeOn'
                    app.actionHandler('clearInputDir');

                    if app.singleFileModeOn
                        select_label = '1. Select single SET File for Preprocessing:';
                        button_label = 'Select File';
                        app.note('Single file mode On.')
                    else
                        select_label = '1. Select directory of SET Files to Preprocess:';
                        button_label = 'Select Folder';
                        app.note('Single file mode Off.')
                    end
                    app.SelectdirectoryofSETFilestoPreprocessLabel.Text = select_label;
                    app.OpenImportFolder.Text = button_label;

                case 'clearInputDir'
                    if ~isempty(app.baseDirectory.Value)
                        app.note(sprintf('Discarding previous input: %s',  app.baseDirectory.Value))
                        app.baseDirectory.Value = '';
                    end

                case 'freespace'
                    app.label_freespace.Text = sprintf('%.1f GB Free', ...
                        app.getUsableDiskSpace());

                case 'totalspace'
                    app.label_total_size.Text = sprintf('%.2f GB Used',app.getTotalUsedSpace());

                case 'updateFileListCount'
                    [fileCount, subfolderCount] = app.countFileList;
                    app.label_filelist.Text =  sprintf('%d Files. %d Dirs.', ...
                        fileCount,subfolderCount);

                    if fileCount == 0
                        app.actionHandler('notReadyToImport');
                    else
                        app.actionHandler('readyToImport');
                    end

                case 'loadCurrentParametersSteps'
                    app.currentParametersSteps = fieldnames(app.PARAMS);
                    if isempty(app.currentPreprocessingStep)
                        app.currentPreprocessingStep = app.currentParametersSteps{1};
                    end

                case 'listIndividualSteps'
                    app.listIndividualSteps;

                case 'refreshParameters'

                    if ~isempty( app.currentParametersCode )
                        % load parameters function and get preprocessing steps
                        try
                            func = str2func(strcat("parameters_",app.currentParametersCode));
                            app.PARAMS=func();
                            app.actionHandler('loadCurrentParametersSteps');
                            app.actionHandler('listIndividualSteps');
                            app.actionHandler('updateStepDetails');
                            app.allowIndividualSteps;
                        catch
                            error('Error loading %s (parameters) file.', app.currentParametersFile);
                        end
                        if strcmp(app.SelectoneButtonGroup.Visible,'off')
                            allowProcessStyle(app);
                        end
                        if isempty(app.PROCESS)
                            app.PROCESS='All';
                        end
                        denyContinuationSteps(app);
                        %denyIndividualSteps(app);
                    else
                        denyAll(app);
                    end

                case 'updateStepDetails'
                    try
                        if isempty(app.currentPreprocessingStep)
                            value = app.PreprocessingStepsListBox.Value;
                        else
                            value = app.currentPreprocessingStep;
                        end
                        app.StepDetailsTextArea.Value = evalc('disp(app.PARAMS.(value))');
                    catch
                        app.StepDetailsTextArea.Value = 'Select a step.';
                    end

                case 'updateCurrentStep'
                    if strcmp(app.PROCESS,'IndividualStep')
                        app.currentPreprocessingStep = app.PreprocessingStepsListBox.Value;
                    
                    elseif strcmp(app.PROCESS,'Continuation') || strcmp(app.PROCESS,'All')
                        app.currentPreprocessingStep = app.currentParametersSteps{end};
                    else
                        app.currentPreprocessingStep = app.currentParametersSteps{end};

                    end
                
                otherwise
                    app.note('Unexpected unhandled action in actionHandler.')
            end

        end


        function toggleLateFunctions( app, toggle )
            app.openParadigmFolder.Enable = toggle;
            app.viewInputFolder.Enable = toggle;
            app.viewOutputFolder.Enable = toggle;
            app.reloadParadigms.Enable = toggle;
            app.openFunction.Enable = toggle;
            app.editParameters.Enable = toggle;
            app.PreprocessingStepsListBox.Enable = toggle;
            app.ContinueFromDropDown.Enable = toggle;
            app.ExecuteButton.Enable = toggle;
            app.ArchiveEntireDatasettoZipButton.Enable = toggle;
            app.ExportLastSteptoOutputFolderButton.Enable = toggle;
            app.DryRunCheckBox.Enable = toggle;
            app.AllStepsButton.Enable = toggle;
            app.ContinuationStepButton.Enable = toggle;
            app.SingleStepButton.Enable = toggle;
            app.AllStepsButton.Enable = toggle;
            app.StepDetailsTextArea.Enable = toggle;
        end

        function checkIfEegLabAvailable( app )
            try
                eeglab nogui;
                app.EegLabIsAvailable = true;
                app.note('EEGLAB Found on MATLAB Path.');
            catch
                app.EegLabIsAvailable = false;
                app.note('EEGLAB Missing from MATLAB Path.');
            end
        end

        function fixEegLabPath( app )
            app.EegLabIsAvailable = false;
            try_eeglab_path = missing;
            while app.EegLabIsAvailable == false
                try
                    if ~ismissing(try_eeglab_path), addpath(try_eeglab_path); end
                    app.checkIfEegLabAvailable();
                    assert( app.EegLabIsAvailable );
                    app.actionHandler('eegLabIsAvailable')
                catch
                    if ~ismissing(try_eeglab_path), rmpath(try_eeglab_path); end
                    try_eeglab_path = uigetdir([],'Choose EEGLAB directory or hit cancel.');
                end
            end
        end

        function addVhtpToPath( app )
            [app.vhtpfolder, ~, ~] = fileparts(which(mfilename));
            addpath(genpath(fullfile( app.vhtpfolder )));
            app.note('Adding vHtp Toolkit to Path.')
        end

        function  [fileCount, subFolderCount]  = countFileList(app)

            results = util_htpDirListing( app.baseDirectory.Value, 'ext', '.set', 'subdirOn', ~app.ignoreSubfolders );

            %hideInputFolder = @(x) strrep(x, app.inputPath.Value, '');
            if isempty(results) == false
                fileCount = height(results);
                subFolderCount = height(unique(results.filepath));
                % app.UITable.Data = [results.filename  cellfun(hideInputFolder, results.filepath, 'UniformOutput', false)];
                % app.UITable.ColumnName = {'Filename','Subfolder'};
            else
                fileCount = 0;
                subFolderCount = 0;
                % app.UITable.Data = [];
                % app.UITable.ColumnName = {'Filename','Subfolder'};
            end
        end

        function space_in_gb = getUsableDiskSpace(app)
            FileObj      = java.io.File(app.outputDirectory.Value);
            space_in_gb = FileObj.getUsableSpace / 1e9;
        end

        function total_in_gb = getTotalUsedSpace(app)
            exportfiles=dir(fullfile(app.outputDirectory.Value,'**/*.*'));
            exportfiles = exportfiles(~[exportfiles.isdir]);
            total_in_gb = sum([exportfiles(:).bytes])/ 1e9;
        end

        function [fullFileName] = userPickSetFile( app )
            try
                [file,folder] = uigetfile(fullfile(app.outputDirectory.Value, '*.set'),'Select a File for Input');
                figure(app.VHTPUIFigure);
                if file==0
                    figure(app.VHTPUIFigure);
                    fullFileName = [];
                    return;
                end
                fullFileName = fullfile(folder,file);
            catch
                warning('User cancelled file selection.')
            end
        end

        function openEegPlot( app, filename )
            pop_eegplot( pop_loadset( filename ), 1, 0, 1);
        end


        function saveLastDirectory(app)
            vhtppreprocess = struct();
            vhtppreprocess.baseDirectory.Value = app.baseDirectory.Value;
            vhtppreprocess.outputDirectory.Value = app.outputDirectory.Value;
            if exist('vhtp_gui_savedata.mat','file')
                save('vhtp_gui_savedata.mat', 'vhtppreprocess', '-append');
            else
                save('vhtp_gui_savedata.mat','vhtppreprocess')
            end
        end
        function loadLastDirectory(app)
            try
                load('vhtp_gui_savedata.mat', 'vhtppreprocess');
                app.baseDirectory.Value     = vhtppreprocess.baseDirectory.Value;
                app.outputDirectory.Value   = vhtppreprocess.outputDirectory.Value;
    
                event.EventName = 'passthrough';
                event.UserData.folder = app.baseDirectory.Value;
                app.OpenImportFolderPushed( event );
    
                event.EventName = 'passthrough';
                event.UserData.folder = app.outputDirectory.Value;
                app.OutputFolderButtonPushed( event );
            catch
                warning('No previous snapshot found.')
            end
        end

        function loadFromAnalysisApp(app, baseDirectory, outputDirectory)
            app.baseDirectory.Value     = baseDirectory;
            app.outputDirectory.Value   = outputDirectory;

            event.EventName = 'passthrough';
            event.UserData.folder = app.baseDirectory.Value;
            app.OpenImportFolderPushed( event );

            event.EventName = 'passthrough';
            event.UserData.folder = app.outputDirectory.Value;
            app.OutputFolderButtonPushed( event );
        end

        function openEegLab(app, filename)
            try
                assignin('base', 'EEG', pop_loadset( filename ));
                eeglab redraw;
            catch
                warning('Unable to load file in EEGLAB.');
            end
        end

        function plotSourceData(app, filename)
            pop_eegplot( eeg_htpCalcSource(pop_loadset( filename )), 1, 0, 1);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, baseDirectory, outputDirectory)

            if nargin > 2
                app.isLoadFromAnalysisApp = true;
                app.analysisBaseDirectory = baseDirectory;
                app.analysisOutputDirectory = outputDirectory;
            end

            % log function
            app.note = @(msg) fprintf('%s: %s\n', 'htpPreprocess', msg );
            app.note('Initializing Preprocessing Utility.');

            % setup up toolbox paths
            app.checkIfEegLabAvailable;

            % force vHtp path
            app.addVhtpToPath;

            % use action handler for startup tasks
            app.actionHandler('startup');

        end

        % Button pushed function: OpenImportFolder
        function OpenImportFolderPushed(app, event)

            if event.EventName == categorical("ButtonPushed"), isEntryButtonPush = true;
            else, isEntryButtonPush=false;
            end

            startFolder =  app.lastImportFolder;

            if app.singleFileModeOn
                if isEntryButtonPush
                    [file,folder] = uigetfile('*.set','Select a File for Input');
                else
                    file = event.UserData.file;
                    folder = event.UserData.folder;
                end
                if file==0
                    figure(app.VHTPUIFigure);
                    return;
                end
                fullFileName = fullfile(folder,file);
                app.baseDirectory.Value = fullFileName;
            else
                if isEntryButtonPush
                    [folder] = uigetdir(startFolder, 'Select a Folder for Input');
                else
                    folder = event.UserData.folder;
                end
                if folder==0
                    figure(app.VHTPUIFigure);
                    return;
                else
                    app.lastImportFolder = folder;
                    app.lastOutputFolder = folder;
                    app.baseDirectory.Value = folder;
                    app.actionHandler( 'updateFileListCount' );
                end

            end
            figure(app.VHTPUIFigure);
            if ~isempty(app.outputDirectory.Value)
                allowParadigms(app);
            end
        end

        % Callback function
        function SingleFileButtonPushed(app, event)
            app.actionHandler('singleFileModeOn');

            value = app.singleFileModeOn;
            if value
                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);

                end
                app.OpenImportFolder.Text = 'Select File';
            else
                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);
                end
                app.OpenImportFolder.Text = 'Select Folder';
            end
        end

        % Button pushed function: OpenExportFolder
        function OutputFolderButtonPushed(app, event)
            startFolder =  app.lastOutputFolder;

            if event.EventName == categorical("ButtonPushed"), isEntryButtonPush = true;
            else, isEntryButtonPush=false;
            end

            if isEntryButtonPush
                [folder] = uigetdir(startFolder, 'Select a Folder for Output');
            else
                folder = event.UserData.folder;
            end
            if folder==0
                figure(app.VHTPUIFigure);
                return;
            else
                app.lastOutputFolder;
                app.outputDirectory.Value = folder;
                figure(app.VHTPUIFigure);
                app.actionHandler( 'freespace' );
                app.actionHandler( 'totalspace' );
            end

            if ~isempty(app.baseDirectory.Value)
                allowParadigms(app);
            end

        end

        % Value changed function: InstructionFileListBox
        function ParadigmListBoxChanged(app, event)
            value = app.InstructionFileListBox.Value;
            app.note(sprintf('Switched to %s Paradigm', value));
            app.setParametersScript( value );
            app.currentParametersCode = value;
            app.actionHandler('refreshParameters');
            app.toggleLateFunctions('on');

        end

        % Value changed function: baseDirectory
        function baseDirectoryChanged(app, event)
            value = app.baseDirectory.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Value changed function: outputDirectory
        function outputDirectoryChanged(app, event)
            value = app.outputDirectory.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
                app.toggleLateFunctions('off');
            end
        end

        % Selection changed function: SelectoneButtonGroup
        function SelectoneButtonGroupChanged(app, event)
            selectedButton = app.SelectoneButtonGroup.SelectedObject;

            switch selectedButton.Text
                case app.WorkflowButtonLabel.Single
                    allowIndividualSteps(app);
                    denyContinuationSteps(app);
                    app.PROCESS = 'IndividualStep';

                case app.WorkflowButtonLabel.Continue
                    allowContinuationSteps(app);
                    denyIndividualSteps(app);
                    app.PROCESS = 'Continuation';
                case app.WorkflowButtonLabel.All
                    denyIndividualSteps(app);
                    denyContinuationSteps(app);
                    app.PROCESS = 'All';
                otherwise
            end

        end

        % Value changed function: DryRunCheckBox
        function DryRunCheckBoxChanged(app, event)
            value = app.DryRunCheckBox.Value;
            app.DRYRUN = value;
        end

        % Value changed function: StartNewCheckBox
        function StartNewCheckBoxChanged(app, event)
            value = app.StartNewCheckBox.Value;
            app.SOURCEFILES = value;
        end

        % Button pushed function: ArchiveEntireDatasettoZipButton
        function ArchiveEntireDatasettoZipButtonPushed(app, event)
            outputvalue=app.outputDirectory.Value;
            if ~isempty(outputvalue) && ~isempty(app.baseDirectory.Value)
                if ~app.singleFileModeOn
                    inputvalue = app.baseDirectory.Value;
                    inputvalue = fullfile(inputvalue,'preprocess');
                else
                    [inputvalue,~,~] = fileparts(app.baseDirectory.Value);
                    inputvalue = fullfile(inputvalue,'preprocess');
                end
                sprintf("Exporting Data.\n")
                if isfolder(outputvalue) && isfolder(inputvalue)
                    zip(fullfile(outputvalue,strjoin([app.InstructionFileListBox.Value,datestr(now, 'yyyy_mm_dd_HH_MM_SS')],'_')),inputvalue);
                    uialert(app.VHTPUIFigure, {'Output Files successfully exported.'},'Output Export','Icon','success');
                    app.actionHandler('freespace');
                    app.actionHandler('totalspace');
                else
                    uialert(app.VHTPUIFigure, {'Export not completed due to non-existent input or output directory.','','Ensure that you have selected a valid base directory/file and/or output directory.'},'Output Export','Icon','error');
                end
            else
                uialert(app.VHTPUIFigure, {'Export not completed due to missing input or output values.','','Ensure that you have selected a valid base directory/file and/or output directory.'},'Output Export','Icon','error');
            end
        end

        % Button pushed function: FixEegLabPath
        function FixEegLabPathButtonPushed(app, event)
            app.fixEegLabPath;
        end

        % Menu selected function: EditEEGsystemcatalogXMLMenu
        function EditEEGsystemcatalogXMLMenuSelected(app, event)
            winopen('cfg_htpEegSystems.xml');
        end

        % Value changed function: SingleFileModeSwitch
        function SingleFileModeSwitchValueChanged(app, event)
            switch app.SingleFileModeSwitch.Value
                case 'On'
                    app.singleFileModeOn = true;
                case 'Off'
                    app.singleFileModeOn = false;
            end

            app.actionHandler('singleFileModeOn');

            if app.singleFileModeOn

                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);
                end
                app.OpenImportFolder.Text = 'Select Files';
            else
                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);
                end
                app.OpenImportFolder.Text = 'Select Folder';
            end

        end

        % Callback function: reloadParadigms
        function reloadParadigmsClicked(app, event)
            app.actionHandler('refreshParameters');
            app.listParadigms();
        end

        % Callback function: openParadigmFolder
        function openParadigmFolderHyperlinkClicked(app, event)
            if ispc
                winopen(fullfile(app.vhtpfolder,'parameters/'));
            elseif isunix
                system(['xdg-open ' fullfile(app.vhtpfolder,'parameters/')]);
            end
        end

        % Callback function: editParameters
        function editParametersHyperlinkClicked(app, event)
            try
                open(app.currentParametersFile);
                app.note(sprintf('Opened %s for editing.', app.currentParametersFile));
            catch
                app.note('Warning: No parameters file selected.')
            end
        end

        % Callback function: openFunction
        function openFunctionHyperlinkClicked(app, event)
            value = app.PreprocessingStepsListBox.Value;
            open(func2str(app.PARAMS.(value).function));
        end

        % Value changed function: PreprocessingStepsListBox
        function PreprocessingStepsListBoxValueChanged(app, event)
            app.currentPreprocessingStep = app.PreprocessingStepsListBox.Value;
            app.actionHandler('refreshParameters');
        end

        % Button pushed function: viewInputFolder
        function viewInputFolderButtonPushed(app, event)
            winopen(app.baseDirectory.Value);
        end

        % Button pushed function: viewOutputFolder
        function viewOutputFolderButtonPushed(app, event)
            winopen(app.outputDirectory.Value);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)

            % account for subfolders EP 7/22
            useSubfolders = ~app.ignoreSubfolders;

            if ~isempty(fieldnames(app.PARAMS))
                func=str2func("util_htpParadigmRun");
                if app.singleFileModeOn
                    [folder,~,~] = fileparts(app.baseDirectory.Value);
                    prev_file_dir = char(fullfile(folder,'preprocess',app.InstructionFileListBox.Value));
                    completed_file_dir = char(fullfile(folder,'completed'));
                else
                    prev_file_dir = char(fullfile(app.baseDirectory.Value,'preprocess',app.InstructionFileListBox.Value));
                    completed_file_dir = char(fullfile(app.baseDirectory.Value,'completed'));
                end

                if ~exist(prev_file_dir,'dir')
                    mkdir(prev_file_dir);
                end
                
                if strcmp(app.PROCESS,'Continuation')
                    if ~exist(completed_file_dir,'dir')
                        mkdir(completed_file_dir);
                    end
                    steps = fieldnames(app.PARAMS);
                    stepsMatch = regexp(app.ContinueFromDropDown.Value,steps,'match','once');
                    startStep = find(~ismissing(stepsMatch));
                    continuationSteps = struct();
                    for i=startStep:length(steps)
                        continuationSteps.(steps{i}) = app.PARAMS.(steps{i});
                    end
                    func(app.baseDirectory.Value,continuationSteps,app.PROCESS,'ext','.set','subdirOn', useSubfolders, 'outputdir',prev_file_dir,'dryrun',app.DRYRUN,'continuationstep', char(app.ContinueFromDropDown.Value),'stepnumbers', [startStep:length(steps)]);
                    app.actionHandler('updateCurrentStep');
                    uialert(app.VHTPUIFigure, {'All steps for Continuation Mode complete for all files.'},'Continuation Mode','Icon','success');
                elseif strcmp(app.PROCESS,'IndividualStep')
                    app.actionHandler('updateCurrentStep');
                    steps = fieldnames(app.PARAMS);
                    stepsMatch = regexp(app.currentPreprocessingStep,steps,'match','once');
                    stepNumber = find(~ismissing(stepsMatch));
                    prev_file_dir = char(fullfile(prev_file_dir,char(app.PARAMS.(app.currentPreprocessingStep).function)));
                    func(app.baseDirectory.Value,app.PARAMS,app.PROCESS,'ext','.set','subdirOn', useSubfolders,'outputdir',prev_file_dir,'dryrun',app.DRYRUN,'individualstep',char(app.PreprocessingStepsListBox.Value),'stepnumbers',stepNumber);
                else
                    if ~app.SOURCEFILES
                        uialert(app.VHTPUIFigure, {'Please Mark that these are Original Files','','Original File Checkbox Missing Check.'},'Error','Icon','error');
                        return;
                    end
                    if ~exist(completed_file_dir,'dir')
                        mkdir(completed_file_dir);
                    end
                    func(app.baseDirectory.Value,app.PARAMS,app.PROCESS,'ext','.set','subdirOn', useSubfolders, 'outputdir',prev_file_dir,'dryrun',app.DRYRUN,'stepnumbers', [1:length(fieldnames(app.PARAMS))]);
                    app.actionHandler('updateCurrentStep');
                    uialert(app.VHTPUIFigure, {'All steps for All Mode complete for all files.'},'All Mode','Icon','success');
                end
            end
        end

        % Value changed function: IgnoresubfoldersCheckBox
        function IgnoresubfoldersCheckBoxValueChanged(app, event)
            value = app.IgnoresubfoldersCheckBox.Value;
            app.ignoreSubfolders = value;
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            % open file browser looking for SET files
            app.openEegPlot(app.userPickSetFile);
        end

        % Callback function: saveCurrentFolders
        function saveCurrentFoldersClicked(app, event)
            app.saveLastDirectory;
        end

        % Callback function: loadLastFolders
        function loadLastFoldersClicked(app, event)
            app.loadLastDirectory;
        end

        % Button pushed function: OpeninEEGLABButton
        function OpeninEEGLABButtonPushed(app, event)
            app.openEegLab(app.userPickSetFile);
        end

        % Button pushed function: PlotSourceButton
        function PlotSourceButtonPushed(app, event)
            try
                app.plotSourceData(app.userPickSetFile);
            catch
                warning('Exit from without selecting directory.')
            end
        end

        % Button pushed function: ClearParadigmFilesButton
        function ClearParadigmFilesButtonPushed(app, event)
            % delete only paradigm directory

        end

        % Button pushed function: selectSet1
        function selectSet1Pushed(app, event)
            app.SET1EditField.Value = app.userPickSetFile;
            EEG = pop_loadset('filename',app.SET1EditField.Value, 'loadmode', 'info');
            app.ChanDropDown.Items = {EEG.chanlocs.labels};
            app.ChanDropDown.ItemsData = {EEG.chanlocs.labels};
            app.ChanDropDown.Value = 'E71';

        end

        % Button pushed function: selectSet2
        function selectSet2ButtonPushed(app, event)
            app.SET2EditField.Value = app.userPickSetFile;
            EEG = pop_loadset('filename', app.SET2EditField.Value, 'loadmode', 'info');

            app.ChanDropDown.Items = {EEG.chanlocs.labels};
            app.ChanDropDown.ItemsData = {EEG.chanlocs.labels};

            app.ChanDropDown.Value = {'E71'};
        end

        % Value changed function: ActionDropDown
        function ActionDropDownValueChanged(app, event)
            value = app.ActionDropDown.Value;

        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            qidropdownvalue = app.ActionDropDown.Value;
            try
                EEG1 = pop_loadset(app.SET1EditField.Value);
                loadsuccess_EEG1 = true;
            catch
                warning('Error opening EEG1.')
                loadsuccess_EEG1 = false;
            end

            try
                EEG2 = pop_loadset(app.SET2EditField.Value);
                loadsuccess_EEG2 = true;
            catch
                warning('Error opening EEG2.')
                loadsuccess_EEG2 = false;
            end


            switch qidropdownvalue
                case 'plotpair'
                    if loadsuccess_EEG1 && loadsuccess_EEG2
                        plottitle = sprintf('EEG2 (red) overlayed on EEG1 (black)');
                        eegplot(EEG1.data, ...
                            'data2',EEG2.data, ...
                            'srate', EEG1.srate, ...
                            'winlength', 3, 'dispchans', 10, 'spacing', round(mean(max((EEG1.data)))), ...
                            'title', 'sigPreProcessAnalyzer: Compare Time Series', 'plottitle', plottitle);
                    else
                        warning('Only single valid SET available.')
                    end

                case 'erp1'
                    plot_erp({EEG1}, app.ChanDropDown.Value);
                case 'erp2'
                    plot_erp({EEG2}, app.ChanDropDown.Value);
                case 'source1'
                    pop_eegplot( eeg_htpCalcSource(EEG1, 'outputdir', app.outputDirectory.Value), 1, 0, 1);

                case 'source2'
                    pop_eegplot( eeg_htpCalcSource(EEG2), 1, 0, 1);

                case 'pairerp'
                    plot_erp({EEG1, EEG2}, app.ChanDropDown.Value, 'labels', {'EEG1','EEG2'},'plotdiff', 0);
                case 'plot1'
                    if loadsuccess_EEG1
                        pop_eegplot( EEG1 );
                    else
                    end
                case 'plot2'
                    if loadsuccess_EEG2
                        pop_eegplot( EEG2 );
                    else
                    end
                case 'qitool'
                    if loadsuccess_EEG1 && loadsuccess_EEG2
                        eeg_htpEegAssessPipelineHAPPE(...
                            EEG1, ...
                            EEG2, ...
                            'outputdir', app.outputDirectory.Value, ...
                            'outputfile', 'sigPreProcessAnalyzerResults.csv');
                    else
                    end
                case 'clipboard1'
                    clipboard('copy',  sprintf('EEG1 = pop_loadset(''%s'');', app.SET1EditField.Value));

                case 'clipboard2'
                    clipboard('copy',  sprintf('EEG2 = pop_loadset(''%s'');', app.SET2EditField.Value));
            end

        end

        % Callback function
        function ExportBidsDatasetButtonPushed(app, event)
            outputvalue=app.outputDirectory.Value;
            if ~isempty(outputvalue) && ~isempty(app.baseDirectory.Value)
                if ~app.singleFileModeOn
                    inputvalue = app.baseDirectory.Value;
                    inputvalue = fullfile(inputvalue);
                else
                    [inputvalue,~,~] = fileparts(app.baseDirectory.Value);
                    inputvalue = fullfile(inputvalue);
                end
                sprintf("Exporting Data.\n")
                if isfolder(outputvalue) && isfolder(inputvalue)
                    func = str2func(['bids_' char(app.currentParametersCode)]);
                    presets = func();
                    util_htpExportBids(inputvalue,presets,'ext','.set','subdirOn',~app.ignoreSubfolders,'outputdir',outputvalue,'paradigm',char(app.currentParametersCode))
                    uialert(app.VHTPUIFigure, {'Output Files successfully exported.'},'Output Export','Icon','success');
                else
                    uialert(app.VHTPUIFigure, {'Export not completed due to non-existent input or output directory.','','Ensure that you have selected a valid base directory/file and/or output directory.'},'Output Export','Icon','error');
                end
            else
                uialert(app.VHTPUIFigure, {'Export not completed due to missing input or output values.','','Ensure that you have selected a valid base directory/file and/or output directory.'},'Output Export','Icon','error');
            end
        end

        % Button pushed function: OpeninAnalysisButton
        function OpeninAnalysisButtonPushed(app, event)
            vhtpAnalysisGui( app.outputDirectory.Value )
        end

        % Button pushed function: ExportLastSteptoOutputFolderButton
        function ExportLastSteptoOutputFolderButtonPushed(app, event)
            if ~isempty(app.outputDirectory.Value)
                fields = fieldnames(app.PARAMS);
                if strcmp(app.PROCESS,'IndividualStep')
                    stepIndex = find(strcmp(fields,app.currentPreprocessingStep));
                else
                    stepIndex = find(structfun(@(x) isfield(x,'saveoutput'),app.PARAMS),1,'last');
                end
                if ~isempty(stepIndex)
                    prevOutputFolder = func2str(app.PARAMS.(string(fields(stepIndex))).function);
                    if app.singleFileModeOn
                        [baseDir,file,~] = fileparts(app.baseDirectory.Value);
                    else
                        baseDir = app.baseDirectory.Value;
                    end
                    if exist(fullfile(baseDir,'preprocess',app.currentParametersCode,prevOutputFolder),'dir')
                        if app.singleFileModeOn
                            outputfiles=dir(fullfile(baseDir,'preprocess',app.currentParametersCode,prevOutputFolder));
                            filestocopy = string(char(outputfiles(cellfun(@(x) contains(x,file),{outputfiles.name})).name));
                        else
                            filestouse = util_htpDirListing(baseDir, 'ext', '.set', 'subdirOn', ~app.ignoreSubfolders);
                            if ~app.ignoreSubfolders
                                filestouse = filestouse(~contains(filestouse.filepath, fullfile(baseDir,'preprocess')) && ~contains(filestouse.filepath,fullfile(baseDir,'completed')),:);
                            end
                            filestocopy = "*.*";
                        end
                        if ~exist(fullfile(app.outputDirectory.Value),'dir')
                            mkdir(fullfile(app.outputDirectory.Value));
                        end
                        for i=1:length(filestocopy)
                            copyfile(fullfile(baseDir,'preprocess',app.currentParametersCode,prevOutputFolder,filestocopy(i)),fullfile(app.outputDirectory.Value));
                        end
                        app.actionHandler('freespace');
                        app.actionHandler('totalspace');
                        uialert(app.VHTPUIFigure, {sprintf('Export of %s step output files successfully completed.',string(fields(stepIndex)))},'Export Last Step','Icon','success');
                    else
                         uialert(app.VHTPUIFigure, {'Export not completed due to this step having no output to export.'},'Export Last Step','Icon','warning');
                        
                    end
                else
                    uialert(app.VHTPUIFigure, {'Export not completed due to this step not having output currently explicitly saved.'},'Export Last Step','Icon','warning');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VHTPUIFigure = uifigure('Visible', 'off');
            app.VHTPUIFigure.Name = 'VHTP';
            app.ConfigureMenu = uimenu(app.VHTPUIFigure);
            app.ConfigureMenu.Text = 'Configure';
            app.EditEEGsystemcatalogXMLMenu = uimenu(app.ConfigureMenu);
            app.EditEEGsystemcatalogXMLMenu.MenuSelectedFcn = createCallbackFcn(app, @EditEEGsystemcatalogXMLMenuSelected, true);
            app.EditEEGsystemcatalogXMLMenu.Text = 'Edit EEG system catalog XML';
            app.ParadigmsLabel = uilabel(app.VHTPUIFigure);
            app.ParadigmsLabel.Text = '3. Instruction File:';
            app.InstructionFileListBox = uilistbox(app.VHTPUIFigure);
            app.InstructionFileListBox.Items = {'{}'};
            app.InstructionFileListBox.ItemsData = {'{}'};
            app.InstructionFileListBox.ValueChangedFcn = createCallbackFcn(app, @ParadigmListBoxChanged, true);
            app.InstructionFileListBox.Enable = 'off';
            app.InstructionFileListBox.Value = {};
            app.IndividualStepsLabel = uilabel(app.VHTPUIFigure);
            app.IndividualStepsLabel.Text = '4. Preprocessing Steps:';
            app.PreprocessingStepsListBox = uilistbox(app.VHTPUIFigure);
            app.PreprocessingStepsListBox.Items = {'{}'};
            app.PreprocessingStepsListBox.ItemsData = {'{}'};
            app.PreprocessingStepsListBox.ValueChangedFcn = createCallbackFcn(app, @PreprocessingStepsListBoxValueChanged, true);
            app.PreprocessingStepsListBox.Value = '{}';
            app.ContinueFromDropDownLabel = uilabel(app.VHTPUIFigure);
            app.ContinueFromDropDownLabel.Text = 'Continue From:';
            app.ContinueFromDropDown = uidropdown(app.VHTPUIFigure);
            app.ContinueFromDropDown.Items = {'[ ]'};
            app.ContinueFromDropDown.Value = '[ ]';
            app.SelectoneButtonGroup = uibuttongroup(app.VHTPUIFigure);
            app.SelectoneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectoneButtonGroupChanged, true);
            app.SelectoneButtonGroup.Title = 'Select one:';
            app.AllStepsButton = uitogglebutton(app.SelectoneButtonGroup);
            app.AllStepsButton.Text = 'All Steps';
            app.AllStepsButton.Value = true;
            app.SingleStepButton = uitogglebutton(app.SelectoneButtonGroup);
            app.SingleStepButton.Text = 'Single Step';
            app.ContinuationStepButton = uitogglebutton(app.SelectoneButtonGroup);
            app.ContinuationStepButton.Text = 'Continuation Step';
            app.DryRunCheckBox = uicheckbox(app.VHTPUIFigure);
            app.DryRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @DryRunCheckBoxChanged, true);
            app.DryRunCheckBox.Text = 'Dry Run';
            app.OpenImportFolder = uibutton(app.VHTPUIFigure, 'push');
            app.OpenImportFolder.ButtonPushedFcn = createCallbackFcn(app, @OpenImportFolderPushed, true);
            app.OpenImportFolder.Text = 'Select Folder';
            app.SelectdirectoryofSETFilestoPreprocessLabel = uilabel(app.VHTPUIFigure);
            app.SelectdirectoryofSETFilestoPreprocessLabel.Text = '1. Select directory of SET Files to Preprocess:';
            app.baseDirectory = uieditfield(app.VHTPUIFigure, 'text');
            app.baseDirectory.ValueChangedFcn = createCallbackFcn(app, @baseDirectoryChanged, true);
            app.OutputDirectoryLabel = uilabel(app.VHTPUIFigure);
            app.OutputDirectoryLabel.Text = '2. Select directory for exporting SET files:';
            app.outputDirectory = uieditfield(app.VHTPUIFigure, 'text');
            app.outputDirectory.ValueChangedFcn = createCallbackFcn(app, @outputDirectoryChanged, true);
            app.OpenExportFolder = uibutton(app.VHTPUIFigure, 'push');
            app.OpenExportFolder.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OpenExportFolder.Text = 'Select Folder';
            app.StartNewCheckBox = uicheckbox(app.VHTPUIFigure);
            app.StartNewCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartNewCheckBoxChanged, true);
            app.StartNewCheckBox.Text = ' Start New';
            app.StartNewCheckBox.Value = true;
            app.ArchiveEntireDatasettoZipButton = uibutton(app.VHTPUIFigure, 'push');
            app.ArchiveEntireDatasettoZipButton.ButtonPushedFcn = createCallbackFcn(app, @ArchiveEntireDatasettoZipButtonPushed, true);
            app.ArchiveEntireDatasettoZipButton.Text = 'Archive Entire Dataset to Zip';
            app.VHTPPreprocessingUtilityLabel = uilabel(app.VHTPUIFigure);
            app.VHTPPreprocessingUtilityLabel.Text = 'VHTP Preprocessing Utility';
            app.Label = uilabel(app.VHTPUIFigure);
            app.Label.Text = 'Preprocessing Chain for EEGLAB SET Files';
            app.RequirementsLabel = uilabel(app.VHTPUIFigure);
            app.RequirementsLabel.Text = 'Requirements:';
            app.FixEegLabPath = uibutton(app.VHTPUIFigure, 'push');
            app.FixEegLabPath.ButtonPushedFcn = createCallbackFcn(app, @FixEegLabPathButtonPushed, true);
            app.FixEegLabPath.Text = 'Fix Path';
            app.EEGLABLampLabel = uilabel(app.VHTPUIFigure);
            app.EEGLABLampLabel.Text = 'EEGLAB :';
            app.EEGLABLamp = uilamp(app.VHTPUIFigure);
            app.Image = uiimage(app.VHTPUIFigure);
            app.Image.ImageSource = 'C:\srv\vhtp\man\figures\logo.png';
            app.SingleFileModeSwitchLabel = uilabel(app.VHTPUIFigure);
            app.SingleFileModeSwitchLabel.Text = 'Single File Mode';
            app.SingleFileModeSwitch = uiswitch(app.VHTPUIFigure, 'slider');
            app.SingleFileModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SingleFileModeSwitchValueChanged, true);
            app.label_filelist = uilabel(app.VHTPUIFigure);
            app.label_filelist.Text = 'Detected Files';
            app.label_freespace = uilabel(app.VHTPUIFigure);
            app.label_freespace.Text = 'Free: X GB';
            app.reloadParadigms = uihyperlink(app.VHTPUIFigure);
            app.reloadParadigms.HyperlinkClickedFcn = createCallbackFcn(app, @reloadParadigmsClicked, true);
            app.reloadParadigms.Text = 'Reload';
            app.openParadigmFolder = uihyperlink(app.VHTPUIFigure);
            app.openParadigmFolder.HyperlinkClickedFcn = createCallbackFcn(app, @openParadigmFolderHyperlinkClicked, true);
            app.openParadigmFolder.Text = 'Open Parameters Folder';
            app.editParameters = uihyperlink(app.VHTPUIFigure);
            app.editParameters.HyperlinkClickedFcn = createCallbackFcn(app, @editParametersHyperlinkClicked, true);
            app.editParameters.Text = 'Edit Parameters';
            app.openFunction = uihyperlink(app.VHTPUIFigure);
            app.openFunction.HyperlinkClickedFcn = createCallbackFcn(app, @openFunctionHyperlinkClicked, true);
            app.openFunction.Text = 'Edit function';
            app.StepDetailsLabel = uilabel(app.VHTPUIFigure);
            app.StepDetailsLabel.Text = '5. Step Details:';
            app.StepDetailsTextArea = uitextarea(app.VHTPUIFigure);
            app.WorkflowStyleLabel = uilabel(app.VHTPUIFigure);
            app.WorkflowStyleLabel.Text = '6. Workflow Style:';
            app.ExecuteWorkflowLabel = uilabel(app.VHTPUIFigure);
            app.ExecuteWorkflowLabel.Text = '7. Execute Workflow:';
            app.viewInputFolder = uibutton(app.VHTPUIFigure, 'push');
            app.viewInputFolder.ButtonPushedFcn = createCallbackFcn(app, @viewInputFolderButtonPushed, true);
            app.viewInputFolder.Text = 'Open Input Folder';
            app.viewOutputFolder = uibutton(app.VHTPUIFigure, 'push');
            app.viewOutputFolder.ButtonPushedFcn = createCallbackFcn(app, @viewOutputFolderButtonPushed, true);
            app.viewOutputFolder.Text = 'Open Export Folder';
            app.ExploreProgressLabel = uilabel(app.VHTPUIFigure);
            app.ExploreProgressLabel.Text = 'Explore Progress:';
            app.ConfigureDatasetsLabel = uilabel(app.VHTPUIFigure);
            app.ConfigureDatasetsLabel.Text = 'Configure Datasets';
            app.PreprocessingWorkflowLabel = uilabel(app.VHTPUIFigure);
            app.PreprocessingWorkflowLabel.Text = 'Preprocessing Workflow';
            app.ExecuteButton = uibutton(app.VHTPUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.IgnoresubfoldersCheckBox = uicheckbox(app.VHTPUIFigure);
            app.IgnoresubfoldersCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoresubfoldersCheckBoxValueChanged, true);
            app.IgnoresubfoldersCheckBox.Text = 'Ignore subfolders';
            app.IgnoresubfoldersCheckBox.Value = true;
            app.PlotDataButton = uibutton(app.VHTPUIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.OpeninEEGLABButton = uibutton(app.VHTPUIFigure, 'push');
            app.OpeninEEGLABButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninEEGLABButtonPushed, true);
            app.OpeninEEGLABButton.Text = 'Open in EEGLAB';
            app.saveCurrentFolders = uihyperlink(app.VHTPUIFigure);
            app.saveCurrentFolders.HyperlinkClickedFcn = createCallbackFcn(app, @saveCurrentFoldersClicked, true);
            app.saveCurrentFolders.Text = 'Save Snapshot';
            app.loadLastFolders = uihyperlink(app.VHTPUIFigure);
            app.loadLastFolders.HyperlinkClickedFcn = createCallbackFcn(app, @loadLastFoldersClicked, true);
            app.loadLastFolders.Text = 'Load Snapshot';
            app.PlotSourceButton = uibutton(app.VHTPUIFigure, 'push');
            app.PlotSourceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSourceButtonPushed, true);
            app.PlotSourceButton.Text = 'Plot Source';
            app.ClearParadigmFilesButton = uibutton(app.VHTPUIFigure, 'push');
            app.ClearParadigmFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearParadigmFilesButtonPushed, true);
            app.ClearParadigmFilesButton.Text = 'Clear Paradigm Files';
            app.QIPanel = uipanel(app.VHTPUIFigure);
            app.QIPanel.Title = 'QI Panel';
            app.SET1EditFieldLabel = uilabel(app.QIPanel);
            app.SET1EditFieldLabel.Text = 'SET #1:';
            app.SET1EditField = uieditfield(app.QIPanel, 'text');
            app.SET2EditFieldLabel = uilabel(app.QIPanel);
            app.SET2EditFieldLabel.Text = 'SET #2:';
            app.SET2EditField = uieditfield(app.QIPanel, 'text');
            app.selectSet1 = uibutton(app.QIPanel, 'push');
            app.selectSet1.ButtonPushedFcn = createCallbackFcn(app, @selectSet1Pushed, true);
            app.selectSet1.Text = 'Select';
            app.selectSet2 = uibutton(app.QIPanel, 'push');
            app.selectSet2.ButtonPushedFcn = createCallbackFcn(app, @selectSet2ButtonPushed, true);
            app.selectSet2.Text = 'Select';
            app.ActionDropDownLabel = uilabel(app.QIPanel);
            app.ActionDropDownLabel.Text = 'Action:';
            app.ActionDropDown = uidropdown(app.QIPanel);
            app.ActionDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionDropDownValueChanged, true);
            app.GoButton = uibutton(app.QIPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ChanDropDownLabel = uilabel(app.QIPanel);
            app.ChanDropDownLabel.Text = 'Chan';
            app.ChanDropDown = uidropdown(app.QIPanel);
            app.OpeninAnalysisButton = uibutton(app.VHTPUIFigure, 'push');
            app.OpeninAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninAnalysisButtonPushed, true);
            app.OpeninAnalysisButton.Text = 'Open in Analysis';
            app.ExportLastSteptoOutputFolderButton = uibutton(app.VHTPUIFigure, 'push');
            app.ExportLastSteptoOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLastSteptoOutputFolderButtonPushed, true);
            app.ExportLastSteptoOutputFolderButton.Text = 'Export Last Step to Output Folder';
            app.label_total_size = uilabel(app.VHTPUIFigure);
            app.label_total_size.Text = 'Total Size: X GB';
            % Show the figure after all components are created
            app.VHTPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vhtpPreprocessGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VHTPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VHTPUIFigure)
        end
    end
end