classdef vhtpQualityControlGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VHTPQIFigure                 matlab.ui.Figure
        Ignoresubfolders             matlab.ui.control.CheckBox
        reloadParameters             matlab.ui.control.Hyperlink
        editInspectionSteps          matlab.ui.control.Hyperlink
        SingleFileModeSwitch         matlab.ui.control.Switch
        SingleFileModeSwitchLabel    matlab.ui.control.Label
        label_filelist               matlab.ui.control.Label
        ValidationTable              matlab.ui.control.Table
        OpenImportFolder             matlab.ui.control.Button
        TestRunCheckBox              matlab.ui.control.CheckBox
        ExecuteButton                matlab.ui.control.Button
        InspectionStepsListBox       matlab.ui.control.ListBox
        InspectionStepsListBoxLabel  matlab.ui.control.Label
        TypeofDataButtonGroup        matlab.ui.container.ButtonGroup
        BehavioralERPButton          matlab.ui.control.RadioButton
        FixedERPButton               matlab.ui.control.RadioButton
        RestingButton                matlab.ui.control.RadioButton
        SelectdirectoryofRawFilestoInspectLabel  matlab.ui.control.Label
        baseDirectory                matlab.ui.control.EditField
        FixEegLabPath                matlab.ui.control.Button
        EEGLABLamp                   matlab.ui.control.Lamp
        EEGLABLampLabel              matlab.ui.control.Label
        RequirementsLabel            matlab.ui.control.Label
        QualityInspectionforRecordingFilesLabel  matlab.ui.control.Label
        VHTPQIUtilityLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        PARAMS=struct(); % Description
        DRYRUN=false;
        PROCESS='';
        SOURCEFILES=0;
        EegLabIsAvailable; % Description
        note; % Description
        singleFileModeOn; % Description
        lastImportFolder; % Description
        lastOutputFolder; % Description
        vhtpfolder; % Description
        currentParadigmFile; % Description
        currentInspectionFile; % Description
        currentInspectionSteps; % Description
        currentInspectionType; % Description
        currentPreprocessingStep; % Description
        WorkflowButtonLabel; % Description
        ignoreSubfolders; % Description
        validationResults;
    end
    
    methods (Access = private)

        function allowTypesData(app)
            app.TypeofDataButtonGroup.Enable = "on";
        end

        function allowInspectionSteps(app)

            app.InspectionStepsListBox.Visible = 'on';
        end

        function toggleLateFunctions( app, toggle )
            %app.viewInputFolder.Enable = toggle;
            app.editInspectionSteps.Enable = toggle;
            app.reloadParameters.Enable = toggle;
            app.TestRunCheckBox.Enable = toggle;
            app.TypeofDataButtonGroup.Enable = toggle;
            app.ExecuteButton.Enable = toggle;
        end

        function checkIfEegLabAvailable( app )
            try
                eeglab nogui;
                app.EegLabIsAvailable = true;
                app.note('EEGLAB Found on MATLAB Path.');
            catch
                app.EegLabIsAvailable = false;
                app.note('EEGLAB Missing from MATLAB Path.');
            end
        end

        function fixEegLabPath( app )
            app.EegLabIsAvailable = false;
            try_eeglab_path = missing;
            while app.EegLabIsAvailable == false
                try
                    if ~ismissing(try_eeglab_path), addpath(try_eeglab_path); end
                    app.checkIfEegLabAvailable();
                    assert( app.EegLabIsAvailable );
                    app.actionHandler('eegLabIsAvailable')
                catch
                    if ~ismissing(try_eeglab_path), rmpath(try_eeglab_path); end
                    try_eeglab_path = uigetdir([],'Choose EEGLAB directory or hit cancel.');
                end
            end
        end

%         function denyDataTypes(app)
%             app.TypeofDataButtonGroup.Enable = 'off'
%         end


        function denyAll(app)
            app.toggleLateFunctions('off');
        end

        function setInspectionScript( app, listItem )

            app.currentInspectionFile = fullfile(app.vhtpfolder, 'qi_parameters/', ['qi_' char(regexprep(listItem,' ','_')) '.m']);

        end

        function listInspectionSteps(app)

                app.InspectionStepsListBox.Items = app.currentInspectionSteps;
                app.InspectionStepsListBox.ItemsData = app.currentInspectionSteps;


        end

        function validationColor(app)
            failedrows = find(strcmp("Failed",app.validationResults.Validation_Result));
            passedrows = find(strcmp("Passed",app.validationResults.Validation_Result));
            bgcolor = zeros([height(app.validationResults),3]);
            if ~isempty(failedrows)
                bgcolor(failedrows,:) = repelem([1 0 0],length(failedrows),1);
                set(app.ValidationTable,'BackgroundColor',bgcolor);
            end
            if ~isempty(passedrows)
                bgcolor(passedrows,:) = repelem([0 1 0],length(passedrows),1);
                set(app.ValidationTable,'BackgroundColor',bgcolor);
            end
        end

        function actionHandler(app, action)

            switch action
                case 'startup'
                    % setting up eeglab path
                    app.EEGLABLamp.Color = [1,0,0];
                    app.FixEegLabPath.Enable = 'on';
                    if app.EegLabIsAvailable
                        app.actionHandler('eegLabIsAvailable');
                    end

                    % setting up single / folder mode
                    app.singleFileModeOn = false;
                    app.SingleFileModeSwitch.Value = 'Off';
                    app.actionHandler('singleFileModeOn');

                    % setup default folders
                    app.lastImportFolder = pwd;

                    % set to ignore subfolder by defalt
                    %app.IgnoresubfoldersCheckBox.Value = false;
                    %app.ignoreSubfolders = app.IgnoresubfoldersCheckBox.Value;

                    % turn off late functions until directories are
                    % selected
                    app.currentInspectionType = 'Resting';
                    app.toggleLateFunctions( 'off' );

                case 'eegLabIsAvailable'
                    % setting up eeglab path
                    app.EEGLABLamp.Color = [0,1,0];
                    app.FixEegLabPath.Enable = 'off';

                case 'singleFileModeOn'
                    app.actionHandler('clearInputDir');
                    denyAll(app);
                    if app.singleFileModeOn
                        select_label = '1. Select single SET File for Preprocessing:';
                        button_label = 'Select File';
                        app.note('Single file mode On.')
                    else
                        select_label = '1. Select directory of SET Files to Preprocess:';
                        button_label = 'Select Folder';
                        app.note('Single file mode Off.')
                    end
                    app.SelectdirectoryofRawFilestoInspectLabel.Text = select_label;
                    app.OpenImportFolder.Text = button_label;

                case 'clearInputDir'
                    if ~isempty(app.baseDirectory.Value)
                        app.note(sprintf('Discarding previous input: %s',  app.baseDirectory.Value))
                        app.baseDirectory.Value = '';

                    end

                case 'freespace'
                    app.label_freespace.Text = sprintf('%.1f GB Free', ...
                        app.getUsableDiskSpace());

                case 'updateFileListCount'
                    [fileCount, subfolderCount] = app.countFileList;
                    app.label_filelist.Text =  sprintf('%d Files. %d Subfolders.', ...
                        fileCount,subfolderCount);

%                     if fileCount == 0
%                         app.actionHandler('notReadyToImport');
%                     else
%                         app.actionHandler('readyToImport');
%                     end

                case 'loadCurrentInspectionSteps'
                    app.currentInspectionSteps = fieldnames(app.PARAMS);

                case 'listInspectionSteps'
                    app.listInspectionSteps;

                case 'refreshParameters'

                    if ~isempty( app.currentInspectionType )
                        % load parameters function and get preprocessing steps
                        try
                            func = str2func(strcat("qi_",regexprep(app.currentInspectionType,' ','_')));
                            app.PARAMS=func();
                            app.actionHandler('loadCurrentInspectionSteps');
                            app.actionHandler('listInspectionSteps');
                        catch
                            error('Error loading Quality Inspection parameters file');
                        end
                        allowInspectionSteps(app);
                        %app.TYPE='Resting';
                        %denyContinuationSteps(app);
                        %denyIndividualSteps(app);
                    else
                        denyAll(app);
                    end
                case 'updateValidationTable'
                    app.ValidationTable.Visible = 'on';
                    app.ValidationTable.Data = app.validationResults;
                    validationColor(app);

                case 'emptyValidationResults'
                    app.validationResults = table;
                    app.ValidationTable.Data = [];
                otherwise
                    app.note('Unexpected unhandled action in actionHandler.')
            end

        end

        function addVhtpToPath( app )
            [app.vhtpfolder, ~, ~] = fileparts(which(mfilename));
            addpath(genpath(fullfile( app.vhtpfolder )));
            app.note('Adding vHtp Toolkit to Path.')
        end

        function  [fileCount, subFolderCount]  = countFileList(app)

            results = util_htpDirListing( app.baseDirectory.Value, 'ext', '.set' );

            %hideInputFolder = @(x) strrep(x, app.inputPath.Value, '');
            if isempty(results) == false
                fileCount = height(results);
                subFolderCount = height(unique(results.filepath));
                % app.UITable.Data = [results.filename  cellfun(hideInputFolder, results.filepath, 'UniformOutput', false)];
                % app.UITable.ColumnName = {'Filename','Subfolder'};
            else
                fileCount = 0;
                subFolderCount = 0;
                % app.UITable.Data = [];
                % app.UITable.ColumnName = {'Filename','Subfolder'};
            end
        end

        function space_in_gb = getUsableDiskSpace(app)
            FileObj      = java.io.File(app.outputDirectory.Value);
            space_in_gb = FileObj.getUsableSpace / 1e9;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.note = @(msg) fprintf('%s: %s\n', 'htpPreprocess', msg );
            app.note('Initializing Preprocessing Utility.');

            % setup up toolbox paths
            app.checkIfEegLabAvailable;

            % force vHtp path
            app.addVhtpToPath;

            % use action handler for startup tasks
            app.actionHandler('startup');
        end

        % Button pushed function: FixEegLabPath
        function FixEegLabPathPushed(app, event)
            app.fixEegLabPath;
        end

        % Button pushed function: OpenImportFolder
        function OpenImportFolderPushed(app, event)
            startFolder =  app.lastImportFolder;

            if app.singleFileModeOn
                [file,folder] = uigetfile('*.set','Select a File for Input');
                if file==0
                    figure(app.VHTPQIFigure);
                    return;
                end
                fullFileName = fullfile(folder,file);
                app.baseDirectory.Value = fullFileName;
            else
                [folder] = uigetdir(startFolder, 'Select a Folder for Input');
                if folder==0
                    figure(app.VHTPQIFigure);
                    return;
                else
                    app.lastImportFolder = folder;
                    app.baseDirectory.Value = folder;
                    app.actionHandler( 'updateFileListCount' );
                end

            end
            figure(app.VHTPQIFigure);
            allowTypesData(app);
            app.toggleLateFunctions('on');
            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.actionHandler('emptyValidationResults')
            useSubfolders = ~app.ignoreSubfolders;
            
            if ~isempty(fieldnames(app.PARAMS))
                func=str2func("util_htpQualityInspection");
                if app.singleFileModeOn
                    [folder,~,~] = fileparts(app.baseDirectory.Value);
                    prev_file_dir = char(fullfile(folder,'quality_inspection',app.InspectionStepsListBox.Value));
                    outputdir = fullfile(folder,'quality_inspection');
                else
                    prev_file_dir = char(fullfile(app.baseDirectory.Value,'quality_inspection'));
                    outputdir = fullfile(app.baseDirectory.Value,'quality_inspection');
                end

                if ~exist(prev_file_dir,'dir')
                    mkdir(prev_file_dir);
                end
            outputvalidation = func(app.baseDirectory.Value,app.PARAMS,'ext','.set','subdirOn', useSubfolders, 'outputdir',prev_file_dir,'dryrun',app.DRYRUN);
            app.validationResults = [app.validationResults; outputvalidation];
            app.actionHandler('updateValidationTable')
            writetable(app.validationResults,fullfile(outputdir,'vhtp_qi_output.xlsx'))
            end
        end

        % Selection changed function: TypeofDataButtonGroup
        function TypeofDataButtonGroupSelectionChanged(app, event)
            value = app.TypeofDataButtonGroup.SelectedObject.Text;
            app.currentInspectionType = value;
            app.setInspectionScript(value);
            app.actionHandler('refreshParameters');
        end

        % Value changed function: SingleFileModeSwitch
        function SingleFileModeSwitchValueChanged(app, event)
            switch app.SingleFileModeSwitch.Value
                case 'On'
                    app.singleFileModeOn = true;
                case 'Off'
                    app.singleFileModeOn = false;
            end

            app.actionHandler('singleFileModeOn');

            if app.singleFileModeOn

                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);
                end
                app.OpenImportFolder.Text = 'Select Files';
            else
                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);
                end
                app.OpenImportFolder.Text = 'Select Folder';
            end
        end

        % Callback function: editInspectionSteps
        function editInspectionStepsClicked(app, event)
            try
                open(app.currentInspectionFile);
                app.note(sprintf('Opened %s for editing.', app.currentInspectionFile));
            catch
                app.note('Warning: No parameters file selected.')
            end
        end

        % Callback function: reloadParameters
        function reloadParametersHyperlinkClicked(app, event)
            app.actionHandler('refreshParameters');

        end

        % Value changed function: Ignoresubfolders
        function IgnoresubfoldersValueChanged(app, event)
            value = app.IgnoresubfoldersCheckBox.Value;
            app.ignoreSubfolders = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VHTPQIFigure = uifigure('Visible', 'off');
            app.VHTPQIFigure.Name = 'MATLAB App';
            app.VHTPQIUtilityLabel = uilabel(app.VHTPQIFigure);
            app.VHTPQIUtilityLabel.Text = 'VHTP QI Utility';
            app.QualityInspectionforRecordingFilesLabel = uilabel(app.VHTPQIFigure);
            app.QualityInspectionforRecordingFilesLabel.Text = 'Quality Inspection for Recording Files';
            app.RequirementsLabel = uilabel(app.VHTPQIFigure);
            app.RequirementsLabel.Text = 'Requirements:';
            app.EEGLABLampLabel = uilabel(app.VHTPQIFigure);
            app.EEGLABLampLabel.Text = 'EEGLAB:';
            app.EEGLABLamp = uilamp(app.VHTPQIFigure);
            app.FixEegLabPath = uibutton(app.VHTPQIFigure, 'push');
            app.FixEegLabPath.ButtonPushedFcn = createCallbackFcn(app, @FixEegLabPathPushed, true);
            app.FixEegLabPath.Text = 'Fix Path';
            app.baseDirectory = uieditfield(app.VHTPQIFigure, 'text');
            app.SelectdirectoryofRawFilestoInspectLabel = uilabel(app.VHTPQIFigure);
            app.SelectdirectoryofRawFilestoInspectLabel.Text = 'Select directory of Raw Files to Inspect:';
            app.TypeofDataButtonGroup = uibuttongroup(app.VHTPQIFigure);
            app.TypeofDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofDataButtonGroupSelectionChanged, true);
            app.TypeofDataButtonGroup.Enable = 'off';
            app.TypeofDataButtonGroup.Title = 'Type of Data';
            app.RestingButton = uiradiobutton(app.TypeofDataButtonGroup);
            app.RestingButton.Text = 'Resting';
            app.RestingButton.Value = true;
            app.FixedERPButton = uiradiobutton(app.TypeofDataButtonGroup);
            app.FixedERPButton.Text = 'Fixed ERP';
            app.BehavioralERPButton = uiradiobutton(app.TypeofDataButtonGroup);
            app.BehavioralERPButton.Text = 'Behavioral ERP';
            app.InspectionStepsListBoxLabel = uilabel(app.VHTPQIFigure);
            app.InspectionStepsListBoxLabel.Enable = 'off';
            app.InspectionStepsListBoxLabel.Text = 'Inspection Steps';
            app.InspectionStepsListBox = uilistbox(app.VHTPQIFigure);
            app.InspectionStepsListBox.Items = {'Channel Montage', 'File Duration', 'Face Leads', 'DIN Name', 'DIN Interval', 'Custom Trial'};
            app.InspectionStepsListBox.Enable = 'off';
            app.InspectionStepsListBox.Value = 'Channel Montage';
            app.ExecuteButton = uibutton(app.VHTPQIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.TestRunCheckBox = uicheckbox(app.VHTPQIFigure);
            app.TestRunCheckBox.Text = 'Test Run?';
            app.OpenImportFolder = uibutton(app.VHTPQIFigure, 'push');
            app.OpenImportFolder.ButtonPushedFcn = createCallbackFcn(app, @OpenImportFolderPushed, true);
            app.OpenImportFolder.Text = 'Select Folder';
            app.ValidationTable = uitable(app.VHTPQIFigure);
            app.ValidationTable.ColumnName = {'File'; 'Validation Status'; 'Fail Reason'};
            app.ValidationTable.RowName = {};
            app.ValidationTable.Visible = 'off';
            app.label_filelist = uilabel(app.VHTPQIFigure);
            app.label_filelist.Text = '';
            app.SingleFileModeSwitchLabel = uilabel(app.VHTPQIFigure);
            app.SingleFileModeSwitchLabel.Text = 'Single File Mode';
            app.SingleFileModeSwitch = uiswitch(app.VHTPQIFigure, 'slider');
            app.SingleFileModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SingleFileModeSwitchValueChanged, true);
            app.editInspectionSteps = uihyperlink(app.VHTPQIFigure);
            app.editInspectionSteps.HyperlinkClickedFcn = createCallbackFcn(app, @editInspectionStepsClicked, true);
            app.editInspectionSteps.Text = {'Edit Inspection Parameters'; ''};
            app.reloadParameters = uihyperlink(app.VHTPQIFigure);
            app.reloadParameters.HyperlinkClickedFcn = createCallbackFcn(app, @reloadParametersHyperlinkClicked, true);
            app.reloadParameters.Text = 'Reload Parameters';
            app.Ignoresubfolders = uicheckbox(app.VHTPQIFigure);
            app.Ignoresubfolders.ValueChangedFcn = createCallbackFcn(app, @IgnoresubfoldersValueChanged, true);
            app.Ignoresubfolders.Text = 'Ignore subfolders';
            % Show the figure after all components are created
            app.VHTPQIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vhtpQualityControlGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VHTPQIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VHTPQIFigure)
        end
    end
end