classdef vhtppreprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VHTPUIFigure                   matlab.ui.Figure
        ConfigureMenu                  matlab.ui.container.Menu
        EditEEGsystemcatalogXMLMenu    matlab.ui.container.Menu
        IgnoresubfoldersCheckBox       matlab.ui.control.CheckBox
        ExecuteButton                  matlab.ui.control.Button
        PreprocessingWorkflowLabel     matlab.ui.control.Label
        ConfigureDatasetsLabel         matlab.ui.control.Label
        ExploreProgressLabel           matlab.ui.control.Label
        viewOutputFolder               matlab.ui.control.Button
        viewInputFolder                matlab.ui.control.Button
        ExecuteWorkflowLabel           matlab.ui.control.Label
        WorkflowStyleLabel             matlab.ui.control.Label
        StepDetailsTextArea            matlab.ui.control.TextArea
        StepDetailsLabel               matlab.ui.control.Label
        openFunction                   matlab.ui.control.Hyperlink
        editParameters                 matlab.ui.control.Hyperlink
        openParadigmFolder             matlab.ui.control.Hyperlink
        reloadParadigms                matlab.ui.control.Hyperlink
        label_freespace                matlab.ui.control.Label
        label_filelist                 matlab.ui.control.Label
        SingleFileModeSwitch           matlab.ui.control.Switch
        SingleFileModeSwitchLabel      matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        EEGLABLamp                     matlab.ui.control.Lamp
        EEGLABLampLabel                matlab.ui.control.Label
        FixEegLabPath                  matlab.ui.control.Button
        RequirementsLabel              matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        vHTPPreprocessingUtilityLabel  matlab.ui.control.Label
        ExportDatasetButton            matlab.ui.control.Button
        StartNewCheckBox               matlab.ui.control.CheckBox
        SingleFileModeCheckBox         matlab.ui.control.CheckBox
        OpenExportFolder               matlab.ui.control.Button
        outputDirectory                matlab.ui.control.EditField
        OutputDirectoryLabel           matlab.ui.control.Label
        baseDirectory                  matlab.ui.control.EditField
        SelectdirectoryofSETFilestoPreprocessLabel  matlab.ui.control.Label
        OpenImportFolder               matlab.ui.control.Button
        TestRunCheckBox                matlab.ui.control.CheckBox
        RunButton                      matlab.ui.control.StateButton
        SelectoneButtonGroup           matlab.ui.container.ButtonGroup
        ContinuationStepButton         matlab.ui.control.ToggleButton
        SingleStepButton               matlab.ui.control.ToggleButton
        AllStepsButton                 matlab.ui.control.ToggleButton
        ContinueFromDropDown           matlab.ui.control.DropDown
        ContinueFromDropDownLabel      matlab.ui.control.Label
        PreprocessingStepsListBox      matlab.ui.control.ListBox
        IndividualStepsLabel           matlab.ui.control.Label
        ParadigmsListBox               matlab.ui.control.ListBox
        ParadigmsLabel                 matlab.ui.control.Label
    end


    properties (Access = private)
        PARAMS=struct(); % Description
        DRYRUN=0;
        PROCESS='';
        SOURCEFILES=0;
        EegLabIsAvailable; % Description
        note; % Description
        singleFileModeOn; % Description
        lastImportFolder; % Description
        lastOutputFolder; % Description
        vhtpfolder; % Description
        currentParadigmFile; % Description
        currentParametersFile; % Description
        currentParametersSteps; % Description
        currentParametersCode; % Description
        currentPreprocessingStep; % Description
        WorkflowButtonLabel; % Description
        ignoreSubfolders; % Description
    end

    methods (Access = private)

        function allowProcessStyle(app)
            app.SelectoneButtonGroup.Visible = 'on';
            app.AllStepsButton.Visible = 'on';
            app.SingleStepButton.Visible = 'on';
            app.ContinuationStepButton.Visible = 'on';
            app.AllStepsButton.Enable=1;
            app.SingleStepButton.Enable=1;
            app.ContinuationStepButton.Enable=1;
            app.AllStepsButton.Value=1;
            app.SingleStepButton.Value=0;
            app.ContinuationStepButton.Value=0;
        end

        function denyProcessStyle(app)
            app.SelectoneButtonGroup.Visible = 'off';
            app.AllStepsButton.Visible = 'off';
            app.SingleStepButton.Visible = 'off';
            app.ContinuationStepButton.Visible = 'off';
            app.AllStepsButton.Enable=0;
            app.SingleStepButton.Enable=0;
            app.ContinuationStepButton.Enable=0;
            app.AllStepsButton.Value=0;
            app.SingleStepButton.Value=0;
            app.ContinuationStepButton.Value=0;
        end

        function allowParadigms(app)
            app.ParadigmsListBox.Enable = "on";
        end

        function setParadigmScript( app, listItem )

            app.currentParadigmFile = fullfile(app.vhtpfolder, 'paradigms/', ['paradigm_' char(listItem) '.m']);

        end

        function setParametersScript( app, listItem )

            app.currentParametersFile = fullfile(app.vhtpfolder, 'parameters/', ['parameters_' char(listItem) '.m']);

        end

        function listParadigms(app)
            filelist = util_htpDirListing(fullfile(app.vhtpfolder, 'parameters/'), 'ext', '.m', 'keyword', 'parameters');
            paradigms = string(regexp(filelist{:,2},'(?<=parameters_)\w*','match'));

            app.ParadigmsListBox.Items = paradigms;
            app.ParadigmsListBox.ItemsData = paradigms;
            app.ParadigmsListBox.Visible = 'on';
            app.ParadigmsListBox.Enable = 'on';

        end

        function denyParadigms(app)
            % app.ParadigmsListBox.Items = {};
            % app.ParadigmsListBox.ItemsData = {};
            % app.ParadigmsListBox.Visible = 'off';
            app.ParadigmsListBox.Enable = 'off';

        end

        function listIndividualSteps(app)

            app.PreprocessingStepsListBox.Items = app.currentParametersSteps;
            app.PreprocessingStepsListBox.ItemsData = app.currentParametersSteps;

        end

        function allowIndividualSteps(app)

            %app.PreprocessingStepsListBox.Items = individualsteps;
            %app.PreprocessingStepsListBox.ItemsData = individualsteps;
            %app.PreprocessingStepsListBox.Visible = 'on';
            app.PreprocessingStepsListBox.Enable = 'on';
        end

        function denyIndividualSteps(app)
            %     app.PreprocessingStepsListBox.Items = {};
            %     app.PreprocessingStepsListBox.ItemsData = {};
            %      app.PreprocessingStepsListBox.Visible = 'off';
            app.PreprocessingStepsListBox.Enable = 'off';
        end

        function allowContinuationSteps(app)
            steps=fieldnames(app.PARAMS);
            %             if ~app.SOURCEFILES
            %                 rerunsteps = string(steps);
            %             else
            %                 rerunsteps = [string(steps)];
            %             end
            rerunsteps = string(steps);

            app.ContinueFromDropDown.Items = rerunsteps;
            app.ContinueFromDropDown.ItemsData = rerunsteps;
            app.ContinueFromDropDown.Visible = 'on';
            app.ContinueFromDropDown.Enable = 'on';
        end

        function denyContinuationSteps(app)
            %app.ContinueFromDropDown.Items = {};
            %app.ContinueFromDropDown.ItemsData = {};
            %app.ContinueFromDropDown.Visible = 'off';
            app.ContinueFromDropDown.Enable = 'off';
        end

        function denyAll(app)
            denyParadigms(app);
            denyProcessStyle(app);
            denyIndividualSteps(app);
            denyContinuationSteps(app);
            app.RunButton.Value = 0;
        end

        function initializePreviousFileDirectories(app,prev_file_dir)
            stepnames = fieldnames(app.PARAMS);
%             if app.SOURCEFILES && ~exist(fullfile(prev_file_dir,'source'),'dir')
%                 mkdir(fullfile(prev_file_dir,'source'));
%             end
            for i=1:length(stepnames)
                if ~exist(fullfile(prev_file_dir,char(app.PARAMS.(stepnames{i}).function)),'dir')
                    mkdir(fullfile(prev_file_dir,char(app.PARAMS.(stepnames{i}).function)));
                end
            end
        end

        function moveFiles(app,prev_file_dir,files_to_move)
            if any(strcmp(app.PROCESS,{'IndividualStep'}))
                if app.singleFileModeOn
                    cellfun(@(x) movefile(x,prev_file_dir),files_to_move);
                else
                    arrayfun(@(x) movefile(fullfile(x.folder,x.name),prev_file_dir),files_to_move);
                end
            else

                folders = arrayfun(@(x) regexp(x,fieldnames(app.PARAMS),'match','once'),string(char(files_to_move(:).name)),'UniformOutput',false);
                if length(fieldnames(app.PARAMS))>1
                    temp=cellfun(@(x) ismissing(x),folders,'UniformOutput',false);
                    folders(cellfun(@(x) all(x),temp))={"source"};
                    folders=cellfun(@(x) rmmissing(x),folders);
                else
                    folders(ismissing(string(folders))) = {"source"};
                end
                for i=1:length(files_to_move)
                    movefile(fullfile(files_to_move(i).folder,files_to_move(i).name),fullfile(prev_file_dir,folders{i}));
                end
            end
        end

        % Revision EP, new utility functions

        function actionHandler(app, action)

            switch action
                case 'startup'
                    % setting up eeglab path
                    app.EEGLABLamp.Color = [1,0,0];
                    app.FixEegLabPath.Enable = 'on';
                    if app.EegLabIsAvailable
                        app.actionHandler('eegLabIsAvailable');
                    end

                    % setting up single / folder mode
                    app.singleFileModeOn = false;
                    app.SingleFileModeSwitch.Value = 'Off';
                    app.actionHandler('singleFileModeOn');

                    % setup default folders
                    app.lastImportFolder = pwd;
                    app.lastOutputFolder = pwd;

                    % set to ignore subfolder by defalt
                    app.IgnoresubfoldersCheckBox.Value = false;
                    app.ignoreSubfolders = app.IgnoresubfoldersCheckBox.Value;

                    % setup freespace
                    app.label_freespace.Text = '';
                    app.label_filelist.Text = '';

                    app.listParadigms();
                    app.denyParadigms();

                    % define Workflow style buttons
                    app.WorkflowButtonLabel = struct();
                    app.WorkflowButtonLabel.All       = 'All Steps (in Order)';
                    app.WorkflowButtonLabel.Single    = 'Selected Step';
                    app.WorkflowButtonLabel.Continue  = 'Continue from Step';

                    app.AllStepsButton.Text = app.WorkflowButtonLabel.All;
                    app.SingleStepButton.Text = app.WorkflowButtonLabel.Single;
                    app.ContinuationStepButton.Text = app.WorkflowButtonLabel.Continue;

                    % turn off late functions until directories are
                    % selected
                    app.toggleLateFunctions( 'off' );

                case 'eegLabIsAvailable'
                    % setting up eeglab path
                    app.EEGLABLamp.Color = [0,1,0];
                    app.FixEegLabPath.Enable = 'off';

                case 'singleFileModeOn'
                    app.actionHandler('clearInputDir');

                    if app.singleFileModeOn
                        select_label = '1. Select single SET File for Preprocessing:';
                        button_label = 'Select File';
                        app.note('Single file mode On.')
                    else
                        select_label = '1. Select directory of SET Files to Preprocess:';
                        button_label = 'Select Folder';
                        app.note('Single file mode Off.')
                    end
                    app.SelectdirectoryofSETFilestoPreprocessLabel.Text = select_label;
                    app.OpenImportFolder.Text = button_label;

                case 'clearInputDir'
                    if ~isempty(app.baseDirectory.Value)
                        app.note(sprintf('Discarding previous input: %s',  app.baseDirectory.Value))
                        app.baseDirectory.Value = '';
                    end

                case 'freespace'
                    app.label_freespace.Text = sprintf('%.1f GB Free', ...
                        app.getUsableDiskSpace());

                case 'updateFileListCount'
                    [fileCount, subfolderCount] = app.countFileList;
                    app.label_filelist.Text =  sprintf('%d Files. %d Subfolders.', ...
                        fileCount,subfolderCount);

                    if fileCount == 0
                        app.actionHandler('notReadyToImport');
                    else
                        app.actionHandler('readyToImport');
                    end

                case 'loadCurrentParametersSteps'
                    app.currentParametersSteps = fieldnames(app.PARAMS);

                case 'listIndividualSteps'
                    app.listIndividualSteps;

                case 'refreshParameters'

                    if ~isempty( app.currentParametersCode )
                        % load parameters function and get preprocessing steps
                        try
                            func = str2func(strcat("parameters_",app.currentParametersCode));
                            app.PARAMS=func();
                            app.actionHandler('loadCurrentParametersSteps');
                            app.actionHandler('listIndividualSteps');
                            app.actionHandler('updateStepDetails');
                            app.allowIndividualSteps;
                        catch
                            error('Error loading %s (parameters) file.', app.currentParametersFile);
                        end
                        allowProcessStyle(app);
                        app.PROCESS='All';
                        denyContinuationSteps(app);
                        %denyIndividualSteps(app);
                    else
                        denyAll(app);
                    end

                case 'updateStepDetails'
                    try
                        if isempty(app.currentPreprocessingStep)
                            value = app.PreprocessingStepsListBox.Value;
                        else
                            value = app.currentPreprocessingStep;
                        end
                        app.StepDetailsTextArea.Value = evalc('disp(app.PARAMS.(value))');
                    catch
                        app.StepDetailsTextArea.Value = 'Select a step.';
                    end
                otherwise
                    app.note('Unexpected unhandled action in actionHandler.')
            end

        end


        function toggleLateFunctions( app, toggle )
            app.openParadigmFolder.Enable = toggle;
            app.viewInputFolder.Enable = toggle;
            app.viewOutputFolder.Enable = toggle;
            app.reloadParadigms.Enable = toggle;
            app.openFunction.Enable = toggle;
            app.editParameters.Enable = toggle;
            app.RunButton.Enable = toggle;
            app.PreprocessingStepsListBox.Enable = toggle;
            app.ContinueFromDropDown.Enable = toggle;
            app.ExportDatasetButton.Enable = toggle;
            app.TestRunCheckBox.Enable = toggle;
            app.AllStepsButton.Enable = toggle;
            app.ContinuationStepButton.Enable = toggle;
            app.SingleStepButton.Enable = toggle;
            app.AllStepsButton.Enable = toggle;
            app.StepDetailsTextArea.Enable = toggle;
        end

        function checkIfEegLabAvailable( app )
            try
                eeglab nogui;
                app.EegLabIsAvailable = true;
                app.note('EEGLAB Found on MATLAB Path.');
            catch
                app.EegLabIsAvailable = false;
                app.note('EEGLAB Missing from MATLAB Path.');
            end
        end

        function fixEegLabPath( app )
            app.EegLabIsAvailable = false;
            try_eeglab_path = missing;
            while app.EegLabIsAvailable == false
                try
                    if ~ismissing(try_eeglab_path), addpath(try_eeglab_path); end
                    app.checkIfEegLabAvailable();
                    assert( app.EegLabIsAvailable );
                    app.actionHandler('eegLabIsAvailable')
                catch
                    if ~ismissing(try_eeglab_path), rmpath(try_eeglab_path); end
                    try_eeglab_path = uigetdir([],'Choose EEGLAB directory or hit cancel.');
                end
            end
        end

        function addVhtpToPath( app )
            [app.vhtpfolder, ~, ~] = fileparts(which(mfilename));
            addpath(genpath(fullfile( app.vhtpfolder )));
            app.note('Adding vHtp Toolkit to Path.')
        end

        function  [fileCount, subFolderCount]  = countFileList(app)

            results = util_htpDirListing( app.baseDirectory.Value, 'ext', '.set' );

            %hideInputFolder = @(x) strrep(x, app.inputPath.Value, '');
            if isempty(results) == false
                fileCount = height(results);
                subFolderCount = height(unique(results.filepath));
                % app.UITable.Data = [results.filename  cellfun(hideInputFolder, results.filepath, 'UniformOutput', false)];
                % app.UITable.ColumnName = {'Filename','Subfolder'};
            else
                fileCount = 0;
                subFolderCount = 0;
                % app.UITable.Data = [];
                % app.UITable.ColumnName = {'Filename','Subfolder'};
            end
        end

        function space_in_gb = getUsableDiskSpace(app)
            FileObj      = java.io.File(app.outputDirectory.Value);
            space_in_gb = FileObj.getUsableSpace / 1e9;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % log function
            app.note = @(msg) fprintf('%s: %s\n', 'htpPreprocess', msg );
            app.note('Initializing Preprocessing Utility.');

            % setup up toolbox paths
            app.checkIfEegLabAvailable;

            % force vHtp path
            app.addVhtpToPath;

            % use action handler for startup tasks
            app.actionHandler('startup');

        end

        % Button pushed function: OpenImportFolder
        function OpenImportFolderPushed(app, event)

            startFolder =  app.lastImportFolder;

            if app.singleFileModeOn
                [file,folder] = uigetfile('*.set','Select a File for Input');
                if file==0
                    figure(app.VHTPUIFigure);
                    return;
                end
                fullFileName = fullfile(folder,file);
                app.baseDirectory.Value = fullFileName;
            else
                [folder] = uigetdir(startFolder, 'Select a Folder for Input');
                if folder==0
                    figure(app.VHTPUIFigure);
                    return;
                else
                    app.lastImportFolder = folder;
                    app.lastOutputFolder = folder;
                    app.baseDirectory.Value = folder;
                    app.actionHandler( 'updateFileListCount' );
                end

            end
            figure(app.VHTPUIFigure);
            if ~isempty(app.outputDirectory.Value)
                allowParadigms(app);
            end
        end

        % Value changed function: SingleFileModeCheckBox
        function SingleFileButtonPushed(app, event)
            app.actionHandler('singleFileModeOn');

            value = app.singleFileModeOn;
            if value
                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);

                end
                app.OpenImportFolder.Text = 'Select File';
            else
                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);
                end
                app.OpenImportFolder.Text = 'Select Folder';
            end
        end

        % Button pushed function: OpenExportFolder
        function OutputFolderButtonPushed(app, event)
            startFolder =  app.lastOutputFolder;

            [folder] = uigetdir(startFolder, 'Select a Folder for Output');
            if folder==0
                figure(app.VHTPUIFigure);
                return;
            else
                app.lastOutputFolder;
                app.outputDirectory.Value = folder;
                figure(app.VHTPUIFigure);
                app.actionHandler( 'freespace' );
            end

            if ~isempty(app.baseDirectory.Value)
                allowParadigms(app);
            end

        end

        % Value changed function: ParadigmsListBox
        function ParadigmListBoxChanged(app, event)
            value = app.ParadigmsListBox.Value;
            app.note(sprintf('Switched to %s Paradigm', value));
            %app.setParadigmScript( value );
            app.setParametersScript( value );
            app.currentParametersCode = value;
            app.actionHandler('refreshParameters');
            app.toggleLateFunctions('on');

        end

        % Value changed function: baseDirectory
        function baseDirectoryChanged(app, event)
            value = app.baseDirectory.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Value changed function: outputDirectory
        function outputDirectoryChanged(app, event)
            value = app.outputDirectory.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Selection changed function: SelectoneButtonGroup
        function SelectoneButtonGroupChanged(app, event)
            selectedButton = app.SelectoneButtonGroup.SelectedObject;

            switch selectedButton.Text
                case app.WorkflowButtonLabel.Single
                    allowIndividualSteps(app);
                    denyContinuationSteps(app);
                    app.PROCESS = 'IndividualStep';

                case app.WorkflowButtonLabel.Continue
                    allowContinuationSteps(app);
                    denyIndividualSteps(app);
                    app.PROCESS = 'Continuation';
                case app.WorkflowButtonLabel.All
                    denyIndividualSteps(app);
                    denyContinuationSteps(app);
                    app.PROCESS = 'All';
                otherwise
            end

        end

        % Value changed function: TestRunCheckBox
        function TestRunCheckBoxChanged(app, event)
            value = app.TestRunCheckBox.Value;
            app.DRYRUN = value;
        end

        % Value changed function: RunButton
        function RunButtonChanged(app, event)
            value = app.RunButton.Value;
            if value
                if ~isempty(fieldnames(app.PARAMS))
                    func=str2func(strcat("paradigm_",app.ParadigmsListBox.Value));
                    if app.singleFileModeOn
                        [folder,file,~] = fileparts(app.baseDirectory.Value);
                        prev_file_dir = fullfile(folder,'prev_files');
                        files_to_move={app.baseDirectory.Value; fullfile(folder,[file '.fdt']);};

                    else
                        prev_file_dir = fullfile(app.baseDirectory.Value,'prev_files');
                        files_to_move = dir(fullfile(app.baseDirectory.Value));
                        files_to_move = files_to_move(~[files_to_move.isdir]);
                    end
                    if ~exist(prev_file_dir,'dir')
                        mkdir(prev_file_dir);
                    end
                    initializePreviousFileDirectories(app,prev_file_dir);
                    if strcmp(app.PROCESS,'Continuation')

                        steps = fieldnames(app.PARAMS);
                        stepsMatch = regexp(app.ContinueFromDropDown.Value,steps,'match','once');
                        startStep = find(~ismissing(stepsMatch));
                        continuationSteps = struct();
                        for i=startStep:length(steps)
                            continuationSteps.(steps{i}) = app.PARAMS.(steps{i});
                        end
                        func(app.baseDirectory.Value,continuationSteps,app.PROCESS,'ext','.set','outputdir',prev_file_dir,'dryrun',false,'continuationstep', char(app.ContinueFromDropDown.Value) );
                        if app.singleFileModeOn
                            [folder,file,~] = fileparts(app.baseDirectory.Value);
                            prev_file_dir = fullfile(folder,'prev_files');
                            files_to_move=dir(fullfile(folder,[file '*']));

                            if app.SOURCEFILES
                                subjectName = regexprep(files_to_move(1).name,'^*\.\w+$','');

                            else
                                subjectName = files_to_move(1).name(1:cell2mat((regexp(files_to_move(1).name,steps)))-2);

                            end

                            files_to_move=dir(fullfile(folder,[subjectName '*']));
                        else
                            files_to_move = dir(fullfile(app.baseDirectory.Value));
                            files_to_move = files_to_move(~[files_to_move.isdir]);
                        end
                        moveFiles(app,prev_file_dir,files_to_move);
                    elseif strcmp(app.PROCESS,'IndividualStep')
                        if app.SOURCEFILES
                            prev_file_dir = fullfile(prev_file_dir,'source');
                        else
                            steps = fieldnames(app.PARAMS);
                            if app.singleFileModeOn
                                step_in_use = regexp(files_to_move{1},fieldnames(app.PARAMS));
                            else
                                step_in_use = regexp(files_to_move(1).name,fieldnames(app.PARAMS));
                            end
                            prev_file_dir = char(fullfile(prev_file_dir, steps(cellfun(@(x) ~isempty(x),step_in_use))));
                            steps=[];
                            step_in_use=[];
                        end
                        func(app.baseDirectory.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',prev_file_dir,'dryrun',false,'individualstep',char(app.PreprocessingStepsListBox.Value));
                        moveFiles(app,prev_file_dir,files_to_move);
                    else
                        if ~app.SOURCEFILES
                            uialert(app.VHTPUIFigure, {'Please Mark that these are Original Files','','Original File Checkbox Missing Check.'},'Error','Icon','error');
                            return;
                        end
                        func(app.baseDirectory.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',app.outputDirectory.Value,'dryrun',false);
                        if app.singleFileModeOn
                            [folder,file,~] = fileparts(app.baseDirectory.Value);
                            prev_file_dir = fullfile(folder,'prev_files');
                            files_to_move=dir(fullfile(folder,[file '*']));
                        else
                            files_to_move = dir(fullfile(app.baseDirectory.Value));
                            files_to_move = files_to_move(~[files_to_move.isdir]);
                        end
                        moveFiles(app,prev_file_dir,files_to_move);
                    end

                end
            end
        end

        % Value changed function: StartNewCheckBox
        function StartNewCheckBoxChanged(app, event)
            value = app.StartNewCheckBox.Value;
            app.SOURCEFILES = value;
        end

        % Button pushed function: ExportDatasetButton
        function ExportDatasetButtonPushed(app, event)
            outputvalue=app.outputDirectory.Value;
            if ~isempty(outputvalue) && ~isempty(app.baseDirectory.Value)
                if ~app.singleFileModeOn
                    inputvalue = app.baseDirectory.Value;
                    inputvalue = fullfile(inputvalue,'preprocess');
                else
                    [inputvalue,~,~] = fileparts(app.baseDirectory.Value);
                    inputvalue = fullfile(inputvalue,'preprocess');
                end
                sprintf("Exporting Data.\n")
                if isfolder(outputvalue) && isfolder(inputvalue)
                    zip(fullfile(outputvalue,strjoin([app.ParadigmsListBox.Value,datestr(now, 'yyyy_mm_dd_HH_MM_SS')],'_')),inputvalue);
                    uialert(app.VHTPUIFigure, {'Output Files successfully exported.'},'Output Export','Icon','success');
                else
                    uialert(app.VHTPUIFigure, {'Export not completed due to non-existent input or output directory.','','Ensure that you have selected a valid base directory/file and/or output directory.'},'Output Export','Icon','error');
                end
            else
                uialert(app.VHTPUIFigure, {'Export not completed due to missing input or output values.','','Ensure that you have selected a valid base directory/file and/or output directory.'},'Output Export','Icon','error');
            end
        end

        % Button pushed function: FixEegLabPath
        function FixEegLabPathButtonPushed(app, event)
            app.fixEegLabPath;
        end

        % Menu selected function: EditEEGsystemcatalogXMLMenu
        function EditEEGsystemcatalogXMLMenuSelected(app, event)
            winopen('cfg_htpEegSystems.xml');
        end

        % Value changed function: SingleFileModeSwitch
        function SingleFileModeSwitchValueChanged(app, event)
            switch app.SingleFileModeSwitch.Value
                case 'On'
                    app.singleFileModeOn = true;
                case 'Off'
                    app.singleFileModeOn = false;
            end

            app.actionHandler('singleFileModeOn');

            if app.singleFileModeOn

                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);
                end
                app.OpenImportFolder.Text = 'Select Files';
            else
                if ~isempty(app.baseDirectory.Value)
                    app.baseDirectory.Value = '';
                    denyAll(app);
                end
                app.OpenImportFolder.Text = 'Select Folder';
            end

        end

        % Callback function: reloadParadigms
        function reloadParadigmsClicked(app, event)
            app.actionHandler('refreshParameters');
            app.listParadigms();
        end

        % Callback function: openParadigmFolder
        function openParadigmFolderHyperlinkClicked(app, event)
            winopen(fullfile(app.vhtpfolder, 'paradigms/'));
        end

        % Callback function: editParameters
        function editParametersHyperlinkClicked(app, event)
            try
                open(app.currentParametersFile);
                app.note(sprintf('Opened %s for editing.', app.currentParametersFile));
            catch
                app.note('Warning: No parameters file selected.')
            end
        end

        % Callback function: openFunction
        function openFunctionHyperlinkClicked(app, event)
            value = app.PreprocessingStepsListBox.Value;
            open(func2str(app.PARAMS.(value).function));
        end

        % Value changed function: PreprocessingStepsListBox
        function PreprocessingStepsListBoxValueChanged(app, event)
            app.currentPreprocessingStep = app.PreprocessingStepsListBox.Value;
            app.actionHandler('refreshParameters');
        end

        % Button pushed function: viewInputFolder
        function viewInputFolderButtonPushed(app, event)
            winopen(app.baseDirectory.Value);
        end

        % Button pushed function: viewOutputFolder
        function viewOutputFolderButtonPushed(app, event)
            winopen(app.outputDirectory.Value);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            
            % account for subfolders EP 7/22
            useSubfolders = ~app.ignoreSubfolders;
            
            if ~isempty(fieldnames(app.PARAMS))
                func=str2func("util_htpParadigmRun");
                if app.singleFileModeOn
                    [folder,~,~] = fileparts(app.baseDirectory.Value);
                    prev_file_dir = char(fullfile(folder,'preprocess',app.ParadigmsListBox.Value));
                else
                    prev_file_dir = char(fullfile(app.baseDirectory.Value,'preprocess',app.ParadigmsListBox.Value));
                end
%                 if app.singleFileModeOn
%                     [folder,file,~] = fileparts(app.baseDirectory.Value);
%                     prev_file_dir = char(fullfile(app.baseDirectory.Value,'preprocess',app.ParadigmsListBox.Value));
%                     %files_to_move={app.baseDirectory.Value; fullfile(folder,[file '.fdt']);};
% 
%                 else
%                     prev_file_dir = char(fullfile(app.baseDirectory.Value,'preprocess',app.ParadigmsListBox.Value));
%                     %files_to_move = dir(fullfile(app.baseDirectory.Value));
%                     %files_to_move = files_to_move(~[files_to_move.isdir]);
%                 end
                if ~exist(prev_file_dir,'dir')
                    mkdir(prev_file_dir);
                end
                initializePreviousFileDirectories(app,prev_file_dir);
                if strcmp(app.PROCESS,'Continuation')

                    steps = fieldnames(app.PARAMS);
                    stepsMatch = regexp(app.ContinueFromDropDown.Value,steps,'match','once');
                    startStep = find(~ismissing(stepsMatch));
                    continuationSteps = struct();
                    for i=startStep:length(steps)
                        continuationSteps.(steps{i}) = app.PARAMS.(steps{i});
                    end
                    func(app.baseDirectory.Value,continuationSteps,app.PROCESS,'ext','.set','subdirOn', useSubfolders, 'outputdir',prev_file_dir,'dryrun',false,'continuationstep', char(app.ContinueFromDropDown.Value),'stepnumbers', [startStep:length(steps)]);
%                     if app.singleFileModeOn
%                         [folder,file,~] = fileparts(app.baseDirectory.Value);
%                         prev_file_dir = fullfile(folder,'preprocess',app.ParadigmsListBox.Value);
%                         %files_to_move=dir(fullfile(folder,[file '*']));
% 
%                         if app.SOURCEFILES
%                             subjectName = regexprep(files_to_move(1).name,'^*\.\w+$','');
% 
%                         else
%                             subjectName = files_to_move(1).name(1:cell2mat((regexp(files_to_move(1).name,steps)))-2);
% 
%                         end
% 
%                         %files_to_move=dir(fullfile(folder,[subjectName '*']));
%                     else
%                         %files_to_move = dir(fullfile(app.baseDirectory.Value));
%                         %files_to_move = files_to_move(~[files_to_move.isdir]);
%                     end
                elseif strcmp(app.PROCESS,'IndividualStep')
%                     if app.SOURCEFILES
%                         prev_file_dir = fullfile(prev_file_dir,'source');
%                     else
%                         steps = fieldnames(app.PARAMS);
%                         stepsMatch = regexp(app.currentPreprocessingStep,steps,'match','once');
%                         stepNumber = find(~ismissing(stepsMatch));
%                         prev_file_dir = char(fullfile(prev_file_dir,char(app.PARAMS.(app.currentPreprocessingStep).function)));
%                     end
                    steps = fieldnames(app.PARAMS);
                    stepsMatch = regexp(app.currentPreprocessingStep,steps,'match','once');
                    stepNumber = find(~ismissing(stepsMatch));
                    prev_file_dir = char(fullfile(prev_file_dir,char(app.PARAMS.(app.currentPreprocessingStep).function)));
                    func(app.baseDirectory.Value,app.PARAMS,app.PROCESS,'ext','.set','subdirOn', useSubfolders,'outputdir',prev_file_dir,'dryrun',false,'individualstep',char(app.PreprocessingStepsListBox.Value),'stepnumbers',stepNumber);
                else
                    if ~app.SOURCEFILES
                        uialert(app.VHTPUIFigure, {'Please Mark that these are Original Files','','Original File Checkbox Missing Check.'},'Error','Icon','error');
                        return;
                    end
                    func(app.baseDirectory.Value,app.PARAMS,app.PROCESS,'ext','.set','subdirOn', useSubfolders, 'outputdir',prev_file_dir,'dryrun',false,'stepnumbers', [1:length(fieldnames(app.PARAMS))]);
%                     if app.singleFileModeOn
%                         [folder,file,~] = fileparts(app.baseDirectory.Value);
%                         prev_file_dir = fullfile(folder,'preprocess',app.ParadigmsListBox.Value);
%                         files_to_move=dir(fullfile(folder,[file '*']));
%                     else
%                         files_to_move = dir(fullfile(app.baseDirectory.Value));
%                         files_to_move = files_to_move(~[files_to_move.isdir]);
%                     end
                end
            end
        end

        % Value changed function: IgnoresubfoldersCheckBox
        function IgnoresubfoldersCheckBoxValueChanged(app, event)
            value = app.IgnoresubfoldersCheckBox.Value;
            app.ignoreSubfolders = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VHTPUIFigure = uifigure('Visible', 'off');
            app.VHTPUIFigure.Name = 'VHTP';
            app.ConfigureMenu = uimenu(app.VHTPUIFigure);
            app.ConfigureMenu.Text = 'Configure';
            app.EditEEGsystemcatalogXMLMenu = uimenu(app.ConfigureMenu);
            app.EditEEGsystemcatalogXMLMenu.MenuSelectedFcn = createCallbackFcn(app, @EditEEGsystemcatalogXMLMenuSelected, true);
            app.EditEEGsystemcatalogXMLMenu.Text = 'Edit EEG system catalog XML';
            app.ParadigmsLabel = uilabel(app.VHTPUIFigure);
            app.ParadigmsLabel.Text = '3. Paradigms:';
            app.ParadigmsListBox = uilistbox(app.VHTPUIFigure);
            app.ParadigmsListBox.Items = {'{}'};
            app.ParadigmsListBox.ItemsData = {'{}'};
            app.ParadigmsListBox.ValueChangedFcn = createCallbackFcn(app, @ParadigmListBoxChanged, true);
            app.ParadigmsListBox.Enable = 'off';
            app.ParadigmsListBox.Value = {};
            app.IndividualStepsLabel = uilabel(app.VHTPUIFigure);
            app.IndividualStepsLabel.Text = '4. Preprocessing Steps:';
            app.PreprocessingStepsListBox = uilistbox(app.VHTPUIFigure);
            app.PreprocessingStepsListBox.Items = {'{}'};
            app.PreprocessingStepsListBox.ItemsData = {'{}'};
            app.PreprocessingStepsListBox.ValueChangedFcn = createCallbackFcn(app, @PreprocessingStepsListBoxValueChanged, true);
            app.PreprocessingStepsListBox.Value = '{}';
            app.ContinueFromDropDownLabel = uilabel(app.VHTPUIFigure);
            app.ContinueFromDropDownLabel.Text = 'Continue From:';
            app.ContinueFromDropDown = uidropdown(app.VHTPUIFigure);
            app.ContinueFromDropDown.Items = {'[ ]'};
            app.ContinueFromDropDown.Value = '[ ]';
            app.SelectoneButtonGroup = uibuttongroup(app.VHTPUIFigure);
            app.SelectoneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectoneButtonGroupChanged, true);
            app.SelectoneButtonGroup.Title = 'Select one:';
            app.AllStepsButton = uitogglebutton(app.SelectoneButtonGroup);
            app.AllStepsButton.Text = 'All Steps';
            app.SingleStepButton = uitogglebutton(app.SelectoneButtonGroup);
            app.SingleStepButton.Text = 'Single Step';
            app.SingleStepButton.Value = true;
            app.ContinuationStepButton = uitogglebutton(app.SelectoneButtonGroup);
            app.ContinuationStepButton.Text = 'Continuation Step';
            app.RunButton = uibutton(app.VHTPUIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonChanged, true);
            app.RunButton.Text = 'Run';
            app.TestRunCheckBox = uicheckbox(app.VHTPUIFigure);
            app.TestRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestRunCheckBoxChanged, true);
            app.TestRunCheckBox.Text = 'Test Run';
            app.OpenImportFolder = uibutton(app.VHTPUIFigure, 'push');
            app.OpenImportFolder.ButtonPushedFcn = createCallbackFcn(app, @OpenImportFolderPushed, true);
            app.OpenImportFolder.Text = 'Select Folder';
            app.SelectdirectoryofSETFilestoPreprocessLabel = uilabel(app.VHTPUIFigure);
            app.SelectdirectoryofSETFilestoPreprocessLabel.Text = '1. Select directory of SET Files to Preprocess:';
            app.baseDirectory = uieditfield(app.VHTPUIFigure, 'text');
            app.baseDirectory.ValueChangedFcn = createCallbackFcn(app, @baseDirectoryChanged, true);
            app.OutputDirectoryLabel = uilabel(app.VHTPUIFigure);
            app.OutputDirectoryLabel.Text = '2. Select directory for output SET files:';
            app.outputDirectory = uieditfield(app.VHTPUIFigure, 'text');
            app.outputDirectory.ValueChangedFcn = createCallbackFcn(app, @outputDirectoryChanged, true);
            app.OpenExportFolder = uibutton(app.VHTPUIFigure, 'push');
            app.OpenExportFolder.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OpenExportFolder.Text = 'Select Folder';
            app.SingleFileModeCheckBox = uicheckbox(app.VHTPUIFigure);
            app.SingleFileModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleFileButtonPushed, true);
            app.SingleFileModeCheckBox.Text = 'Single File Mode?';
            app.StartNewCheckBox = uicheckbox(app.VHTPUIFigure);
            app.StartNewCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartNewCheckBoxChanged, true);
            app.StartNewCheckBox.Text = ' Start New';
            app.ExportDatasetButton = uibutton(app.VHTPUIFigure, 'push');
            app.ExportDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatasetButtonPushed, true);
            app.ExportDatasetButton.Text = 'Export Dataset';
            app.vHTPPreprocessingUtilityLabel = uilabel(app.VHTPUIFigure);
            app.vHTPPreprocessingUtilityLabel.Text = 'vHTP Preprocessing Utility';
            app.Label = uilabel(app.VHTPUIFigure);
            app.Label.Text = 'Preprocessing Chain for EEGLAB SET Files';
            app.RequirementsLabel = uilabel(app.VHTPUIFigure);
            app.RequirementsLabel.Text = 'Requirements:';
            app.FixEegLabPath = uibutton(app.VHTPUIFigure, 'push');
            app.FixEegLabPath.ButtonPushedFcn = createCallbackFcn(app, @FixEegLabPathButtonPushed, true);
            app.FixEegLabPath.Text = 'Fix Path';
            app.EEGLABLampLabel = uilabel(app.VHTPUIFigure);
            app.EEGLABLampLabel.Text = 'EEGLAB :';
            app.EEGLABLamp = uilamp(app.VHTPUIFigure);
            app.Image = uiimage(app.VHTPUIFigure);
            app.Image.ImageSource = 'C:\srv\vhtp\man\figures\logo.png';
            app.SingleFileModeSwitchLabel = uilabel(app.VHTPUIFigure);
            app.SingleFileModeSwitchLabel.Text = 'Single File Mode';
            app.SingleFileModeSwitch = uiswitch(app.VHTPUIFigure, 'slider');
            app.SingleFileModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SingleFileModeSwitchValueChanged, true);
            app.label_filelist = uilabel(app.VHTPUIFigure);
            app.label_filelist.Text = 'Detected Files';
            app.label_freespace = uilabel(app.VHTPUIFigure);
            app.label_freespace.Text = 'Free: X gb';
            app.reloadParadigms = uihyperlink(app.VHTPUIFigure);
            app.reloadParadigms.HyperlinkClickedFcn = createCallbackFcn(app, @reloadParadigmsClicked, true);
            app.reloadParadigms.Text = 'Reload';
            app.openParadigmFolder = uihyperlink(app.VHTPUIFigure);
            app.openParadigmFolder.HyperlinkClickedFcn = createCallbackFcn(app, @openParadigmFolderHyperlinkClicked, true);
            app.openParadigmFolder.Text = 'Open Paradigm Folder';
            app.editParameters = uihyperlink(app.VHTPUIFigure);
            app.editParameters.HyperlinkClickedFcn = createCallbackFcn(app, @editParametersHyperlinkClicked, true);
            app.editParameters.Text = 'Edit Parameters';
            app.openFunction = uihyperlink(app.VHTPUIFigure);
            app.openFunction.HyperlinkClickedFcn = createCallbackFcn(app, @openFunctionHyperlinkClicked, true);
            app.openFunction.Text = 'Edit function';
            app.StepDetailsLabel = uilabel(app.VHTPUIFigure);
            app.StepDetailsLabel.Text = '5. Step Details:';
            app.StepDetailsTextArea = uitextarea(app.VHTPUIFigure);
            app.WorkflowStyleLabel = uilabel(app.VHTPUIFigure);
            app.WorkflowStyleLabel.Text = '6. Workflow Style:';
            app.ExecuteWorkflowLabel = uilabel(app.VHTPUIFigure);
            app.ExecuteWorkflowLabel.Text = '7. Execute Workflow:';
            app.viewInputFolder = uibutton(app.VHTPUIFigure, 'push');
            app.viewInputFolder.ButtonPushedFcn = createCallbackFcn(app, @viewInputFolderButtonPushed, true);
            app.viewInputFolder.Text = 'Open Input Folder';
            app.viewOutputFolder = uibutton(app.VHTPUIFigure, 'push');
            app.viewOutputFolder.ButtonPushedFcn = createCallbackFcn(app, @viewOutputFolderButtonPushed, true);
            app.viewOutputFolder.Text = 'Open Output Folder';
            app.ExploreProgressLabel = uilabel(app.VHTPUIFigure);
            app.ExploreProgressLabel.Text = 'Explore Progress:';
            app.ConfigureDatasetsLabel = uilabel(app.VHTPUIFigure);
            app.ConfigureDatasetsLabel.Text = 'Configure Datasets';
            app.PreprocessingWorkflowLabel = uilabel(app.VHTPUIFigure);
            app.PreprocessingWorkflowLabel.Text = 'Preprocessing Workflow';
            app.ExecuteButton = uibutton(app.VHTPUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.IgnoresubfoldersCheckBox = uicheckbox(app.VHTPUIFigure);
            app.IgnoresubfoldersCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoresubfoldersCheckBoxValueChanged, true);
            app.IgnoresubfoldersCheckBox.Text = 'Ignore subfolders';
            % Show the figure after all components are created
            app.VHTPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vhtppreprocess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VHTPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VHTPUIFigure)
        end
    end
end